classdef DataFilter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataFilterUIFigure          matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        Switch                      matlab.ui.control.Switch
        AdvancedFilterLabel         matlab.ui.control.Label
        FilterListPanel             matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        LoadFilterSetButton         matlab.ui.control.Button
        SaveFilterSetButton         matlab.ui.control.Button
        SetFilterButton             matlab.ui.control.Button
        RemoveButton                matlab.ui.control.Button
        Tree                        matlab.ui.container.CheckBoxTree
        AddFilterPanel              matlab.ui.container.Panel
        Label                       matlab.ui.control.Label
        GridLayout2                 matlab.ui.container.GridLayout
        ExtraPropertyDropDown       matlab.ui.control.DropDown
        ExtraPropertyDropDownLabel  matlab.ui.control.Label
        toEditField                 matlab.ui.control.NumericEditField
        toEditFieldLabel            matlab.ui.control.Label
        PropertyDropDown            matlab.ui.control.DropDown
        PropertyDropDownLabel       matlab.ui.control.Label
        LogicalDropDown             matlab.ui.control.DropDown
        LogicalDropDownLabel        matlab.ui.control.Label
        FromEditField               matlab.ui.control.NumericEditField
        FromEditFieldLabel          matlab.ui.control.Label
        TypeDropDown                matlab.ui.control.DropDown
        TypeDropDownLabel           matlab.ui.control.Label
        RangeLabel                  matlab.ui.control.Label
        AddButton                   matlab.ui.control.Button
    end

    properties
        DataFilterStruct %Structured array to save the filter settings to
    end
    properties (Access = private)
        Mainapp % Connection to calling window
        ImportSettingStruc %import settings struc
    end
    
    methods (Access = private)
        
        function fillPropertyDropDown(app)
            %fills the data filter with the different categories
            proplist = cell(numel(app.Mainapp.ButtonGroup_2.Children),1);
            for i = 1:numel(app.Mainapp.ButtonGroup_2.Children)
                child = app.Mainapp.ButtonGroup_2.Children(i);
                proplist{i} = child.Text;
            end
            proplist{end+1} = 'DeepSPT';
            proplist{end+1} = 'BaseProperties';
            app.PropertyDropDown.Items = proplist;
        end
        
        function fillExtraPropertyDropDown(app)
            %fills the extra property with the different subcategories
            if app.PropertyDropDown.Value == "Jump Distances" || app.PropertyDropDown.Value == "Cumulative Jump Distances" || app.PropertyDropDown.Value == "Mean Jump Distances" ||app.PropertyDropDown.Value == "Cumulative Mean Jump Distances"
                list = ["X" "Y" "Z" "XY" "XYZ"];
                app.ExtraPropertyDropDown.Items = list;
            elseif app.PropertyDropDown.Value == "Jump Angles"
                list = ["XY" "XYZ"];
                app.ExtraPropertyDropDown.Items = list;
            elseif app.PropertyDropDown.Value == "MSD from Intern MSD Fit"
                list = ["2D-Alpha" "2D-D" "2D-MSD" "2D-LogR2", "2D-LinR2" "3D-Alpha" "3D-D" "3D-MSD" "3D-LogR2" "3D-LinR2"];
                app.ExtraPropertyDropDown.Items = list;
            elseif app.PropertyDropDown.Value == "Diffusion Parameters from External"
                list = ["D" "MSD"];
                app.ExtraPropertyDropDown.Items = list;
            elseif app.PropertyDropDown.Value == "Classification from External"
                diffusionTypeNames = app.ImportSettingStruc.diffstates;
                diffusionTypeNames = split(diffusionTypeNames, ", ");
                list = diffusionTypeNames;
                app.ExtraPropertyDropDown.Items = list;
            elseif app.PropertyDropDown.Value == "Confinement Ratio"
                list = ["2D Ratio" "2D Length" "2D NetLength" "3D Ratio" "3D Length" "3D NetLength"];
                app.ExtraPropertyDropDown.Items = list;
            elseif app.PropertyDropDown.Value == "Number of Jumps Per Track"
                app.ExtraPropertyDropDown.Items = "Nothing yet";
            elseif app.PropertyDropDown.Value == "DeepSPT"
                allpropertyNames = ["DSPT-Alpha", "DSPT-D", "extra", "pval" "DSPT-Efficiency", "DSPT-logEfficiency", "DSPT-FractalDim", "DSPT-Gaussiantiy", "DSPT-Kurtosis", "DSPT-MSDratio", "DSPT-Trappedness", "DSPT-T0", "DSPT-T1", "DSPT-T2", "DSPT-T3", "DSPT-Lifetime", "DSPT-Length", "DSPT-AvgStepLength", "DSPT-AvgMSD", "DSPT-AvgDP", "DSPT-corrDP", "DSPT-signDP", "DSPT-SumSteplength", "DSPT-MinSteplength", "DSPT-MaxSteplength", "DSPT-BroadnessSteplength", "DSPT-Speed", "DSPT-Covariance", "DSPT-FractionSlow", "DSPT-FractionFast", "DSPT-Volume", "DSPT-Percent-NormalDiff", "DSPT-Percent-DirectedMot", "DSPT-Percent-ConfinedDiff", "DSPT-Percent-SuperDiff", "DSPT-NumberChangepoints", "DSPT-Instant-MSD-D", "DSPT-meanSequence", "DSPT-medianSequence", "maxSequence", "DSPT-minSequence", "DSPT-stdSequence", "DSPT-SimSeq"];
                allpropertyIDS = [23:1:64];
                app.ExtraPropertyDropDown.Items = allpropertyNames;
            elseif app.PropertyDropDown.Value == "BaseProperties"
                allpropertyNames = ["TrackID", "Timestep", "X-Pos", "Y-Pos", "Z-Pos", "Ext-Jumpdistance", "Ext-Segment-D", "Ext-Segment-DErr", "Ext-Segment-DR", "Ext-Segment-MeanJumpDistance", "Ext-Segment-MeanJumpDistanceErr", "Ext-Segment-MSD", "Ext-Segment-MSDErr", "Motiontype", "SegmentStart", "SegmentLifetime", "NumberOfSegmentsInTrack", "Intensity", "SegmentMeanJumpDist-N", "Timelag", "TrackId", "Tracksegments"];
                allpropertyIDS = [1:1:22];
                app.ExtraPropertyDropDown.Items = allpropertyNames;
            else
                app.ExtraPropertyDropDown.Items = "Nothing yet";
            end
        end
        
        function addFilterSettings(app)
            %adds the current filter settings to the gui 
            %grab the options from the addfilter dialogue
            filtertype = app.TypeDropDown.Value;
            filterprop = app.PropertyDropDown.Value;
            filterextraprop = app.ExtraPropertyDropDown.Value;
            filterminval = app.FromEditField.Value;
            filtermaxval = app.toEditField.Value;
            filterlogical = app.LogicalDropDown.Value;
            
            filtername = join([filtertype " " filterprop "-" filterextraprop ": From " filterminval " to " filtermaxval ". Logical:" filterlogical], "");
            %need to convert the different variables into values the
            %trackanalysis window will understand
            %type, 1 for include, 0 for not include
            if app.TypeDropDown.Value == "Include"
                filtertype = 1;
            else
                filtertype = 0;
            end
            %property
            %translates the filter into the path of the data struct the
            %filter will be used on
            if app.PropertyDropDown.Value == "Jump Distances"
                filterprop = "JumpDist";
            elseif app.PropertyDropDown.Value == "Cumulative Jump Distances"
                filterprop = "CumJumpDist";
            elseif app.PropertyDropDown.Value == "Mean Jump Distances"
                filterprop = "MeanJumpDist";
            elseif app.PropertyDropDown.Value == "Cumulative Mean Jump Distances"
                filterprop = "CumMeanJumpDist";
            elseif app.PropertyDropDown.Value == "Jump Angles"
                filterprop = "JumpAngles";
            elseif app.PropertyDropDown.Value == "Diffusion Parameters from Swift"
                filterprop = "SwiftParams";
            elseif app.PropertyDropDown.Value == "MSD from Intern MSD Fit"
                filterprop = "InternMSD";
            elseif app.PropertyDropDown.Value == "Classification from External"
                filterprop = "SwiftParams";
            elseif app.PropertyDropDown.Value == "Confinement Ratio"
                filterprop = "TrackLength";
            elseif app.PropertyDropDown.Value == "Number of Jumps Per Track"
                filterprop = "TrackLength";
            elseif app.PropertyDropDown.Value == "Diffusion Type Switching Frequencies"
                filterprop = "SwiftParams";
            end
            
            %extraproperty
            diffusionTypeNames = app.ImportSettingStruc.diffstates;
            diffusionTypeNames = split(diffusionTypeNames, ", ");
            diffusionTypeNames = string((diffusionTypeNames));
            chosenType = string(app.ExtraPropertyDropDown.Value);
            if app.PropertyDropDown.Value == "Classification from External"
                filterextraprop = "type";
                for i = 1:size(diffusionTypeNames,1)
                    if app.ExtraPropertyDropDown.Value == diffusionTypeNames(i)
                        filterminval = i;
                        filtermaxval = i;
                        break
                    end
                end
            elseif app.ExtraPropertyDropDown.Value == "2D-Alpha"
                filterextraprop = "XY.Alpha";
            elseif app.ExtraPropertyDropDown.Value == "2D-D"
                filterextraprop = "XY.d";
            elseif app.ExtraPropertyDropDown.Value == "2D-MSD"
                filterextraprop = "XY.a";
            elseif app.ExtraPropertyDropDown.Value == "2D-LinR2"
                filterextraprop = "XY.linR";
            elseif app.ExtraPropertyDropDown.Value == "2D-LogR2"
                filterextraprop = "XY.logR";
            elseif app.ExtraPropertyDropDown.Value == "3D-Alpha"
                filterextraprop = "XYZ.Alpha";
            elseif app.ExtraPropertyDropDown.Value == "3D-D"
                filterextraprop = "XYZ.d";
            elseif app.ExtraPropertyDropDown.Value == "3D-MSD"
                filterextraprop = "XYZ.a";
            elseif app.ExtraPropertyDropDown.Value == "3D-LinR2"
                filterextraprop = "XYZ.linR";
            elseif app.ExtraPropertyDropDown.Value == "3D-LogR2"
                filterextraprop = "XYZ.logR";
            elseif app.ExtraPropertyDropDown.Value == "D"
                filterextraprop = "D";
            elseif app.ExtraPropertyDropDown.Value == "MSD"
                filterextraprop = "MSD";
            elseif app.ExtraPropertyDropDown.Value == "2D Ratio"
                filterextraprop = "ConfRatio.XY";
            elseif app.ExtraPropertyDropDown.Value == "3D Ratio"
                filterextraprop = "ConfRatio.XYZ";
            elseif app.ExtraPropertyDropDown.Value == "2D Length"
                filterextraprop = "AbsLength.XY";
            elseif app.ExtraPropertyDropDown.Value == "3D Length"
                filterextraprop = "AbsLength.XYZ";
            elseif app.ExtraPropertyDropDown.Value == "2D NetLength"
                filterextraprop = "NetLength.XY";
            elseif app.ExtraPropertyDropDown.Value == "3D NetLength"
                filterextraprop = "NetLength.XYZ";
            elseif app.PropertyDropDown.Value == "Diffusion Type Switching Frequencies"
                filterextraprop = "switchFreq";
            elseif app.PropertyDropDown.Value == "Number of Jumps Per Track"
                filterextraprop = "Steps";
            else
                filterextraprop = string(app.ExtraPropertyDropDown.Value);
            end

            %logical
            if app.LogicalDropDown.Value == "And"
                %and = 1
                filterlogical = 1;
            else
                %or = 0
                filterlogical = 0;
            end
            %then pack the values
            filtercell = {filtertype, filterprop, filterextraprop, filterminval, filtermaxval, filterlogical};
            uitreenode(app.Tree, "Text", filtername, "NodeData", filtercell);
        end
        
        function removeFilterSettings(app)
            %removes the selected node from the tree
            selection = app.Tree.SelectedNodes;
            selection.delete;
        end
        
        function packFilterSettings(app)
            %gathers all checked filters and passes it to the main app
            checkedNodes = app.Tree.CheckedNodes;
            filterIDs = app.Switch.Value;
            app.DataFilterStruct.nodes = checkedNodes;
            app.DataFilterStruct.filterIDS = filterIDs;
            app.Mainapp.grabFilter();
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.Mainapp = mainapp; 
            movegui(app.DataFilterUIFigure,"center");
            app.ImportSettingStruc = app.Mainapp.ImportSettingsStruct;
            fillPropertyDropDown(app);
            fillExtraPropertyDropDown(app);
        end

        % Value changed function: PropertyDropDown
        function PropertyDropDownValueChanged(app, event)
            value = app.PropertyDropDown.Value;
            fillExtraPropertyDropDown(app);
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            checkedNodes = app.Tree.CheckedNodes;
            
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            addFilterSettings(app);
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            removeFilterSettings(app);
        end

        % Close request function: DataFilterUIFigure
        function DataFilterUIFigureCloseRequest(app, event)
            app.DataFilterUIFigure.Visible = "off";
            a=1;
            %delete(app)
            
        end

        % Button pushed function: SetFilterButton
        function SetFilterButtonPushed(app, event)
            packFilterSettings(app);
        end

        % Button pushed function: SaveFilterSetButton
        function SaveFilterSetButtonPushed(app, event)
            if isa(app.DataFilterStruct, "DataFilterStruct")
                %nothing
            else
                packFilterSettings(app);
            end
            
            filterspec = {'*.mat'};
            [f, p] = uiputfile(filterspec);
            % Make sure user didn't cancel uigetfile dialog
            if ~ischar(p)
                error("No file selected")
            end
            DataFilterStruct = app.DataFilterStruct;
            fulfile = fullfile(p,f);
            save(fulfile, "DataFilterStruct");
        end

        % Button pushed function: LoadFilterSetButton
        function LoadFilterSetButtonPushed(app, event)
            filterspec = {'*.mat'};
            [f, p] = uigetfile(filterspec);
            % Make sure user didn't cancel uigetfile dialog
            if ~ischar(p)
            error("No file selected")
            end
            loadfile = load(fullfile(p,f));
            if size(loadfile,1) > 0
                app.DataFilterStruct = loadfile.DataFilterStruct;
                
                for i = 1:size(app.DataFilterStruct,1)
                    data = app.DataFilterStruct(i).NodeData;
                    filtertype = data{1,1};
                    filterprop = data{1,2};
                    filterextraprop = data{1,3};
                    filterminval = data{1,4};
                    filtermaxval = data{1,5};
                    filterlogical = data{1,6};
                    filtercell = {filtertype, filterprop, filterextraprop, filterminval, filtermaxval, filterlogical};
                    if filtertype == 1
                        filtertype = "Include";
                    else
                        filtertype = "Exclude";
                    end
                    if filterlogical == 1
                        filterlogical = "And";
                    else
                        filterlogical = "Or";
                    end
                    filtername = join([filtertype " " filterprop "-" filterextraprop ": From " string(filterminval) " to " string(filtermaxval) ". Logical:" filterlogical], "");
                    
                    uitreenode(app.Tree, "Text", filtername, "NodeData", filtercell);
                end
            end
               
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataFilterUIFigure = uifigure('Visible', 'off');
            app.DataFilterUIFigure.Name = 'Data Filter';
            app.DataFilterUIFigure.Icon = '3rdPeak1.png';
            app.DataFilterUIFigure.CloseRequestFcn = createCallbackFcn(app, @DataFilterUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.DataFilterUIFigure);
            app.AddFilterPanel = uipanel(app.GridLayout);
            app.AddFilterPanel.Tooltip = {'Define the filter settings here that will be apllied to the data. '};
            app.AddFilterPanel.Title = 'Add Filter';
            app.GridLayout2 = uigridlayout(app.AddFilterPanel);
            app.AddButton = uibutton(app.GridLayout2, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.RangeLabel = uilabel(app.GridLayout2);
            app.RangeLabel.Text = 'Range';
            app.TypeDropDownLabel = uilabel(app.GridLayout2);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.GridLayout2);
            app.TypeDropDown.Items = {'Include', 'Exclude'};
            app.TypeDropDown.Value = 'Include';
            app.FromEditFieldLabel = uilabel(app.GridLayout2);
            app.FromEditFieldLabel.Text = 'From';
            app.FromEditField = uieditfield(app.GridLayout2, 'numeric');
            app.LogicalDropDownLabel = uilabel(app.GridLayout2);
            app.LogicalDropDownLabel.Text = 'Logical';
            app.LogicalDropDown = uidropdown(app.GridLayout2);
            app.LogicalDropDown.Items = {'And', 'Or', ''};
            app.LogicalDropDown.Value = 'And';
            app.PropertyDropDownLabel = uilabel(app.GridLayout2);
            app.PropertyDropDownLabel.Text = 'Property';
            app.PropertyDropDown = uidropdown(app.GridLayout2);
            app.PropertyDropDown.ValueChangedFcn = createCallbackFcn(app, @PropertyDropDownValueChanged, true);
            app.toEditFieldLabel = uilabel(app.GridLayout2);
            app.toEditFieldLabel.Text = 'to';
            app.toEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ExtraPropertyDropDownLabel = uilabel(app.GridLayout2);
            app.ExtraPropertyDropDownLabel.Text = 'Extra Property';
            app.ExtraPropertyDropDown = uidropdown(app.GridLayout2);
            app.Label = uilabel(app.AddFilterPanel);
            app.Label.Text = '';
            app.FilterListPanel = uipanel(app.GridLayout);
            app.FilterListPanel.Title = 'Filter List';
            app.GridLayout3 = uigridlayout(app.FilterListPanel);
            app.Tree = uitree(app.GridLayout3, 'checkbox');
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Tree.Tooltip = {'Contains the single filter nodes added in the panel above.'; 'After adding a node you will have to tick it to activate it and then "Set Filter" on the right to ba applied onto the data.'};
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.RemoveButton = uibutton(app.GridLayout3, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Tooltip = {'Remove the selected filter node from the list.'};
            app.RemoveButton.Text = 'Remove';
            app.SetFilterButton = uibutton(app.GridLayout3, 'push');
            app.SetFilterButton.ButtonPushedFcn = createCallbackFcn(app, @SetFilterButtonPushed, true);
            app.SetFilterButton.Tooltip = {'Sets the current filter settings and calculates the track-ids that will still be present after filtering'};
            app.SetFilterButton.Text = 'Set Filter';
            app.SaveFilterSetButton = uibutton(app.GridLayout3, 'push');
            app.SaveFilterSetButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFilterSetButtonPushed, true);
            app.SaveFilterSetButton.Tooltip = {'Saves the current filter set for later.'};
            app.SaveFilterSetButton.Text = 'Save Filter Set';
            app.LoadFilterSetButton = uibutton(app.GridLayout3, 'push');
            app.LoadFilterSetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFilterSetButtonPushed, true);
            app.LoadFilterSetButton.Tooltip = {'Loads a saved filter set.'};
            app.LoadFilterSetButton.Text = 'Load Filter Set';
            app.AdvancedFilterLabel = uilabel(app.GridLayout);
            app.AdvancedFilterLabel.Text = 'Advanced Filter';
            app.Switch = uiswitch(app.GridLayout, 'slider');
            app.Switch.Items = {'Track', 'Track-Segments'};
            app.Switch.Value = 'Track';
            % Show the figure after all components are created
            app.DataFilterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataFilter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataFilterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataFilterUIFigure)
        end
    end
end