classdef PreprocessingWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PreprocessingWindowUIFigure     matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        ResolutionValidationPanel       matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        FRCErrorLabel                   matlab.ui.control.Label
        FRCValueLabel                   matlab.ui.control.Label
        CalculateFRCButton              matlab.ui.control.Button
        FSCErrorLabel                   matlab.ui.control.Label
        FSCValueLabel                   matlab.ui.control.Label
        CalculateFSCButton              matlab.ui.control.Button
        SwiftAutomatedTrackingPanel_2   matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        MaxtrackradiusEditField_2       matlab.ui.control.NumericEditField
        MaxtrackradiusEditField_2Label  matlab.ui.control.Label
        MaxIterEditField_2              matlab.ui.control.NumericEditField
        MaxIterEditField_2Label         matlab.ui.control.Label
        StopDIffEditField_2             matlab.ui.control.NumericEditField
        StopDIffEditField_2Label        matlab.ui.control.Label
        p_BleachEditField_2             matlab.ui.control.NumericEditField
        p_BleachEditField_2Label        matlab.ui.control.Label
        ExpDIsplEditField_2             matlab.ui.control.NumericEditField
        ExpDIsplEditField_2Label        matlab.ui.control.Label
        Switch_5                        matlab.ui.control.Switch
        StartTrackingButton             matlab.ui.control.Button
        SwiftParameterEstimationPanel   matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        DataTypeButton                  matlab.ui.control.Button
        PreciisonZValue                 matlab.ui.control.Label
        PrecisionZLabel                 matlab.ui.control.Label
        ExpNoiseRateValue               matlab.ui.control.Label
        DiffractionLimitValue           matlab.ui.control.Label
        PreciisonValue                  matlab.ui.control.Label
        ExpDisplacementValue            matlab.ui.control.Label
        p_BleachValue                   matlab.ui.control.Label
        ExpNoiseRateLabel               matlab.ui.control.Label
        DiffractionLimitLabel           matlab.ui.control.Label
        PrecisionXYLabel                matlab.ui.control.Label
        ExpDisplacementLabel            matlab.ui.control.Label
        p_BleachLabel                   matlab.ui.control.Label
        SelectFileButton                matlab.ui.control.Button
        StartProcessingButton           matlab.ui.control.Button
        SaveLocationPanel               matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        AlsosaveascsvCheckBox           matlab.ui.control.CheckBox
        EditField                       matlab.ui.control.EditField
        NoFolderSelectedLabel           matlab.ui.control.Label
        SetSaveFolderButton             matlab.ui.control.Button
        LocalisationDataPreprocessingLabel  matlab.ui.control.Label
        SwiftAutomatedTrackingPanel     matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        MaxtrackradiusEditField         matlab.ui.control.NumericEditField
        MaxtrackradiusEditFieldLabel    matlab.ui.control.Label
        MaxIterEditField                matlab.ui.control.NumericEditField
        MaxIterEditFieldLabel           matlab.ui.control.Label
        StopDIffEditField               matlab.ui.control.NumericEditField
        StopDIffEditFieldLabel          matlab.ui.control.Label
        p_BleachEditField               matlab.ui.control.NumericEditField
        p_BleachLabel_2                 matlab.ui.control.Label
        ExpDIsplEditField               matlab.ui.control.NumericEditField
        ExpDIsplEditFieldLabel          matlab.ui.control.Label
        MeanWeightSwitch                matlab.ui.control.Switch
        LoaddriftcorrectedfilesButton   matlab.ui.control.Button
        EnableTrackingCheckBox          matlab.ui.control.CheckBox
        DriftCorrectionPanel            matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        CellFilePatternEditField        matlab.ui.control.EditField
        CellFilePatternLabel            matlab.ui.control.Label
        BeadFilePatternEditField        matlab.ui.control.EditField
        BeadFilePatternLabel            matlab.ui.control.Label
        LoadintesityfilteredfilesButton  matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        FilePairsFoundLabel             matlab.ui.control.Label
        Switch_3                        matlab.ui.control.Switch
        GetFilesButton_2                matlab.ui.control.Button
        SelectMainFolderButton_2        matlab.ui.control.Button
        PerformDriftCorrectionCheckBox  matlab.ui.control.CheckBox
        DataFilterPanel                 matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        UnitUsedLabel_3                 matlab.ui.control.Label
        LoadprecisionfilteredfilesButton  matlab.ui.control.Button
        LoadmaskedfilesButton           matlab.ui.control.Button
        UnitUsedLabel_5                 matlab.ui.control.Label
        UnitUsedLabel_4                 matlab.ui.control.Label
        UnitUsedLabel_2                 matlab.ui.control.Label
        UnitUsedLabel                   matlab.ui.control.Label
        MaxEditField_4                  matlab.ui.control.NumericEditField
        MaxEditField_4Label             matlab.ui.control.Label
        MinEditField_7                  matlab.ui.control.NumericEditField
        MinEditField_7Label             matlab.ui.control.Label
        FilterActiveCheckBoxIntensity   matlab.ui.control.CheckBox
        FilterActiveCheckBoxPrecisionZ  matlab.ui.control.CheckBox
        FilterActiveCheckBoxPrecisionXY  matlab.ui.control.CheckBox
        FilterActiveCheckBoxPositionZ   matlab.ui.control.CheckBox
        FilterActiveCheckBoxPositionY   matlab.ui.control.CheckBox
        FilterActiveCheckBoxPositionX   matlab.ui.control.CheckBox
        FilterbyIntensityLabel          matlab.ui.control.Label
        SigmaEditField_3                matlab.ui.control.NumericEditField
        SigmaEditField_3Label           matlab.ui.control.Label
        SigmaEditField                  matlab.ui.control.NumericEditField
        SigmaEditFieldLabel             matlab.ui.control.Label
        MaxEditField_3                  matlab.ui.control.NumericEditField
        MaxEditField_3Label             matlab.ui.control.Label
        MinEditField_6                  matlab.ui.control.NumericEditField
        MinEditField_6Label             matlab.ui.control.Label
        MaxEditField_2                  matlab.ui.control.NumericEditField
        MaxEditField_2Label             matlab.ui.control.Label
        MinEditField_5                  matlab.ui.control.NumericEditField
        MinEditField_5Label             matlab.ui.control.Label
        MaxEditField                    matlab.ui.control.NumericEditField
        MaxEditFieldLabel               matlab.ui.control.Label
        MinEditField_4                  matlab.ui.control.NumericEditField
        MinEditField_4Label             matlab.ui.control.Label
        ZLabel_2                        matlab.ui.control.Label
        XYLabel                         matlab.ui.control.Label
        FilterbyPrecisionLabel          matlab.ui.control.Label
        ZLabel                          matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        XLabel                          matlab.ui.control.Label
        UseManualPolymaskCheckBox       matlab.ui.control.CheckBox
        FilterbyPositionLabel           matlab.ui.control.Label
        LoadDataPanel                   matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        SelectMainFolderButton          matlab.ui.control.Button
        GetFilesButton                  matlab.ui.control.Button
        FileStringPatternEditField      matlab.ui.control.EditField
        FileStringPatternEditFieldLabel  matlab.ui.control.Label
        SettingsButton                  matlab.ui.control.Button
        DataTypeLabel                   matlab.ui.control.Label
        Switch_2                        matlab.ui.control.Switch
        FilesFoundNumber                matlab.ui.control.Label
        FilesFoundLabel                 matlab.ui.control.Label
        SelectFilesButton               matlab.ui.control.Button
        NestedFolderStructureLabel      matlab.ui.control.Label
        AllfilesinasinglefolderLabel    matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        FolderStructureLabel            matlab.ui.control.Label
    end

    properties (Access = private)
        Mainapp % calling selection window
        SettingsWindow %window to select the import data type
        SettingsChanged %bool to save the value if settings have changed or not
        TrackSettingsWindow %settings window to select also a different data type as swift to estimate tracking parameters
        
        LocalisationData %Localisations after import into the app
        FileLocations %List of files to process
        BeadLocations %List of files of bead data for drift correction
        MainFolder %Main Folder for subfolder processing
        
        SaveLocation %Folder where to save the data to
        
        DriftCorrLocs %localisations after intensity filter
        
        Tracks %import from track file
        CalculatedDataStruc %for MJD and N

        SideloadedData %for data that has been processed once and shall be processed again
        SideloadedDataType %identifyer for stage to start with the processing
    end
    
    properties (Access = public)
        ImportSettingsStruct %contains all the information about the data that will be processed
        TrackSettingsStruct %struct to save the track settings
    end

    methods (Static)
        
    end
    
    methods (Access = public)
        
        function tracks = getTracks(app)
            tracks = app.tracks;
        end
        
        function setUnitUsed(app)
            %Changes the GUI to the selected units.
            unit = app.ImportSettingsStruct.customUnits.LengthUnit;
            unittext = join(["[" unit "]"],"");
            app.UnitUsedLabel.Text = unittext;
            app.UnitUsedLabel_2.Text = unittext;
            app.UnitUsedLabel_3.Text = unittext;
            app.UnitUsedLabel_4.Text = unittext;
            app.UnitUsedLabel_5.Text = unittext;
        end
        
        function setSettingsChanged(app, value)
            %Bool to control if file settings have changed in between
            %operations
            app.SettingsChanged = value;
        end
        
        function updateSettings(app)
            %grabs the settings from the settings dialogue window
            if isa(app.SettingsWindow, "ImportSettingsPreprocessingWindow")
                app.ImportSettingsStruct = app.SettingsWindow.getSettings();
                %now enable more buttons
                app.SettingsButton.Enable = "on";
                enableLoadData(app);
                setUnitUsed(app);
            else
                %Nothing
            end
        end
        
        function updateTrackSettings(app)
            %if there is a settings window, get the settings
            if isa(app.TrackSettingsWindow, "ImportSettingsTracksPreprocessingWindow")
                app.TrackSettingsStruct = app.TrackSettingsWindow.getSettings();
                app.SelectFileButton.Enable ="on";
                app.DataTypeButton.Enable = "on";
                %with filtered data we dont have tracks
                if app.TrackSettingsStruct.type == "Filtered data .mat"
                else
                    app.CalculateFSCButton.Enable ="on";
                    app.CalculateFRCButton.Enable = "on";
                end
            else
                %Nothing
            end
        end
    end
    
    methods (Access = private)
        %% Gui Control
        function disableDataFilter(app)
            app.DataFilterPanel.Enable = "off";
            FilterPanelChildren = app.GridLayout3.Children;
            set(FilterPanelChildren, "Enable", "off");
        end
        
        function enableDataFilter(app)
            app.DataFilterPanel.Enable = "on";
            FilterPanelChildren = app.GridLayout3.Children;
            set(FilterPanelChildren, "Enable", "on");
        end
        
        function enableResolutionValidation(app)
            app.ResolutionValidationPanel.Enable = "on";
            children = app.GridLayout9.Children;
            set(children, "Enable", "on");
        end
        
        function disableResolutionValidation(app)
            app.ResolutionValidationPanel.Enable = "off";
            children = app.GridLayout9.Children;
            set(children, "Enable", "off");  
        end
        
        function disableDriftCorrection(app)
            app.DriftCorrectionPanel.Enable = "off";
            DriftPanelFilterChildren = app.GridLayout4.Children;
            set(DriftPanelFilterChildren, "Enable", "off");
        end
        
        function enableDriftCorrection(app)
            app.DriftCorrectionPanel.Enable = "on";
            app.PerformDriftCorrectionCheckBox.Enable = "on";
            DriftPanelFilterChildren = app.GridLayout4.Children;
            set(DriftPanelFilterChildren, "Enable", "on");
        end
        
        function disableSaveLocation(app)
            app.SaveLocationPanel.Enable = "off";
            SaveLocChildren = app.GridLayout6.Children;
            set(SaveLocChildren, "Enable", "off");
        end
        
        function enableSaveLocation(app)
            app.SaveLocationPanel.Enable = "on";
            SaveLocChildren = app.GridLayout6.Children;
            set(SaveLocChildren, "Enable", "on");
        end
        
        function disableLoadData(app)
            app.SelectFileButton.Enable = "off";
            app.SelectFilesButton.Enable = "off";
            app.Switch.Enable = "off";
            app.SelectMainFolderButton.Enable = "off";
            app.GetFilesButton.Enable = "off";
            app.FileStringPatternEditField.Enable = "off";
        end
        
        function enableLoadData(app)
            app.SelectFilesButton.Enable = "on";
            app.Switch.Enable = "on";
            %app.SelectMainFolderButton.Enable = "on";
            %app.GetFilesButton.Enable = "on";
            %app.FileStringPatternEditField.Enable = "on";
        end
        
        function disableInLineTracking(app)
            app.SwiftAutomatedTrackingPanel.Enable = "off";
            Child = app.GridLayout5.Children;
            set(Child, "Enable", "off");
        end
        
        function enableInLineTracking(app)
            app.SwiftAutomatedTrackingPanel.Enable = "on";
            Child = app.GridLayout5.Children;
            set(Child, "Enable", "on");
        end
        
        function disableExtraTracking(app)
            app.SwiftAutomatedTrackingPanel_2.Enable = "off";
            Child = app.GridLayout8.Children;
            set(Child, "Enable", "off");
        end
        
        function enableExtraTracking(app)
            app.SwiftAutomatedTrackingPanel_2.Enable = "on";
            Child = app.GridLayout8.Children;
            set(Child, "Enable", "on");
            %app.StartTrackingButton.Enable = "off";
        end
        
        %%
        function struc = setSaveStruct(app)
            %sets the properties for the calculated values so they dont
            %need to be calcualted again and again
            s = struct();
            s.JumpDist.X = {};
            s.JumpDist.Y = {};
            s.JumpDist.Z = {};
            s.JumpDist.XY = {};
            s.JumpDist.XYZ = {};
            s.CumJumpDist.X = {};
            s.CumJumpDist.Y = {};
            s.CumJumpDist.Z = {};
            s.CumJumpDist.XY = {};
            s.CumJumpDist.XYZ = {};
            s.MeanJumpDist.X = {};
            s.MeanJumpDist.Y = {};
            s.MeanJumpDist.Z = {};
            s.MeanJumpDist.XY = {};
            s.MeanJumpDist.XYZ = {};
            s.CumMeanJumpDist.X = {};
            s.CumMeanJumpDist.Y = {};
            s.CumMeanJumpDist.Z = {};
            s.CumMeanJumpDist.XY = {};
            s.CumMeanJumpDist.XYZ = {};
            s.JumpAngles.XY = {};
            s.JumpAngles.XYZ = {};
            s.SwiftParams.MSD = {};
            s.SwiftParams.MSDerr = {};
            s.SwiftParams.D = {};
            s.SwiftParams.Derr = {};
            s.SwiftParams.type = {};
            s.InternMSD.XY.MSDclass = [];
            s.InternMSD.XY.Alpha = {};
            s.InternMSD.XY.a = {};
            s.InternMSD.XY.d = {};
            s.InternMSD.XYZ.MSDclass = [];
            s.InternMSD.XYZ.Alpha = {};
            s.InternMSD.XYZ.a = {};
            s.InternMSD.XYZ.d = {};
            s.TrackLength.Steps = {};
            s.TrackLength.NetLength.XY = {};
            s.TrackLength.NetLength.XYZ = {};
            s.TrackLength.ConfRatio.XY = {};
            s.TrackLength.ConfRatio.XYZ = {};
            s.TrackLength.AbsLength.XY = {};
            s.TrackLength.AbsLength.XYZ = {};
            s.TrackLength.CumLen = {};
            s.TrackLength.MeanLen.XY = {};
            s.TrackLength.MeanLen.XYZ = {};
            struc = s;
        end
        
        function checkForLocalisationFiles(app)
            %if we have localisation files loaded, enable more options
            if app.Switch_2.Value == "Localisation Data Processing"
                if size(app.FileLocations,1) >= 1
                    enableDataFilter(app);
                    enableDriftCorrection(app);
                    enableSaveLocation(app);
                    enableInLineTracking(app);
                end
            end
        end
        
        function checkForImageFiles(app)
            %if we want to process images, we dont need so much
            if app.Switch_2.Value == "Image Data Masking"
                enableSaveLocation(app);
            end
        end
        
        function runLocParameterEstimation(app)
            [precisionXYString, precisionZString] = calculatePrecision(app.DriftCorrLocs, app.ImportSettingsStruct.customUnits.LengthUnit);
            app.PreciisonValue.Text = precisionXYString;
            app.PreciisonZValue.Text = precisionZString;
            %Diffraction Limit by Minimal Distance resolved
            diffractionLimitValueString = calculateDiffractionLimit(app.DriftCorrLocs, app.ImportSettingsStruct.customUnits.LengthUnit);
            app.DiffractionLimitValue.Text = diffractionLimitValueString;
            %Noise rate
            expNoiseRateValueString = calculateExpNoiseRate(app.DriftCorrLocs);
            app.ExpNoiseRateValue.Text = expNoiseRateValueString;
            %save the final values to look them up later
            EstiParameters = table(str2double(precisionXYString), str2double(precisionZString), str2double(diffractionLimitValueString), str2double(expNoiseRateValueString));
            EstiParameters.Properties.VariableNames = {'PrecXY', 'PrecZ', 'DiffLimit', 'Exp Noise'};
            writetable(EstiParameters);
        end
        
        function manageDriftCorrectionPanel(app)
            %enables disables part of the drift correction panel depending
            %on the choice of the user
            if app.PerformDriftCorrectionCheckBox.Value == 1
                app.Switch_3.Enable = "on";
            else
                app.Switch_3.Enable = "off";
            end
            if app.Switch_3.Value == "Reference Bead"
                app.BeadFilePatternEditField.Enable = "on";
                app.BeadFilePatternLabel.Enable = "on";
                app.CellFilePatternEditField.Enable = "on";
                app.CellFilePatternLabel.Enable = "on";
                app.SelectMainFolderButton_2.Enable = "on";
                app.GetFilesButton_2.Enable = "on";
                app.FilePairsFoundLabel.Enable = "on";
                app.Label.Enable = "on";
            else
                app.BeadFilePatternEditField.Enable = "off";
                app.BeadFilePatternLabel.Enable = "off";
                app.CellFilePatternEditField.Enable = "off";
                app.CellFilePatternLabel.Enable = "off";
                app.SelectMainFolderButton_2.Enable = "off";
                app.GetFilesButton_2.Enable = "off";
                app.FilePairsFoundLabel.Enable = "off";
                app.Label.Enable = "off";
            end
        end
        
        function performSanityCheck(app)
            %function to control that all necessary inputs have been made
            %for data processing, will enable the processing button
            if ~isempty(app.FileLocations) && ~isempty(app.SaveLocation)
                app.StartProcessingButton.Enable = "on";
            elseif ~isempty(app.SideloadedData) && ~isempty(app.SaveLocation)
                app.StartProcessingButton.Enable = "on";
            else
                app.StartProcessingButton.Enable = "off";
            end
        end
 
        function options = getOptions(app)
            %packs all the settings into one file so they can be saved
            %during the processing
            filelocs = "locs";
            for i = 1:size(app.FileLocations,1)
                filelocs(i) = app.FileLocations{i,1};
            end
            options.files = filelocs;
            options.XYZ.XY.UseManualPolymask = app.UseManualPolymaskCheckBox.Value;
            options.XYZ.XY.X.Used = app.FilterActiveCheckBoxPositionX.Value;
            options.XYZ.XY.X.Min = app.MinEditField_4.Value;
            options.XYZ.XY.X.Max = app.MaxEditField.Value;
            options.XYZ.XY.Y.Used = app.FilterActiveCheckBoxPositionY.Value;
            options.XYZ.XY.Y.Min = app.MinEditField_5.Value;
            options.XYZ.XY.Y.Max = app.MaxEditField_2.Value;
            options.XYZ.Z.Used = app.FilterActiveCheckBoxPositionZ.Value;
            options.XYZ.Z.Min = app.MinEditField_6.Value;
            options.XYZ.Z.Max = app.MaxEditField_3.Value;
            options.precision.XY.Used = app.FilterActiveCheckBoxPrecisionXY.Value;
            options.precision.XY.sigma = app.SigmaEditField.Value;
            options.precision.Z.Used = app.FilterActiveCheckBoxPrecisionZ.Value;
            options.precision.Z.sigma = app.SigmaEditField_3.Value;
            options.intensity.Used = app.FilterActiveCheckBoxIntensity.Value;
            options.intensity.Min = app.MinEditField_7.Value;
            options.intensity.Max = app.MaxEditField_4.Value;
            options.drift.Performdrift = app.PerformDriftCorrectionCheckBox.Value;
            if app.Switch_3.Value == "No Reference Bead"
                reference = 0;
            else 
                reference = 1;
            end
            options.drift.ReferenceAvailable = reference;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            %set link to main app
            app.Mainapp = mainapp;
            movegui(app.PreprocessingWindowUIFigure, "center");
            %generate the save structure
            app.CalculatedDataStruc = setSaveStruct(app);
            
            %disable nearly everything at the beginning
            %data processing
            disableDataFilter(app);
            disableDriftCorrection(app);
            disableSaveLocation(app);
            disableLoadData(app);
            disableInLineTracking(app);
            disableExtraTracking(app);
            
            %preprocessing button
            app.StartProcessingButton.Enable = "off";
            
            %parameterEstimation
            app.SelectFileButton.Enable = "off";
            app.ResolutionValidationPanel.Enable = "off";

            %Enable the side-loading buttons
            app.LoadmaskedfilesButton.Enable = "off";
            app.DataFilterPanel.Enable = "off";
            app.LoadprecisionfilteredfilesButton.Enable = "off";
            app.DriftCorrectionPanel.Enable = "off";
            app.LoadintesityfilteredfilesButton.Enable = "off";
            app.SwiftAutomatedTrackingPanel.Enable = "off";
            app.LoaddriftcorrectedfilesButton.Enable = "off";

            app.SideloadedDataType = 0;

        end

        % Button pushed function: SettingsButton
        function SettingsButtonPushed(app, event)
            app.SettingsButton.Enable = "off";
            app.SettingsWindow = ImportSettingsPreprocessingWindow(app);
        end

        % Button pushed function: SelectFilesButton
        function SelectFilesButtonPushed(app, event)
            %Button to select the trace data matching the microscopy data.
            %check if settings changed. if yes clear the data
            if app.SettingsChanged
                app.FileLocations = [];
            end
            if app.Switch_2.Value == "Localisation Data Processing"
                %Load the locs
                app.FileLocations = getLocFilelocationfromSingleFolder(app.ImportSettingsStruct);
                %if files are found, enable more options
                checkForLocalisationFiles(app);
            else
                %Load image files and preprocess them to get rid of beads
                %etc
                % Display uigetfile dialog
                filterspec = {'*.tif'};
                [f, p] = uigetfile(filterspec, 'MultiSelect','on');
                % Make sure user didn't cancel uigetfile dialog
                if ~ischar(p)
                    error("No file selected")
                end
                if isa(f,"char") %only one file selected, convert to cell 
                    b = f;
                    f = cell(1,1);
                    f{1,1} = b;
                end
                files = f.';
                fullfilenames = cellfun(@(x) join([p,x]), files, 'UniformOutput', false);
                app.FileLocations = fullfilenames;
                %enable save location and processing if files are found
                checkForImageFiles(app);   
            end
            app.FilesFoundNumber.Text = int2str(size(app.FileLocations,1));
            app.SideloadedDataType = 0;
            performSanityCheck(app);
        end

        % Button pushed function: SelectMainFolderButton
        function SelectMainFolderButtonPushed(app, event)
            %gets the head folder for nested folder file search
            app.MainFolder = uigetdir;
            if ~isempty(app.MainFolder)
                app.GetFilesButton.Enable = "on";
                app.FileStringPatternEditField.Enable = "on";
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            app.FileLocations = [];
            if value == "Single Folder"
                %shut off nested file functions
                app.SelectMainFolderButton.Enable = "off";
                app.FileStringPatternEditField.Enable = "off";
                app.GetFilesButton.Enable = "off";
                %turn on single folder
                app.SelectFilesButton.Enable = "on";
            else
                %the other way round
                app.SelectFilesButton.Enable = "off";
                %enable nested folder
                app.SelectMainFolderButton.Enable = "on";
                app.FileStringPatternEditField.Enable = "on";
                app.GetFilesButton.Enable = "on";
            end
        end

        % Button pushed function: GetFilesButton
        function GetFilesButtonPushed(app, event)
            app.FileLocations = nestedFolderFileSearch(app.MainFolder, app.FileStringPatternEditField.Value);
            %now we got the path to the files we are interested in. we are
            %happy.   
            app.FilesFoundNumber.Text = int2str(size(app.FileLocations,1));
            %Enable more options if files are found
            checkForLocalisationFiles(app);
            app.SideloadedDataType = 0;
            performSanityCheck(app);
        end

        % Value changed function: MinEditField_4
        function MinEditField_4ValueChanged(app, event)
            app.FilterActiveCheckBoxPositionX.Value = 1;            
        end

        % Value changed function: MaxEditField
        function MaxEditFieldValueChanged(app, event)
            app.FilterActiveCheckBoxPositionX.Value = 1;   
        end

        % Value changed function: MinEditField_5
        function MinEditField_5ValueChanged(app, event)
            app.FilterActiveCheckBoxPositionY.Value = 1;               
        end

        % Value changed function: MaxEditField_2
        function MaxEditField_2ValueChanged(app, event)
            app.FilterActiveCheckBoxPositionY.Value = 1;    
        end

        % Value changed function: MinEditField_6
        function MinEditField_6ValueChanged(app, event)
            app.FilterActiveCheckBoxPositionZ.Value = 1;            
        end

        % Value changed function: MaxEditField_3
        function MaxEditField_3ValueChanged(app, event)
            app.FilterActiveCheckBoxPositionZ.Value = 1;            
        end

        % Value changed function: SigmaEditField
        function SigmaEditFieldValueChanged(app, event)
            app.FilterActiveCheckBoxPrecisionXY.Value = 1;            
        end

        % Value changed function: SigmaEditField_3
        function SigmaEditField_3ValueChanged(app, event)
            app.FilterActiveCheckBoxPrecisionZ.Value = 1;            
        end

        % Value changed function: MinEditField_7
        function MinEditField_7ValueChanged(app, event)
            app.FilterActiveCheckBoxIntensity.Value = 1;            
        end

        % Value changed function: MaxEditField_4
        function MaxEditField_4ValueChanged(app, event)
            app.FilterActiveCheckBoxIntensity.Value = 1;            
        end

        % Value changed function: UseManualPolymaskCheckBox
        function UseManualPolymaskCheckBoxValueChanged(app, event)
            value = app.UseManualPolymaskCheckBox.Value;
            if value == 1 %polymask enabled
                app.FilterActiveCheckBoxPositionX.Value = 0;
                app.FilterActiveCheckBoxPositionY.Value = 0;
                %dont use the usual filter
                app.FilterActiveCheckBoxPositionX.Enable = "off";
                app.FilterActiveCheckBoxPositionY.Enable = "off";
                app.MinEditField_4.Enable = "off";
                app.MaxEditField.Enable = "off";
                app.MinEditField_5.Enable = "off";
                app.MaxEditField_2.Enable = "off";
            else %make elemts available again
                app.FilterActiveCheckBoxPositionX.Enable = "on";
                app.FilterActiveCheckBoxPositionY.Enable = "on";
                app.MinEditField_4.Enable = "on";
                app.MaxEditField.Enable = "on";
                app.MinEditField_5.Enable = "on";
                app.MaxEditField_2.Enable = "on";
            end
        end

        % Button pushed function: SetSaveFolderButton
        function SetSaveFolderButtonPushed(app, event)
            app.SaveLocation = uigetdir;
            if ~isempty(app.SaveLocation)
                app.NoFolderSelectedLabel.Text = app.SaveLocation;
            end
            performSanityCheck(app);
        end

        % Button pushed function: StartProcessingButton
        function StartProcessingButtonPushed(app, event)
            d = uiprogressdlg(app.PreprocessingWindowUIFigure,'Title','Processing', 'Indeterminate','on');
            drawnow
            if app.Switch_2.Value == "Image Data Masking"
                %Perform the image division. Divide bead from cell if
                %necessary. Do it with all the collected images.
                files = app.FileLocations;
                for i = 1:size(files,1)
                    processBeadsAndCellImage(files{i,1}, app);
                end
            else
                %as beadlocations are optional, they might not be
                %initialized at that point
                if isa(app.BeadLocations, "cell")
                else
                    app.BeadLocations = {};
                end
                saveCSV = app.AlsosaveascsvCheckBox.Value;
                if isa(app.SideloadedDataType, "double") && (app.SideloadedDataType > 0 ) 
                    %data sideloading
                    if app.SideloadedDataType == 1
                        app.DriftCorrLocs = performLocalisationPreprocessingAfterMasking(app.SaveLocation, app.EditField.Value, app.SideloadedData, app.BeadLocations, getOptions(app), saveCSV);
                    elseif app.SideloadedDataType == 2
                        app.DriftCorrLocs = performLocalisationPreprocessingAfterPrecisionFiltering(app.SaveLocation, app.EditField.Value, app.SideloadedData, app.BeadLocations, getOptions(app), saveCSV);
                    elseif app.SideloadedDataType == 3
                        app.DriftCorrLocs = performLocalisationPreprocessingAfterIntensityFilter(app.SaveLocation, app.EditField.Value, app.SideloadedData, app.BeadLocations, getOptions(app), saveCSV);
                    elseif app.SideloadedDataType == 4
                        app.DriftCorrLocs = performLocalisationPreprocessingAfterDriftCorr(app.SaveLocation, app.EditField.Value, app.SideloadedData, getOptions(app), saveCSV);
                    end
                else
                    %Perform Localisation Data Preprocessing
                    app.DriftCorrLocs = performLocalisationPreprocessing(app.SaveLocation, app.EditField.Value, app.ImportSettingsStruct, app.FileLocations, app.BeadLocations, getOptions(app), saveCSV);
                end
                %Gather Additional Data for Tracking Parameters
                %Localisation Precision
                runLocParameterEstimation(app);
                %save the settings anyway for later
                settingStruct = app.ImportSettingsStruct;
                save("LoadingSettings.mat", "settingStruct");
                writestruct(settingStruct, "SettingsUsed.xml");
                delete(d);
                %Perform the tracking
                if app.EnableTrackingCheckBox.Value
                    ProcessingDataPath = pwd;
                    precisionxy = str2double(app.PreciisonValue.Text);
                    precisionZ = str2double(app.PreciisonZValue.Text);
                    diffractionLimit = str2double(app.DiffractionLimitValue.Text);
                    ExpNoise = str2double(app.ExpNoiseRateValue.Text);
                    expDispla = app.ExpDIsplEditField.Value;
                    pBleach = app.p_BleachEditField.Value;
                    maxTrackRad = app.MaxtrackradiusEditField.Value;
                    if maxTrackRad < expDispla
                        maxTrackRad = expDispla*1.1;
                    end
                    if app.MeanWeightSwitch.Value == "Mean"
                        MeanWeightVal = 0;
                    else
                        MeanWeightVal = 1;
                    end
                    timeStep = app.ImportSettingsStruct.customUnits.Timestep;
                    timeUnit = app.ImportSettingsStruct.customUnits.TimeUnit;
                    lengthUnit = app.ImportSettingsStruct.customUnits.LengthUnit;
                    ChangeMin = app.StopDIffEditField.Value/100;
                    IterMax = app.MaxIterEditField.Value;
                    d = uiprogressdlg(app.PreprocessingWindowUIFigure,'Title','Tracking', 'Indeterminate','on');
                    drawnow
                    automateSwiftTracking(ProcessingDataPath, precisionxy, precisionZ, diffractionLimit, ExpNoise, expDispla, pBleach, timeStep, timeUnit, lengthUnit, ChangeMin, IterMax, maxTrackRad, MeanWeightVal);
                end
            end
            delete(d);
        end

        % Close request function: PreprocessingWindowUIFigure
        function PreprocessingWindowUIFigureCloseRequest(app, event)
            %Closes the figure and opens the selection window again
            app.Mainapp.ThirdPeakUIFigure.Visible = "on";
            delete(app)
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            %load file
            app.Tracks = loadTrackDataFileSelectionPreprocessing(app.TrackSettingsStruct);
            %set the Resolution Validation Active
            enableResolutionValidation(app);
            %check datatype, discern between tracks and filtered Locs
            if app.TrackSettingsStruct.type == "Filtered data .mat"
                %only localisations
                %Localisation Precision, Diffraction Limit and Noise
                app.DriftCorrLocs = app.Tracks;
                runLocParameterEstimation(app);
            else
                %check if only one or multipe track files have been loaded
                if size(app.Tracks, 1) > 1 %multiple
                    app.Tracks = catTrackDataRename(app.Tracks);
                else
                    %only one
                   app.Tracks = app.Tracks{1,1};
                end
                %track data available, calculate additional data
                if size(app.Tracks,2)<7
                    %no MJD and no N calculated yet
                    ids = unique(app.Tracks(:,1));
                    for i = 1:size(ids)
                        singletrack = app.Tracks(app.Tracks(:,1)==ids(i),:);
                        calculateJumpDistances(singletrack, app.CalculatedDataStruc);
                        calculateStepNumbers(singletrack, app.CalculatedDataStruc);
                    end
                    calculateMeanJumpDistances(app.CalculatedDataStruc, app.CalculatedDataStruc);
                    %all data calculated, save it into the tracks
                    app.Tracks(:,10) = app.CalculatedDataStruc.MeanJumpDist.XYZ{:,2};
                    app.Tracks(:,19) = app.CalculatedDataStruc.TrackLength.Steps{:,2};
                end
            if app.Switch_5.Value == "Mean"
                meanVal = 0;
            else
                meanVal = 1;
            end
            expdisplacementvaluestring = calculateExpDisplacement(app.Tracks, meanVal);
            bleachvaluestring = calculatePBleach(app.Tracks, app.TrackSettingsStruct.customUnits.Timestep, app.TrackSettingsStruct.customUnits.TimeUnit, 1);
            app.ExpDisplacementValue.Text = expdisplacementvaluestring;
            app.p_BleachValue.Text = bleachvaluestring;
            end
            if ~isnan(str2double(app.PreciisonValue.Text)) && ~isnan(str2double(app.DiffractionLimitValue.Text))
                enableExtraTracking(app);
            end
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            disableDriftCorrection(app);
            disableDataFilter(app);
            if value == "Localisation Data Processing"
                %enable/disable functions depending on the processing type
                if isa(app.SettingsWindow, "ImportSettingsPreprocessingWindow")
                    enableLoadData(app);
                end
            else
                disableDriftCorrection(app);
                disableDataFilter(app);
                enableLoadData(app);
            end
        end

        % Button pushed function: DataTypeButton
        function DataTypeButtonPushed(app, event)
            app.DataTypeButton.Enable = "off";
            app.TrackSettingsWindow = ImportSettingsTracksPreprocessingWindow(app);
        end

        % Button pushed function: CalculateFSCButton
        function CalculateFSCButtonPushed(app, event)
            d = uiprogressdlg(app.PreprocessingWindowUIFigure,'Title','Calculating FSC', 'Indeterminate','on');
            drawnow
            [finalvoxsize,error] = calculateFSC(app.Tracks, app.TrackSettingsStruct);
            app.FSCValueLabel.Text = int2str(finalvoxsize);
            app.FSCErrorLabel.Text = join(["+- " int2str(error)]);
            delete(d);
        end

        % Button pushed function: CalculateFRCButton
        function CalculateFRCButtonPushed(app, event)
            d = uiprogressdlg(app.PreprocessingWindowUIFigure,'Title','Calculating FRC', 'Indeterminate','on');
            drawnow
            frc = calculateFRC(app.Tracks, app.TrackSettingsStruct);
            delete(d);
        end

        % Value changed function: PerformDriftCorrectionCheckBox
        function PerformDriftCorrectionCheckBoxValueChanged(app, event)
            value = app.PerformDriftCorrectionCheckBox.Value;
            if value == 0
                app.Switch_3.Value = "No Reference Bead";
            end
            manageDriftCorrectionPanel(app);
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            value = app.Switch_3.Value;
            if value == "Reference Bead"
                disableLoadData(app);
            else
                enableLoadData(app);
            end
            manageDriftCorrectionPanel(app);
        end

        % Button pushed function: SelectMainFolderButton_2
        function SelectMainFolderButton_2Pushed(app, event)
            %gets the head folder for nested folder file search
            app.MainFolder = uigetdir;
            if ~isempty(app.MainFolder)
                app.GetFilesButton.Enable = "on";
                app.FileStringPatternEditField.Enable = "on";
            end
        end

        % Button pushed function: GetFilesButton_2
        function GetFilesButton_2Pushed(app, event)
            app.FileLocations = nestedFolderFileSearch(app.MainFolder, app.CellFilePatternEditField.Value);
            app.BeadLocations = nestedFolderFileSearch(app.MainFolder, app.BeadFilePatternEditField.Value);
            %now we got the path to the files we are interested in. we are
            %happy.   
            app.Label.Text = int2str(size(app.FileLocations,1));
            %check that we have the same size, for every cell a bead
            if size(app.FileLocations,1) == size(app.BeadLocations,1)
                %Enable more options if files are found
                checkForLocalisationFiles(app);
                performSanityCheck(app);
            else
                msgbox("Number of Beads and Cells do not match!")
            end
        end

        % Value changed function: EnableTrackingCheckBox
        function EnableTrackingCheckBoxValueChanged(app, event)
            value = app.EnableTrackingCheckBox.Value;
            if value
                enableInLineTracking(app);
            else
                disableInLineTracking(app);
                app.SwiftAutomatedTrackingPanel.Enable = "on";
                app.EnableTrackingCheckBox.Enable = "on";
            end
        end

        % Button pushed function: StartTrackingButton
        function StartTrackingButtonPushed(app, event)
            %Get the files
            % Display uigetfile dialog
            filterspec = {'*.csv'};
            [~, p] = uigetfile(filterspec, 'MultiSelect','off');
            cd(p);
            cd ..;
            ProcessingDataPath = pwd;
            precisionxy = double(app.PreciisonValue.Text);
            precisionZ = double(app.PreciisonZValue.Text);
            diffractionLimit = double(app.DiffractionLimitValue.Text);
            ExpNoise = double(app.ExpNoiseRateValue.Text);
            expDispla = app.ExpDIsplEditField_2.Value;
            pBleach = app.p_BleachEditField_2.Value;
            timeStep = app.ImportSettingsStruct.customUnits.Timestep;
            timeUnit = app.ImportSettingsStruct.customUnits.TimeUnit;
            lengthUnit = app.ImportSettingsStruct.customUnits.LengthUnit;
            ChangeMin = app.StopDIffEditField_2.Value/100;
            IterMax = app.MaxIterEditField_2.Value;
            maxTrackRad = app.MaxtrackradiusEditField_2.Value;
                    if maxTrackRad < expDispla
                        maxTrackRad = expDispla*1.1;
                    end
                    if app.Switch_5.Value == "Mean"
                        MeanWeightVal = 0;
                    else
                        MeanWeightVal = 1;
                    end
            d = uiprogressdlg(app.PreprocessingWindowUIFigure,'Title','Tracking', 'Indeterminate','on');
            drawnow
            automateSwiftTracking(ProcessingDataPath, precisionxy, precisionZ, diffractionLimit, ExpNoise, expDispla, pBleach, timeStep, timeUnit, lengthUnit, ChangeMin, IterMax, maxTrackRad, MeanWeightVal);

            delete(d);
        end

        % Button pushed function: LoadmaskedfilesButton
        function LoadmaskedfilesButtonPushed(app, event)
         filterspec = {'*.mat'};
         [f, p] = uigetfile(filterspec, 'MultiSelect','off');
         % Make sure user didn't cancel uigetfile dialog
         if ~ischar(p)
            error("No file selected")
         end
         app.SideloadedData = load([p, f]).datatosave;
         app.SideloadedDataType = 1;
         enableDataFilter(app);
         enableDriftCorrection(app);
         enableSaveLocation(app);
         enableInLineTracking(app);
        end

        % Button pushed function: LoadprecisionfilteredfilesButton
        function LoadprecisionfilteredfilesButtonPushed(app, event)
            filterspec = {'*.mat'};
            [f, p] = uigetfile(filterspec, 'MultiSelect','off');
             % Make sure user didn't cancel uigetfile dialog
            if ~ischar(p)
                error("No file selected")
            end
            app.SideloadedData = load([p, f]).datatosave;
            app.SideloadedDataType = 2;
            enableDataFilter(app);
            enableDriftCorrection(app);
            enableSaveLocation(app);
            enableInLineTracking(app);
        end

        % Button pushed function: LoadintesityfilteredfilesButton
        function LoadintesityfilteredfilesButtonPushed(app, event)
            filterspec = {'*.mat'};
            [f, p] = uigetfile(filterspec, 'MultiSelect','off');
            % Make sure user didn't cancel uigetfile dialog
            if ~ischar(p)
                error("No file selected")
            end
            app.SideloadedData = load([p, f]).datatosave;
            app.SideloadedDataType = 3;
            enableDriftCorrection(app);
            enableSaveLocation(app);
            enableInLineTracking(app);
        end

        % Button pushed function: LoaddriftcorrectedfilesButton
        function LoaddriftcorrectedfilesButtonPushed(app, event)
            filterspec = {'*.mat'};
            [f, p] = uigetfile(filterspec, 'MultiSelect','off');
            % Make sure user didn't cancel uigetfile dialog
            if ~ischar(p)
                error("No file selected")
            end
            app.SideloadedData = load([p, f]).datatosave;
            app.SideloadedDataType = 4;
            enableSaveLocation(app);
            enableInLineTracking(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PreprocessingWindowUIFigure = uifigure('Visible', 'off');
            app.PreprocessingWindowUIFigure.Name = 'Preprocessing Window';
            app.PreprocessingWindowUIFigure.Icon = '3rdPeak1.png';
            app.PreprocessingWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @PreprocessingWindowUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.PreprocessingWindowUIFigure);
            app.LoadDataPanel = uipanel(app.GridLayout);
            app.LoadDataPanel.Tooltip = {'Panel that controls the loading of the data. Can either process locaisation data or .tiff images.'};
            app.LoadDataPanel.Title = 'Load Data';
            app.GridLayout2 = uigridlayout(app.LoadDataPanel);
            app.FolderStructureLabel = uilabel(app.GridLayout2);
            app.FolderStructureLabel.Text = 'Folder Structure';
            app.Switch = uiswitch(app.GridLayout2, 'slider');
            app.Switch.Items = {'Single Folder', 'Nested Folder'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Tooltip = {'This switch determines the search for the file names. if all files are located in one folder (Single Folder) or if they are organized in a layered subfolders (Nested Folder Structure). In case of a nested structure, one selects the main folder and defines the file name pattern below. Then one has to press "Get Files" to search for files matching the pattern.'};
            app.Switch.Value = 'Single Folder';
            app.AllfilesinasinglefolderLabel = uilabel(app.GridLayout2);
            app.AllfilesinasinglefolderLabel.Text = 'All files in a single folder';
            app.NestedFolderStructureLabel = uilabel(app.GridLayout2);
            app.NestedFolderStructureLabel.Text = 'Nested Folder Structure';
            app.SelectFilesButton = uibutton(app.GridLayout2, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilesButtonPushed, true);
            app.SelectFilesButton.Tooltip = {'Opens a file selection dialogue based on the DataType settings set before. Will alow you to select multiple files from a single folder.'};
            app.SelectFilesButton.Text = 'Select Files';
            app.FilesFoundLabel = uilabel(app.GridLayout2);
            app.FilesFoundLabel.Text = 'Files Found:';
            app.FilesFoundNumber = uilabel(app.GridLayout2);
            app.FilesFoundNumber.Text = '0';
            app.Switch_2 = uiswitch(app.GridLayout2, 'slider');
            app.Switch_2.Items = {'Localisation Data Processing', 'Image Data Masking'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Tooltip = {'This switch defines if either localisatioon data will be processed or .tif files. For tif files, a mask can  be drawn to separate a reference bead from biological data. Useful if the bead interferes with the localisation process.'};
            app.Switch_2.Value = 'Localisation Data Processing';
            app.DataTypeLabel = uilabel(app.GridLayout2);
            app.DataTypeLabel.Text = 'Data Type';
            app.SettingsButton = uibutton(app.GridLayout2, 'push');
            app.SettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SettingsButtonPushed, true);
            app.SettingsButton.Tooltip = {'Opens the Settings Window to determine the type of the localisation file.'};
            app.SettingsButton.Text = 'Settings';
            app.FileStringPatternEditFieldLabel = uilabel(app.GridLayout2);
            app.FileStringPatternEditFieldLabel.Text = 'File String Pattern';
            app.FileStringPatternEditField = uieditfield(app.GridLayout2, 'text');
            app.FileStringPatternEditField.Tooltip = {'Define a string pattern here to look for files of interest. Use "%d" as a counting placeholder. E.g File_1; File_2; File_%d.'};
            app.GetFilesButton = uibutton(app.GridLayout2, 'push');
            app.GetFilesButton.ButtonPushedFcn = createCallbackFcn(app, @GetFilesButtonPushed, true);
            app.GetFilesButton.Tooltip = {'After selecting the main folder and defining the File String Pattern, press this button to search for your files. The number of files found will be shown at "Files Found"'};
            app.GetFilesButton.Text = 'Get Files';
            app.SelectMainFolderButton = uibutton(app.GridLayout2, 'push');
            app.SelectMainFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectMainFolderButtonPushed, true);
            app.SelectMainFolderButton.Tooltip = {'Opens a folder select dialogue to select uppermost folder of the nested folder structure to be searched in for files matching the string pattern.'};
            app.SelectMainFolderButton.Text = 'Select Main Folder';
            app.DataFilterPanel = uipanel(app.GridLayout);
            app.DataFilterPanel.Tooltip = {'Panel to control the filters applied to your localisation data. Usually, localisation can be quite noisy. To remove unwanted localisation, we can either use their coordinates, their precision value or their intensity.'};
            app.DataFilterPanel.Title = 'Data Filter';
            app.GridLayout3 = uigridlayout(app.DataFilterPanel);
            app.FilterbyPositionLabel = uilabel(app.GridLayout3);
            app.FilterbyPositionLabel.Text = 'Filter by Position';
            app.UseManualPolymaskCheckBox = uicheckbox(app.GridLayout3);
            app.UseManualPolymaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseManualPolymaskCheckBoxValueChanged, true);
            app.UseManualPolymaskCheckBox.Tooltip = {'If this is set active, a window for each of your files will be openend that allows you to manually draw around your region of interest. Press space to start drawing. Use left click to set the boundaries of your region and double click to finish. The same window will open again in case there are multiple regions of interest or a manual error occured in the first process. Repeat the process described above, or, if everything is already done, press Enter to move on to the next file.'};
            app.UseManualPolymaskCheckBox.Text = 'Use Manual Polymask';
            app.XLabel = uilabel(app.GridLayout3);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.GridLayout3);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.GridLayout3);
            app.ZLabel.Text = 'Z';
            app.FilterbyPrecisionLabel = uilabel(app.GridLayout3);
            app.FilterbyPrecisionLabel.Text = 'Filter by Precision';
            app.XYLabel = uilabel(app.GridLayout3);
            app.XYLabel.Text = 'XY';
            app.ZLabel_2 = uilabel(app.GridLayout3);
            app.ZLabel_2.Text = 'Z';
            app.MinEditField_4Label = uilabel(app.GridLayout3);
            app.MinEditField_4Label.Text = 'Min';
            app.MinEditField_4 = uieditfield(app.GridLayout3, 'numeric');
            app.MinEditField_4.ValueChangedFcn = createCallbackFcn(app, @MinEditField_4ValueChanged, true);
            app.MinEditField_4.Tooltip = {'Defines the minimal value of the X Coordinate to be kept.'};
            app.MaxEditFieldLabel = uilabel(app.GridLayout3);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.GridLayout3, 'numeric');
            app.MaxEditField.ValueChangedFcn = createCallbackFcn(app, @MaxEditFieldValueChanged, true);
            app.MaxEditField.Tooltip = {'Defines the maximal value of the X Coordinate to be kept.'};
            app.MinEditField_5Label = uilabel(app.GridLayout3);
            app.MinEditField_5Label.Text = 'Min';
            app.MinEditField_5 = uieditfield(app.GridLayout3, 'numeric');
            app.MinEditField_5.ValueChangedFcn = createCallbackFcn(app, @MinEditField_5ValueChanged, true);
            app.MinEditField_5.Tooltip = {'Defines the minimal value of the Y Coordinate to be kept.'};
            app.MaxEditField_2Label = uilabel(app.GridLayout3);
            app.MaxEditField_2Label.Text = 'Max';
            app.MaxEditField_2 = uieditfield(app.GridLayout3, 'numeric');
            app.MaxEditField_2.ValueChangedFcn = createCallbackFcn(app, @MaxEditField_2ValueChanged, true);
            app.MaxEditField_2.Tooltip = {'Defines the maximal value of the Y Coordinate to be kept.'};
            app.MinEditField_6Label = uilabel(app.GridLayout3);
            app.MinEditField_6Label.Text = 'Min';
            app.MinEditField_6 = uieditfield(app.GridLayout3, 'numeric');
            app.MinEditField_6.ValueChangedFcn = createCallbackFcn(app, @MinEditField_6ValueChanged, true);
            app.MinEditField_6.Tooltip = {'Defines the minimal value of the Z Coordinate to be kept.'};
            app.MaxEditField_3Label = uilabel(app.GridLayout3);
            app.MaxEditField_3Label.Text = 'Max';
            app.MaxEditField_3 = uieditfield(app.GridLayout3, 'numeric');
            app.MaxEditField_3.ValueChangedFcn = createCallbackFcn(app, @MaxEditField_3ValueChanged, true);
            app.MaxEditField_3.Tooltip = {'Defines the maximal value of the Z Coordinate to be kept.'};
            app.SigmaEditFieldLabel = uilabel(app.GridLayout3);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.GridLayout3, 'numeric');
            app.SigmaEditField.ValueChangedFcn = createCallbackFcn(app, @SigmaEditFieldValueChanged, true);
            app.SigmaEditField.Tooltip = {'Give the minimal localisation precision allowed for XY..'};
            app.SigmaEditField_3Label = uilabel(app.GridLayout3);
            app.SigmaEditField_3Label.Text = 'Sigma';
            app.SigmaEditField_3 = uieditfield(app.GridLayout3, 'numeric');
            app.SigmaEditField_3.ValueChangedFcn = createCallbackFcn(app, @SigmaEditField_3ValueChanged, true);
            app.SigmaEditField_3.Tooltip = {'Give the minimal localisation precision allowed for Z..'};
            app.FilterbyIntensityLabel = uilabel(app.GridLayout3);
            app.FilterbyIntensityLabel.Text = 'Filter by Intensity';
            app.FilterActiveCheckBoxPositionX = uicheckbox(app.GridLayout3);
            app.FilterActiveCheckBoxPositionX.Tooltip = {'Shows if the filter will be used or not'};
            app.FilterActiveCheckBoxPositionX.Text = 'Filter Active';
            app.FilterActiveCheckBoxPositionY = uicheckbox(app.GridLayout3);
            app.FilterActiveCheckBoxPositionY.Tooltip = {'Shows if the filter will be used or not'};
            app.FilterActiveCheckBoxPositionY.Text = 'Filter Active';
            app.FilterActiveCheckBoxPositionZ = uicheckbox(app.GridLayout3);
            app.FilterActiveCheckBoxPositionZ.Tooltip = {'Shows if the filter will be used or not'};
            app.FilterActiveCheckBoxPositionZ.Text = 'Filter Active';
            app.FilterActiveCheckBoxPrecisionXY = uicheckbox(app.GridLayout3);
            app.FilterActiveCheckBoxPrecisionXY.Tooltip = {'Shows if the filter will be used or not.'};
            app.FilterActiveCheckBoxPrecisionXY.Text = 'Filter Active';
            app.FilterActiveCheckBoxPrecisionZ = uicheckbox(app.GridLayout3);
            app.FilterActiveCheckBoxPrecisionZ.Tooltip = {'Shows if the filter will be used or not.'};
            app.FilterActiveCheckBoxPrecisionZ.Text = 'Filter Active';
            app.FilterActiveCheckBoxIntensity = uicheckbox(app.GridLayout3);
            app.FilterActiveCheckBoxIntensity.Tooltip = {'Shows if the filter will be used or not.'};
            app.FilterActiveCheckBoxIntensity.Text = 'Filter Active';
            app.MinEditField_7Label = uilabel(app.GridLayout3);
            app.MinEditField_7Label.Text = 'Min';
            app.MinEditField_7 = uieditfield(app.GridLayout3, 'numeric');
            app.MinEditField_7.ValueChangedFcn = createCallbackFcn(app, @MinEditField_7ValueChanged, true);
            app.MinEditField_7.Tooltip = {'Defines the minimal intensity value for your localisation that will be kept.'};
            app.MaxEditField_4Label = uilabel(app.GridLayout3);
            app.MaxEditField_4Label.Text = 'Max';
            app.MaxEditField_4 = uieditfield(app.GridLayout3, 'numeric');
            app.MaxEditField_4.ValueChangedFcn = createCallbackFcn(app, @MaxEditField_4ValueChanged, true);
            app.MaxEditField_4.Tooltip = {'Defines the maximal intensity value of the localisations to be kept.'};
            app.UnitUsedLabel = uilabel(app.GridLayout3);
            app.UnitUsedLabel.Text = ' ';
            app.UnitUsedLabel_2 = uilabel(app.GridLayout3);
            app.UnitUsedLabel_2.Text = ' ';
            app.UnitUsedLabel_4 = uilabel(app.GridLayout3);
            app.UnitUsedLabel_4.Text = ' ';
            app.UnitUsedLabel_5 = uilabel(app.GridLayout3);
            app.UnitUsedLabel_5.Text = ' ';
            app.LoadmaskedfilesButton = uibutton(app.GridLayout3, 'push');
            app.LoadmaskedfilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmaskedfilesButtonPushed, true);
            app.LoadmaskedfilesButton.Text = 'Load masked files';
            app.LoadprecisionfilteredfilesButton = uibutton(app.GridLayout3, 'push');
            app.LoadprecisionfilteredfilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadprecisionfilteredfilesButtonPushed, true);
            app.LoadprecisionfilteredfilesButton.Text = 'Load precision-filtered files';
            app.UnitUsedLabel_3 = uilabel(app.GridLayout3);
            app.UnitUsedLabel_3.Text = ' ';
            app.DriftCorrectionPanel = uipanel(app.GridLayout);
            app.DriftCorrectionPanel.Tooltip = {'Panel to determine thefiles for the drift correction.'};
            app.DriftCorrectionPanel.Title = 'Drift Correction';
            app.GridLayout4 = uigridlayout(app.DriftCorrectionPanel);
            app.PerformDriftCorrectionCheckBox = uicheckbox(app.GridLayout4);
            app.PerformDriftCorrectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @PerformDriftCorrectionCheckBoxValueChanged, true);
            app.PerformDriftCorrectionCheckBox.Tooltip = {'Determines if your data will be drift corrected using a mean shift approach.'};
            app.PerformDriftCorrectionCheckBox.Text = 'Perform Drift Correction';
            app.SelectMainFolderButton_2 = uibutton(app.GridLayout4, 'push');
            app.SelectMainFolderButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectMainFolderButton_2Pushed, true);
            app.SelectMainFolderButton_2.Tooltip = {'Select the uppermost folder of your subfolder hirachy.'};
            app.SelectMainFolderButton_2.Text = 'Select Main Folder';
            app.GetFilesButton_2 = uibutton(app.GridLayout4, 'push');
            app.GetFilesButton_2.ButtonPushedFcn = createCallbackFcn(app, @GetFilesButton_2Pushed, true);
            app.GetFilesButton_2.Tooltip = {'After selecting the main folder and defining the File String Pattern, press this button to search for your files.'};
            app.GetFilesButton_2.Text = 'Get Files';
            app.Switch_3 = uiswitch(app.GridLayout4, 'slider');
            app.Switch_3.Items = {'No Reference Bead', 'Reference Bead'};
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_3.Tooltip = {'If "No Reference Bead" is selected, data will be shift corrected using the intrinsic shift in the locations per file. This will work with the files selected in the "Load Data" panel. Localisation will be filtered first and then corrected for drift. If "Reference Bead" is chosen, one will have to point the software to the localisation files of the beads and cells respectively. Will overwrite the files selected in the "Load Data" panel. Cells will be processed depending on the filters used and then the drift determined by the bead localisation will be applied to the cell.'};
            app.Switch_3.Value = 'No Reference Bead';
            app.FilePairsFoundLabel = uilabel(app.GridLayout4);
            app.FilePairsFoundLabel.Text = 'File Pairs Found:';
            app.Label = uilabel(app.GridLayout4);
            app.Label.Text = '0';
            app.LoadintesityfilteredfilesButton = uibutton(app.GridLayout4, 'push');
            app.LoadintesityfilteredfilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadintesityfilteredfilesButtonPushed, true);
            app.LoadintesityfilteredfilesButton.Text = 'Load intesity-filtered files';
            app.BeadFilePatternLabel = uilabel(app.GridLayout4);
            app.BeadFilePatternLabel.Text = 'Bead File Pattern';
            app.BeadFilePatternEditField = uieditfield(app.GridLayout4, 'text');
            app.BeadFilePatternEditField.Tooltip = {'Define a string pattern here to look for files of interest. Use "%d" as a counting placeholder. E.g File_1; File_2; File_%d.'};
            app.BeadFilePatternEditField.Value = '*_bead.*';
            app.CellFilePatternLabel = uilabel(app.GridLayout4);
            app.CellFilePatternLabel.Text = 'Cell File Pattern';
            app.CellFilePatternEditField = uieditfield(app.GridLayout4, 'text');
            app.CellFilePatternEditField.Tooltip = {'Define a string pattern here to look for files of interest. Use "%d" as a counting placeholder. E.g File_1; File_2; File_%d.'};
            app.CellFilePatternEditField.Value = '*_cell.*';
            app.SwiftAutomatedTrackingPanel = uipanel(app.GridLayout);
            app.SwiftAutomatedTrackingPanel.Title = 'Swift Automated Tracking';
            app.GridLayout5 = uigridlayout(app.SwiftAutomatedTrackingPanel);
            app.EnableTrackingCheckBox = uicheckbox(app.GridLayout5);
            app.EnableTrackingCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableTrackingCheckBoxValueChanged, true);
            app.EnableTrackingCheckBox.Text = 'Enable Tracking';
            app.LoaddriftcorrectedfilesButton = uibutton(app.GridLayout5, 'push');
            app.LoaddriftcorrectedfilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddriftcorrectedfilesButtonPushed, true);
            app.LoaddriftcorrectedfilesButton.Text = 'Load drift-corrected files';
            app.MeanWeightSwitch = uiswitch(app.GridLayout5, 'slider');
            app.MeanWeightSwitch.Items = {'Mean', 'Weighted Mean'};
            app.MeanWeightSwitch.Value = 'Mean';
            app.ExpDIsplEditFieldLabel = uilabel(app.GridLayout5);
            app.ExpDIsplEditFieldLabel.Text = 'Exp. DIspl.';
            app.ExpDIsplEditField = uieditfield(app.GridLayout5, 'numeric');
            app.ExpDIsplEditField.Tooltip = {'The distance a particle will move between frames.'};
            app.ExpDIsplEditField.Value = 250;
            app.p_BleachLabel_2 = uilabel(app.GridLayout5);
            app.p_BleachLabel_2.Text = 'p_Bleach';
            app.p_BleachEditField = uieditfield(app.GridLayout5, 'numeric');
            app.p_BleachEditField.Tooltip = {'The probability of a particle to bleach between frames. '};
            app.p_BleachEditField.Value = 0.1;
            app.StopDIffEditFieldLabel = uilabel(app.GridLayout5);
            app.StopDIffEditFieldLabel.Text = 'Stop DIff. %';
            app.StopDIffEditField = uieditfield(app.GridLayout5, 'numeric');
            app.StopDIffEditField.Value = 5;
            app.MaxIterEditFieldLabel = uilabel(app.GridLayout5);
            app.MaxIterEditFieldLabel.Text = 'Max. Iter.';
            app.MaxIterEditField = uieditfield(app.GridLayout5, 'numeric');
            app.MaxIterEditField.Value = 10;
            app.MaxtrackradiusEditFieldLabel = uilabel(app.GridLayout5);
            app.MaxtrackradiusEditFieldLabel.Text = 'Max. track radius';
            app.MaxtrackradiusEditField = uieditfield(app.GridLayout5, 'numeric');
            app.MaxtrackradiusEditField.Tooltip = {'Maximum radius of a emitter between two frames.'};
            app.MaxtrackradiusEditField.Value = 500;
            app.LocalisationDataPreprocessingLabel = uilabel(app.GridLayout);
            app.LocalisationDataPreprocessingLabel.Text = 'Localisation Data Preprocessing';
            app.SaveLocationPanel = uipanel(app.GridLayout);
            app.SaveLocationPanel.Tooltip = {'Panel to set a save location.'};
            app.SaveLocationPanel.Title = 'Save Location';
            app.GridLayout6 = uigridlayout(app.SaveLocationPanel);
            app.SetSaveFolderButton = uibutton(app.GridLayout6, 'push');
            app.SetSaveFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SetSaveFolderButtonPushed, true);
            app.SetSaveFolderButton.Tooltip = {'Select a save folder in which the results will be saved to. A subfolder per analysis will be generated depending on the name given to the right. The foldername will increase in number if the name is already used.'};
            app.SetSaveFolderButton.Text = 'Set Save Folder';
            app.NoFolderSelectedLabel = uilabel(app.GridLayout6);
            app.NoFolderSelectedLabel.Tooltip = {'Shows the current save folder path.'};
            app.NoFolderSelectedLabel.Text = 'No Folder Selected';
            app.EditField = uieditfield(app.GridLayout6, 'text');
            app.EditField.Tooltip = {'Folder name to save the analysed data to. Default is Analysis.'};
            app.EditField.Value = 'Analysis';
            app.AlsosaveascsvCheckBox = uicheckbox(app.GridLayout6);
            app.AlsosaveascsvCheckBox.Text = 'Also save as .csv';
            app.StartProcessingButton = uibutton(app.GridLayout, 'push');
            app.StartProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @StartProcessingButtonPushed, true);
            app.StartProcessingButton.Tooltip = {'Starts the filtering of the data and correct for drift if selected. Will save the processed localisations in a format to be used by swift for tracking (csv). Also some parameters for tracking will be calculated and displayed.'};
            app.StartProcessingButton.Text = 'Start Processing';
            app.SwiftParameterEstimationPanel = uipanel(app.GridLayout);
            app.SwiftParameterEstimationPanel.Tooltip = {'Panel to display determined parameters that can be used for tracking parameter estimation.'};
            app.SwiftParameterEstimationPanel.Title = ' (Swift) Parameter Estimation';
            app.GridLayout7 = uigridlayout(app.SwiftParameterEstimationPanel);
            app.SelectFileButton = uibutton(app.GridLayout7, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Tooltip = {'Select a tracked file to estimate the displacement and the bleach value. A few iterations of adjusting this value and re-tracking should lead to a convergance to a suitable value.'};
            app.SelectFileButton.Text = 'Select File';
            app.p_BleachLabel = uilabel(app.GridLayout7);
            app.p_BleachLabel.Tooltip = {'The probability of a particle to bleach between frames. '};
            app.p_BleachLabel.Text = 'p_Bleach';
            app.ExpDisplacementLabel = uilabel(app.GridLayout7);
            app.ExpDisplacementLabel.Tooltip = {'The distance a particle will move between frames.'};
            app.ExpDisplacementLabel.Text = 'Exp Displacement';
            app.PrecisionXYLabel = uilabel(app.GridLayout7);
            app.PrecisionXYLabel.Tooltip = {'Precision of XY determined from the localisation data.'};
            app.PrecisionXYLabel.Text = 'PrecisionXY';
            app.DiffractionLimitLabel = uilabel(app.GridLayout7);
            app.DiffractionLimitLabel.Tooltip = {'Minimal distance of two points to be separated.'};
            app.DiffractionLimitLabel.Text = 'Diffraction Limit';
            app.ExpNoiseRateLabel = uilabel(app.GridLayout7);
            app.ExpNoiseRateLabel.Tooltip = {'The amount of background signal'};
            app.ExpNoiseRateLabel.Text = 'Exp Noise Rate';
            app.p_BleachValue = uilabel(app.GridLayout7);
            app.p_BleachValue.Text = 'p_Bleach';
            app.ExpDisplacementValue = uilabel(app.GridLayout7);
            app.ExpDisplacementValue.Text = 'Exp Displacement';
            app.PreciisonValue = uilabel(app.GridLayout7);
            app.PreciisonValue.Text = 'PrecisonXY';
            app.DiffractionLimitValue = uilabel(app.GridLayout7);
            app.DiffractionLimitValue.Text = 'Diffraction Limit';
            app.ExpNoiseRateValue = uilabel(app.GridLayout7);
            app.ExpNoiseRateValue.Text = 'Exp Noise Rate';
            app.PrecisionZLabel = uilabel(app.GridLayout7);
            app.PrecisionZLabel.Tooltip = {'Precision of Z determined from the localisation data.'};
            app.PrecisionZLabel.Text = 'PrecisionZ';
            app.PreciisonZValue = uilabel(app.GridLayout7);
            app.PreciisonZValue.Text = 'PrecisonZ';
            app.DataTypeButton = uibutton(app.GridLayout7, 'push');
            app.DataTypeButton.ButtonPushedFcn = createCallbackFcn(app, @DataTypeButtonPushed, true);
            app.DataTypeButton.Tooltip = {'Opens a window to select the datatype of tracks to be loaded to refine the parameters used for tracking.'};
            app.DataTypeButton.Text = 'Data Type';
            app.SwiftAutomatedTrackingPanel_2 = uipanel(app.GridLayout);
            app.SwiftAutomatedTrackingPanel_2.Title = 'Swift Automated Tracking';
            app.GridLayout8 = uigridlayout(app.SwiftAutomatedTrackingPanel_2);
            app.StartTrackingButton = uibutton(app.GridLayout8, 'push');
            app.StartTrackingButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrackingButtonPushed, true);
            app.StartTrackingButton.Text = 'Start Tracking';
            app.Switch_5 = uiswitch(app.GridLayout8, 'slider');
            app.Switch_5.Items = {'Mean', 'Weighted Mean'};
            app.Switch_5.Value = 'Mean';
            app.ExpDIsplEditField_2Label = uilabel(app.GridLayout8);
            app.ExpDIsplEditField_2Label.Text = 'Exp. DIspl.';
            app.ExpDIsplEditField_2 = uieditfield(app.GridLayout8, 'numeric');
            app.ExpDIsplEditField_2.Value = 250;
            app.p_BleachEditField_2Label = uilabel(app.GridLayout8);
            app.p_BleachEditField_2Label.Text = 'p_Bleach';
            app.p_BleachEditField_2 = uieditfield(app.GridLayout8, 'numeric');
            app.p_BleachEditField_2.Value = 0.1;
            app.StopDIffEditField_2Label = uilabel(app.GridLayout8);
            app.StopDIffEditField_2Label.Text = 'Stop DIff. %';
            app.StopDIffEditField_2 = uieditfield(app.GridLayout8, 'numeric');
            app.StopDIffEditField_2.Value = 10;
            app.MaxIterEditField_2Label = uilabel(app.GridLayout8);
            app.MaxIterEditField_2Label.Text = 'Max. Iter.';
            app.MaxIterEditField_2 = uieditfield(app.GridLayout8, 'numeric');
            app.MaxIterEditField_2.Value = 10;
            app.MaxtrackradiusEditField_2Label = uilabel(app.GridLayout8);
            app.MaxtrackradiusEditField_2Label.Text = 'Max. track radius';
            app.MaxtrackradiusEditField_2 = uieditfield(app.GridLayout8, 'numeric');
            app.MaxtrackradiusEditField_2.Tooltip = {'Maximum radius of a emitter between two frames.'};
            app.MaxtrackradiusEditField_2.Value = 500;
            app.ResolutionValidationPanel = uipanel(app.GridLayout);
            app.ResolutionValidationPanel.Title = 'Resolution Validation';
            app.GridLayout9 = uigridlayout(app.ResolutionValidationPanel);
            app.CalculateFSCButton = uibutton(app.GridLayout9, 'push');
            app.CalculateFSCButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateFSCButtonPushed, true);
            app.CalculateFSCButton.Tooltip = {'Will calculate the Fourier Shell Correlation of tracked data to give an estimation of the resolution in the file. If the amount of data is low, the FSC might underestimate the resolution, espacially for tracks. '};
            app.CalculateFSCButton.Text = 'Calculate FSC';
            app.FSCValueLabel = uilabel(app.GridLayout9);
            app.FSCValueLabel.Text = 'FSC Value';
            app.FSCErrorLabel = uilabel(app.GridLayout9);
            app.FSCErrorLabel.Text = 'FSC Error';
            app.CalculateFRCButton = uibutton(app.GridLayout9, 'push');
            app.CalculateFRCButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateFRCButtonPushed, true);
            app.CalculateFRCButton.Tooltip = {'Will calculate the Fourier Ring Correlation of tracked data to give an estimation of the resolution in the file. If the amount of data is low, the FRC might underestimate the resolution, espacially for tracks. '};
            app.CalculateFRCButton.Text = 'Calculate FRC';
            app.FRCValueLabel = uilabel(app.GridLayout9);
            app.FRCValueLabel.Text = 'FRC Value';
            app.FRCErrorLabel = uilabel(app.GridLayout9);
            app.FRCErrorLabel.Text = 'FRCError';
            % Show the figure after all components are created
            app.PreprocessingWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PreprocessingWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PreprocessingWindowUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PreprocessingWindowUIFigure)
        end
    end
end