classdef SelectionWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ThirdPeakUIFigure    matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        Image                matlab.ui.control.Image
        ExitButton           matlab.ui.control.Button
        PreprocessingButton  matlab.ui.control.Button
        VisualisationButton  matlab.ui.control.Button
        ValidationButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        ValiDationWindow % Link to the Validation Window
        VisualiSationWindow % LInk to the Visualisation Window
        PreProcessingWindow % Link to the Batch process Window
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.ThirdPeakUIFigure, "center");
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.delete;
        end

        % Button pushed function: ValidationButton
        function ValidationButtonPushed(app, event)
            app.ThirdPeakUIFigure.Visible = "off";
            app.ValiDationWindow = ValidationWindow(app);
        end

        % Button pushed function: VisualisationButton
        function VisualisationButtonPushed(app, event)
            app.ThirdPeakUIFigure.Visible = "off";
            app.VisualiSationWindow = VisualisationWindow(app);
        end

        % Button pushed function: PreprocessingButton
        function PreprocessingButtonPushed(app, event)
            app.ThirdPeakUIFigure.Visible = "off";
            app.PreProcessingWindow = PreprocessingWindow(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ThirdPeakUIFigure = uifigure('Visible', 'off');
            app.ThirdPeakUIFigure.Name = 'ThirdPeak';
            app.ThirdPeakUIFigure.Icon = '3rdPeak1.png';
            app.GridLayout = uigridlayout(app.ThirdPeakUIFigure);
            app.ValidationButton = uibutton(app.GridLayout, 'push');
            app.ValidationButton.ButtonPushedFcn = createCallbackFcn(app, @ValidationButtonPushed, true);
            app.ValidationButton.Tooltip = {'Opens interface which helps to validate calculated positions in XYZ with the original microsocopy image/stack'};
            app.ValidationButton.Text = 'Validation';
            app.VisualisationButton = uibutton(app.GridLayout, 'push');
            app.VisualisationButton.ButtonPushedFcn = createCallbackFcn(app, @VisualisationButtonPushed, true);
            app.VisualisationButton.Tooltip = {'Interface to visualize and analzyze the track data'};
            app.VisualisationButton.Text = 'Visualisation';
            app.PreprocessingButton = uibutton(app.GridLayout, 'push');
            app.PreprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocessingButtonPushed, true);
            app.PreprocessingButton.Tooltip = {'Interface to analyze a subset of tracks previously selected in the Visualisation step.'};
            app.PreprocessingButton.Text = 'Preprocessing';
            app.ExitButton = uibutton(app.GridLayout, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = '3rdPeak1.png';
            % Show the figure after all components are created
            app.ThirdPeakUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SelectionWindow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ThirdPeakUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ThirdPeakUIFigure)
        end
    end
end