classdef SingleTrackAnalysisWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SingleTrackAnalysisUIFigure    matlab.ui.Figure
        GridLayout7                    matlab.ui.container.GridLayout
        IntensityAnglesandTrackControlPanel  matlab.ui.container.Panel
        GridLayout10                   matlab.ui.container.GridLayout
        DimensionsPanel                matlab.ui.container.Panel
        Switch_4                       matlab.ui.control.Switch
        Switch_3                       matlab.ui.control.Switch
        IntensityPanel                 matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        IntensityoptionsButtonGroup    matlab.ui.container.ButtonGroup
        CummulativeButton              matlab.ui.control.RadioButton
        MeanButton                     matlab.ui.control.RadioButton
        IntensityAx                    matlab.ui.control.UIAxes
        AnglesPanel                    matlab.ui.container.Panel
        GridLayout11                   matlab.ui.container.GridLayout
        TrackSelectionPanel            matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        NextButton                     matlab.ui.control.Button
        TrackIDDropDown                matlab.ui.control.DropDown
        TrackIDDropDownLabel           matlab.ui.control.Label
        PreviousButton                 matlab.ui.control.Button
        SpecificTrackPropertiesPanel   matlab.ui.container.Panel
        GridLayout9                    matlab.ui.container.GridLayout
        FurtherDataAnalysisPanel       matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        ConfinementRatioValueLabel     matlab.ui.control.Label
        TotaltrajectoryTimeValueLabel  matlab.ui.control.Label
        NettodistValueLabel            matlab.ui.control.Label
        TotaldistValueLabel            matlab.ui.control.Label
        ConfinementRatioLabel          matlab.ui.control.Label
        TotalTrajectoryTimeLabel       matlab.ui.control.Label
        NettodistancetraveledLabel     matlab.ui.control.Label
        TotaldistancetravelledLabel    matlab.ui.control.Label
        PropertySelectionPanel         matlab.ui.container.Panel
        Button                         matlab.ui.control.Button
        DropDown                       matlab.ui.control.DropDown
        DropDownLabel                  matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        TrackOverviewPanel             matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        Slider                         matlab.ui.control.Slider
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        VisualisationWin % the connection to the calling main app
        
        TrackData %Data of a single track directly from the main app
        PixelFrameTrackData %TrackData converted into Pixel/frame format
        Zdata %keeps the z positions
        PixelZdata %z positions of the converted data
        
        TrackID%ID of current track
        
        Pixelsize%size of a pixel
        Timestepsize %size of the timestep between frames
        LengthUnit %unit of the length
        TimeUnit %unit of the time
        
        Polaxes %polar axes element for the jump angles
        
        SaveStructure %structured array to save the calculated values
        FlatDataArray

        PropertyNames %Names of the colum variables
        PropertyIDs % Column number associated with the respective variable
        AllPropertyNames
        AllPropertyIDs
    end
    
    methods (Static)
        function struc = setSaveStruct()
            %sets the properties for the calculated values so they dont
            %need to be calcualted again and again
            s = struct();
            s.JumpDist.X = {};
            s.JumpDist.Y = {};
            s.JumpDist.Z = {};
            s.JumpDist.XY = {};
            s.JumpDist.XYZ = {};
            s.CumJumpDist.X = {};
            s.CumJumpDist.Y = {};
            s.CumJumpDist.Z = {};
            s.CumJumpDist.XY = {};
            s.CumJumpDist.XYZ = {};
            s.MeanJumpDist.X = {};
            s.MeanJumpDist.Y = {};
            s.MeanJumpDist.Z = {};
            s.MeanJumpDist.XY = {};
            s.MeanJumpDist.XYZ = {};
            s.CumMeanJumpDist.X = {};
            s.CumMeanJumpDist.Y = {};
            s.CumMeanJumpDist.Z = {};
            s.CumMeanJumpDist.XY = {};
            s.CumMeanJumpDist.XYZ = {};
            s.JumpAngles.XY = {};
            s.JumpAngles.XYZ = {};
            s.SwiftParams.MSD = {};
            s.SwiftParams.MSDerr = {};
            s.SwiftParams.D = {};
            s.SwiftParams.Derr = {};
            s.SwiftParams.type = {};
            s.InternMSD.XY.MSDclass = [];
            s.InternMSD.XY.Alpha = {};
            s.InternMSD.XY.a = {};
            s.InternMSD.XY.d = {};
            s.InternMSD.XYZ.MSDclass = [];
            s.InternMSD.XYZ.Alpha = {};
            s.InternMSD.XYZ.a = {};
            s.InternMSD.XYZ.d = {};
            s.TrackLength.Steps = {};
            s.TrackLength.NetLength.XY = {};
            s.TrackLength.NetLength.XYZ = {};
            s.TrackLength.ConfRatio.XY = {};
            s.TrackLength.ConfRatio.XYZ = {};
            s.TrackLength.AbsLength.XY = {};
            s.TrackLength.AbsLength.XYZ = {};
            s.TrackLength.CumLen = {};
            %s.TrackLength.CumLen = {};
            s.TrackLength.MeanLen.XY = {};
            s.TrackLength.MeanLen.XYZ = {};
            s.Intensities = {};
            struc = s;
        end
    end
    
    methods (Access = private)
        
        function checkNextIdPresent(app)
            %checks if a id is available before or after the current one and
            %disables the respective button if necesasry
            prevBu = app.PreviousButton;
            nextBu = app.NextButton;
            %make sure that both are enabled
            prevBu.Enable = 1;
            nextBu.Enable = 1;
            allids = app.VisualisationWin.getAllIds();
            lastid = length(allids);
            idx = find(allids == app.TrackIDDropDown.Value);
            %disable Previous if first and next if last
            if idx == 1
                prevBu.Enable = 0;
            elseif idx == lastid
                nextBu.Enable = 0;
            end
        end
        
        function adjustDataAndScale(app)
            %when offering more data import option, I have to adjust the
            %data so that is uniform and that it can be used as an overlay
            %for the microscopy image. As I cant scale the image in a sens
            %of making pixel smaller, i will have to work with the
            %track/spot data.
            %4 options: pixel/frame, pixel/unit, unit/frame and unit/unit
            %mic data is pixel/frame, so we have to make the tracks fit
            type = split(convertCharsToStrings(app.VisualisationWin.ImportSettingsStruct.customUnits.DataType),"/");
            comparison = strcmp(type, ["Pixel"; "Frame"]);
            if comparison %pixel/frame
                %if pixel/frame, nothing much has to be done
                app.PixelFrameTrackData = app.TrackData;
                app.TrackData(:,2) = round(app.TrackData(:,2)*app.Timestepsize,0);
                app.TrackData(:,3:5) = app.TrackData(:,3:5)*app.Pixelsize;
            elseif comparison(1) & (~comparison(2))%Pixel/Unit
                %if pixel/unit, must convert unit frame
                time = app.TrackData(:,2);
                time = round(time/app.Timestepsize,0);
                app.PixelFrameTrackData = app.TrackData;
                app.PixelFrameTrackData(:,2) = time;
                app.TrackData(:,3:5) = app.TrackData(:,3:5)*app.Pixelsize;
            elseif (~comparison(1)) & comparison(2)%Unit/Frame
                %if unit/frame, must convert unit to pixel with the scale
                xyzdata = app.TrackData(:,3:5);
                xyzdata = xyzdata/app.Pixelsize;
                app.PixelFrameTrackData = app.TrackData;
                app.PixelFrameTrackData(:,3:5) = xyzdata;
                app.TrackData(:,2) = app.TrackData(:,2)*app.Timestepsize;
            else
                %if unit/unit, must convert both
                time = app.TrackData(:,2);
                time = round(time/app.Timestepsize,0);
                xyzdata = app.TrackData(:,3:5);
                xyzdata = xyzdata/app.Pixelsize;
                app.PixelFrameTrackData = app.TrackData;
                app.PixelFrameTrackData(:,2) = time;
                app.PixelFrameTrackData(:,3:5) = xyzdata;
            end
        end

        function setUnitName(app)
            %adds the units from the import settings to the switch in the
            %bottom right
            items = app.Switch_4.Items;
            name = join([app.LengthUnit "/" app.TimeUnit],"");
            name = convertStringsToChars(name);
            items{2} = name;
            app.Switch_4.Items = items;
        end
        
        function getNewTrack(app,ID)
            %grabs new track data from the main app when a new track id is
            %chosen
            app.TrackData = getTrackbyID(app.VisualisationWin, ID);
        end
        
        function switch3dData(app)
            switch app.Switch_3.Value
                case "2D"
                    %flatten 3d data
                    app.PixelZdata = app.PixelFrameTrackData(:,5);
                    app.Zdata = app.TrackData(:,5);
                    app.TrackData(:,5) = 0;
                    app.PixelFrameTrackData(:,5) = 0;
                case "3D"
                    %recover 3d positions
                    if ~isempty(app.Zdata)
                        app.TrackData(:,5) = app.Zdata;
                        app.PixelFrameTrackData(:,5) = app.PixelZdata;
                    end
            end
        end
        
        function populateGraphs(app)
            %Calculates the data and calls the plotting function
            app.SaveStructure = app.setSaveStruct();
            %prepare 2d data
            switch3dData(app);
            %decide if 2d or 3d
            if app.Switch_3.Value == "2D"
                dimension = 2;
            else
                dimension = 3;
            end
            %decide if pixel/Frame or unit
            if app.Switch_4.Value == "Pixel/Frame"
                isPixel = 1;
            else 
                isPixel = 0;
            end
            %therefore decide the data format
            if isPixel
                tmptrackdata = app.PixelFrameTrackData;
            else
                tmptrackdata = app.TrackData;
            end
            %decide if mean or cummulative intensity
            if app.MeanButton.Value
                isMean = 1;
            else
                isMean = 0;
            end
            NumberofPointstofitEditField = 5;
            %generate the data and plot it
            app.SaveStructure = calculateDataSingleTrackAnalysis(tmptrackdata, dimension, NumberofPointstofitEditField, 0, app.Timestepsize, app.SaveStructure);
            outputLabels = plotAllFiguresSingleTrackAnalysis(app.SaveStructure, dimension, app.TimeUnit, app.Polaxes, app.IntensityAx, isMean, ~isPixel, app.Timestepsize);
            %setMSDAnalysisLabels(app, outputLabels);
            setFurtherDataAnalysisLabels(app);
        end 
        
        function setMSDAnalysisLabels(app, outputlabels)
            app.SwiftDValueLabel.Text = string(app.SaveStructure.SwiftParams.D{1,1});
            app.InternDValLabel.Text = outputlabels(1);
            app.InternAlphaValLabel.Text = outputlabels(2);
            app.InternRvalLabel.Text = outputlabels(3);
        end
        
        function setFurtherDataAnalysisLabels(app)
            %Sets the labels in the further data analysis tab in accordance to the
            %calculated values 
            if app.Switch_4.Value == "Pixel/Frame"
                %labels
                app.TotaldistancetravelledLabel.Text = "Total Distance Travelled [px]";
                app.NettodistancetraveledLabel.Text = "Netto Distance Travelled [px]";
                app.TotalTrajectoryTimeLabel.Text = "Total Trajectory Steps";
                app.ConfinementRatioLabel.Text = "Confinement Ratio";
            else
                %labels
                app.TotaldistancetravelledLabel.Text = sprintf("Total Distance Travelled [%s]",app.LengthUnit);
                app.NettodistancetraveledLabel.Text = sprintf("Netto Distance Travelled [%s]", app.LengthUnit);
                app.TotalTrajectoryTimeLabel.Text = "Total Trajectory Steps";
                app.ConfinementRatioLabel.Text = "Confinement Ratio";
            end
            %set the values in the GUI
            app.TotaldistValueLabel.Text = num2str(app.SaveStructure.TrackLength.AbsLength.XYZ{1,2});
            app.NettodistValueLabel.Text = num2str(app.SaveStructure.TrackLength.NetLength.XYZ{1,2});
            app.TotaltrajectoryTimeValueLabel.Text = num2str(app.SaveStructure.TrackLength.Steps{1,2});
            app.ConfinementRatioValueLabel.Text = num2str(app.SaveStructure.TrackLength.ConfRatio.XYZ{1,2});
        end

        function grabFlattendStrucData(app)
            try
            app.FlatDataArray = app.VisualisationWin.FlattedDataStruc;
            app.FlatDataArray = app.FlatDataArray(app.FlatDataArray (:,1) == app.TrackID,:);
            catch
                app.FlatDataArray = [];
            end
        end

        function OLD_fillDropDownWithProperties(app)

            %Fills the central dropdown menue with the properties contained
            %in the track file. Can select one of you choosing.
            allpropertyNames = ["TrackID", "Timestep", "X-Pos", "Y-Pos", "Z-Pos", "Ext-Jumpdistance", "Ext-Segment-D", "Ext-Segment-DErr", "Ext-Segment-DR", "Ext-Segment-MeanJumpDistance", "Ext-Segment-MeanJumpDistanceErr", "Ext-Segment-MSD", "Ext-Segment-MSDErr", "Motiontype", "SegmentStart", "SegmentLifetime", "NumberOfSegmentsInTrack", "Intensity", "SegmentMeanJumpDist-N", "Timelag", "TrackId", "Tracksegments", "DSPT-Alpha", "DSPT-D", "extra", "pval" "DSPT-Efficiency", "DSPT-logEfficiency", "DSPT-FractalDim", "DSPT-Gaussiantiy", "DSPT-Kurtosis", "DSPT-MSDratio", "DSPT-Trappedness", "DSPT-T0", "DSPT-T1", "DSPT-T2", "DSPT-T3", "DSPT-Lifetime", "DSPT-Length", "DSPT-AvgStepLength", "DSPT-AvgMSD", "DSPT-AvgDP", "DSPT-corrDP", "DSPT-signDP", "DSPT-SumSteplength", "DSPT-MinSteplength", "DSPT-MaxSteplength", "DSPT-BroadnessSteplength", "DSPT-Speed", "DSPT-Covariance", "DSPT-FractionSlow", "DSPT-FractionFast", "DSPT-Volume", "DSPT-Percent-NormalDiff", "DSPT-Percent-DirectedMot", "DSPT-Percent-ConfinedDiff", "DSPT-Percent-SuperDiff", "DSPT-NumberChangepoints", "DSPT-Instant-MSD-D", "DSPT-meanSequence", "DSPT-medianSequence", "maxSequence", "DSPT-minSequence", "DSPT-stdSequence", "DSPT-SimSeq", "IntJumpDistX", "IntJumpDistY", "IntJumpDistZ", "IntJumpDistXY", "IntJumpDistXYZ", "IntCumJumpDistX","IntCumJumpDistY","IntCumJumpDIstZ","IntCumJumpDistXY","IntCumJumpDistXYZ", "IntMeanJumpDistX", "IntMeanJumpDistY","IntMeanJumpDistZ","IntMeanJumpDistXY","IntMeanJumpDistXYZ","IntCumMeanJumpDistX","IntCumMeanJumpDistY","IntCumMeanJumpDistZ","IntCumMeanJumpDistXY","IntCumMeanJumpDistXYZ","IntJumpAnglesXY","IntJumpAnglesXYZ","IntNumbrSteps","IntNetLenXY","IntNetLenXYZ","IntConfRatioXY","IntConfRatioXYZ","IntAbsLenXY","IntAbsLenXYZ","IntMSDXYAlpha","IntMSDXYA","IntMSDXYd","IntMSDXYLogR","IntMSDXYLinR","IntMSDXYZAlpha","IntMSDXYZA","IntMSDXYZd","IntMSDXYZLogR","IntMSDXYZLinR"];
            app.AllPropertyNames = allpropertyNames;
            allpropertyIDS = [1:1:size(allpropertyNames,2)];
            allpropertyIDS = allpropertyIDS(allpropertyIDS>0);
            app.AllPropertyIDs = allpropertyIDS;
            %check if deepspt and or other data is available


            if mean(app.TrackData(:,6:22),"all") == 0 %no extra data yet
                if isempty(app.FlatDataArray)
                    %no extra data
                    allpropertyIDS = allpropertyIDS(allpropertyIDS<6);
                elseif size(app.FlatDataArray,2) < 46 & (mean(app.FlatDataArray(:,35:45),"all") == 0) %maybe now
                    allpropertyIDS = allpropertyIDS(allpropertyIDS<6 | (allpropertyIDS>65 & allpropertyIDS<95));
                elseif size(app.FlatDataArray,2) < 46
                    allpropertyIDS = allpropertyIDS(allpropertyIDS<6 | allpropertyIDS>65);
                end
                %allpropertyNames = allpropertyNames(allpropertyIDS>0);
            elseif size(app.TrackData, 2) < 24 %swift/external data
                if isempty(app.FlatDataArray)
                    %no extra data
                    allpropertyIDS = allpropertyIDS(allpropertyIDS<23);
                elseif size(app.FlatDataArray,2) < 46 & (mean(app.FlatDataArray(:,35:45),"all") == 0)%maybe now
                    allpropertyIDS = allpropertyIDS(allpropertyIDS<23 | (allpropertyIDS>65 & allpropertyIDS<95));
                elseif size(app.FlatDataArray,2) < 46
                    allpropertyIDS = allpropertyIDS(allpropertyIDS<23 | allpropertyIDS>65);
                end
                %allpropertyNames = allpropertyNames(allpropertyIDS>0);
            elseif (size(app.TrackData, 2) < 67) &  (mean(app.TrackData(:,6:22),"all") == 0)%no external but Deepspt
                if isempty(app.FlatDataArray)
                    %no extra data
                    allpropertyIDS = allpropertyIDS(allpropertyIDS<6 |(allpropertyIDS>22 & allpropertyIDS<67));
                elseif size(app.FlatDataArray,2) < 46 & (mean(app.FlatDataArray(:,35:45),"all") == 0)%maybe now
                    allpropertyIDS = allpropertyIDS(allpropertyIDS<6 |  (allpropertyIDS>22 & allpropertyIDS<95));
                elseif size(app.FlatDataArray,2) < 46
                    allpropertyIDS = allpropertyIDS(allpropertyIDS<6 | allpropertyIDS>22);
                end
                %allpropertyNames = allpropertyNames(allpropertyIDS>0);
            elseif size(app.TrackData, 2) == 66 %external and DeepSPT
                if isempty(app.FlatDataArray)
                    %no extra data
                    allpropertyIDS = allpropertyIDS(allpropertyIDS<66);
                elseif size(app.FlatDataArray,2) < 46 & (mean(app.FlatDataArray(:,35:45),"all") == 0)%maybe now
                    allpropertyIDS = allpropertyIDS(allpropertyIDS<95);
                elseif size(app.FlatDataArray,2) < 46
                    allpropertyIDS = allpropertyIDS;
                end
                %allpropertyNames = allpropertyNames(allpropertyIDS>0);
            end

            allpropertyNames = allpropertyNames(allpropertyIDS);
            %Send to app as constant variable
            app.PropertyNames = allpropertyNames;
            app.PropertyIDs = allpropertyIDS;

            %Set the dropdown menu
            propertyNames = cellstr(allpropertyNames);
            app.DropDown.Items = propertyNames;
        end


        function fillDropDownWithProperties(app)
            %Fills the central dropdown menue with the properties contained
            %in the track file. Can select one of you choosing.
            allpropertyNames = ["TrackID", "Timestep", "X-Pos", "Y-Pos", "Z-Pos", "Ext-Jumpdistance", "Ext-Segment-D", "Ext-Segment-DErr", "Ext-Segment-DR", "Ext-Segment-MeanJumpDistance", "Ext-Segment-MeanJumpDistanceErr", "Ext-Segment-MSD", "Ext-Segment-MSDErr", "Motiontype", "SegmentStart", "SegmentLifetime", "NumberOfSegmentsInTrack", "Intensity", "SegmentMeanJumpDist-N", "Timelag", "TrackId", "Tracksegments", "DSPT-Alpha", "DSPT-D", "extra", "pval" "DSPT-Efficiency", "DSPT-logEfficiency", "DSPT-FractalDim", "DSPT-Gaussiantiy", "DSPT-Kurtosis", "DSPT-MSDratio", "DSPT-Trappedness", "DSPT-T0", "DSPT-T1", "DSPT-T2", "DSPT-T3", "DSPT-Lifetime", "DSPT-Length", "DSPT-AvgStepLength", "DSPT-AvgMSD", "DSPT-AvgDP", "DSPT-corrDP", "DSPT-signDP", "DSPT-SumSteplength", "DSPT-MinSteplength", "DSPT-MaxSteplength", "DSPT-BroadnessSteplength", "DSPT-Speed", "DSPT-Covariance", "DSPT-FractionSlow", "DSPT-FractionFast", "DSPT-Volume", "DSPT-Percent-NormalDiff", "DSPT-Percent-DirectedMot", "DSPT-Percent-ConfinedDiff", "DSPT-Percent-SuperDiff", "DSPT-NumberChangepoints", "DSPT-Instant-MSD-D", "DSPT-meanSequence", "DSPT-medianSequence", "maxSequence", "DSPT-minSequence", "DSPT-stdSequence", "DSPT-SimSeq", "IntJumpDistX", "IntJumpDistY", "IntJumpDistZ", "IntJumpDistXY", "IntJumpDistXYZ", "IntCumJumpDistX","IntCumJumpDistY","IntCumJumpDIstZ","IntCumJumpDistXY","IntCumJumpDistXYZ", "IntMeanJumpDistX", "IntMeanJumpDistY","IntMeanJumpDistZ","IntMeanJumpDistXY","IntMeanJumpDistXYZ","IntCumMeanJumpDistX","IntCumMeanJumpDistY","IntCumMeanJumpDistZ","IntCumMeanJumpDistXY","IntCumMeanJumpDistXYZ","IntJumpAnglesXY","IntJumpAnglesXYZ","IntNumbrSteps","IntNetLenXY","IntNetLenXYZ","IntConfRatioXY","IntConfRatioXYZ","IntAbsLenXY","IntAbsLenXYZ","IntMSDXYAlpha","IntMSDXYA","IntMSDXYd","IntMSDXYLogR","IntMSDXYLinR","IntMSDXYZAlpha","IntMSDXYZA","IntMSDXYZd","IntMSDXYZLogR","IntMSDXYZLinR"];
            app.AllPropertyNames = allpropertyNames;
            allpropertyIDS = [1:1:size(allpropertyNames,2)];
            allpropertyIDS = allpropertyIDS(allpropertyIDS>0);
            %check if deepspt and or other data is available
            if ( (mean(app.TrackData(:,6:22),"all") == 0)  && (size(app.TrackData,2) < 24) )%no extra data yet
                if isempty(app.FlatDataArray)
                    %no extra data
                    allpropertyIDS = allpropertyIDS(allpropertyIDS<6);
                elseif size(app.FlatDataArray,2) < 46 & (mean(app.FlatDataArray(:,35:45),"all") == 0) %maybe now
                    allpropertyIDS = allpropertyIDS(allpropertyIDS<6 | (allpropertyIDS>65 & allpropertyIDS<95));
                elseif size(app.FlatDataArray,2) < 46
                    allpropertyIDS = allpropertyIDS(allpropertyIDS<6 | allpropertyIDS>65);
                end
                %allpropertyNames = allpropertyNames(allpropertyIDS>0);
            elseif size(app.TrackData, 2) < 24 %swift/external data
                if isempty(app.FlatDataArray)
                    %no extra data
                    allpropertyIDS = allpropertyIDS(allpropertyIDS<23);
                elseif size(app.FlatDataArray,2) < 46 & (mean(app.FlatDataArray(:,35:45),"all") == 0)%maybe now
                    allpropertyIDS = allpropertyIDS(allpropertyIDS<23 | (allpropertyIDS>65 & allpropertyIDS<95));
                elseif size(app.FlatDataArray,2) < 46
                    allpropertyIDS = allpropertyIDS(allpropertyIDS<23 | allpropertyIDS>65);
                end
                %allpropertyNames = allpropertyNames(allpropertyIDS>0);
            elseif (size(app.TrackData, 2) < 67) &  (mean(app.TrackData(:,6:22),"all") == 0)%no external but Deepspt
                if isempty(app.FlatDataArray)
                    %no extra data
                    allpropertyIDS = allpropertyIDS(allpropertyIDS<6 |(allpropertyIDS>22 & allpropertyIDS<66));
                elseif size(app.FlatDataArray,2) < 46 & (mean(app.FlatDataArray(:,35:45),"all") == 0)%maybe now
                    allpropertyIDS = allpropertyIDS(allpropertyIDS<6 |  (allpropertyIDS>22 & allpropertyIDS<95));
                elseif size(app.FlatDataArray,2) < 46
                    allpropertyIDS = allpropertyIDS(allpropertyIDS<6 | allpropertyIDS>22);
                end
                %allpropertyNames = allpropertyNames(allpropertyIDS>0);
            elseif size(app.TrackData, 2) == 66 %external and DeepSPT
                if isempty(app.FlatDataArray)
                    %no extra data
                    allpropertyIDS = allpropertyIDS(allpropertyIDS<66);
                elseif size(app.FlatDataArray,2) < 46 & (mean(app.FlatDataArray(:,35:45),"all") == 0)%maybe now
                    allpropertyIDS = allpropertyIDS(allpropertyIDS<95);
                elseif size(app.FlattedDataStruc,2) < 46
                    allpropertyIDS = allpropertyIDS;
                end
                %allpropertyNames = allpropertyNames(allpropertyIDS>0);
            end
            allpropertyNames = allpropertyNames(allpropertyIDS);
            %Send to app as constant variable
            app.PropertyNames = allpropertyNames;
            app.AllPropertyIDs = allpropertyIDS;

            %Set the dropdown menu
            propertyNames = cellstr(allpropertyNames);
            app.DropDown.Items = propertyNames;
            
        end

        function plotSingleTrack(app)
            Axes = app.UIAxes;
            Track = app.TrackData;
            is3D = app.Switch_3.Value;
            timepoint = ceil(app.Slider.Value);
            lengthUnit = app.LengthUnit;
            if app.Switch_4.Value == "Pixel/Frame"
                isPixel = 1;
                Track = app.PixelFrameTrackData;
                if is3D == "3D"
                    Track(:,5) = app.PixelZdata;
                end
            else
                isPixel = 0;
                Track = app.TrackData;
                if is3D == "3D"
                    Track(:,5) = app.Zdata;
                end
            end
            plotSingleTrackSingleTrackAnalysis(Axes, Track, is3D, timepoint, lengthUnit, isPixel)
        end

        function adjustSliderValues(app)
            app.Slider.Value = 1;
            app.Slider.Limits = [1 size(app.TrackData,1)];
            app.Slider.Value = size(app.TrackData,1);
        end

        function plotSelectedTrackProperty(app)
            Axes = app.UIAxes2;
            Property = app.DropDown.Value;
            propertynames = app.AllPropertyNames;
            propertyIDs = app.AllPropertyIDs;
            Track = app.TrackData;

            is3D = app.Switch_3.Value;
            timeunit = app.TimeUnit;
            stepsize = app.Timestepsize;
            lengthUnit = app.LengthUnit;
            if app.Switch_4.Value == "Pixel/Frame"
                isPixel = 1;
                Track = app.PixelFrameTrackData;
                if ~isempty(app.FlatDataArray)
                    Track(:,67:(66-5+size(app.FlatDataArray,2))) = app.FlatDataArray(:,6:end);
                end
                if is3D == "3D"
                    Track(:,5) = app.PixelZdata;
                end
            else
                isPixel = 0;
                Track = app.TrackData;
                if ~isempty(app.FlatDataArray)
                    Track(:,67:(66-5+size(app.FlatDataArray,2))) = app.FlatDataArray(:,6:end);
                end
                if is3D == "3D"
                    Track(:,5) = app.Zdata;
                end
            end

            plotTrackPropertySingleTrackAnalysis(Axes, Property, propertynames, propertyIDs, Track, is3D, isPixel, lengthUnit, timeunit, stepsize)
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, visualisationwin, trackdata, ID)
            %grab the data from the visualisation window
            app.VisualisationWin = visualisationwin;
            app.Polaxes = polaraxes(app.GridLayout11);
            app.TrackData = trackdata;
            app.TrackID = ID;
            %get the units and conversion factors
            app.Pixelsize = app.VisualisationWin.ImportSettingsStruct.customUnits.Pixelsize;
            app.Timestepsize = app.VisualisationWin.ImportSettingsStruct.customUnits.Timestep;
            app.LengthUnit = app.VisualisationWin.ImportSettingsStruct.customUnits.LengthUnit;
            app.TimeUnit = app.VisualisationWin.ImportSettingsStruct.customUnits.TimeUnit;
            adjustDataAndScale(app);
            %set the gui layout
            setUnitName(app);
            app.TrackIDDropDown.Items = visualisationwin.getAllIds();
            app.TrackIDDropDown.Value = num2str(ID);
            grabFlattendStrucData(app);
            fillDropDownWithProperties(app);
            %generate save structure
            app.SaveStructure = app.setSaveStruct();
            %calculate and plot the data
            populateGraphs(app); 
            %plot the track
            adjustSliderValues(app)
            plotSingleTrack(app);
            plotSelectedTrackProperty(app);
        end

        % Value changed function: TrackIDDropDown
        function TrackIDDropDownValueChanged(app, event)
            %new track id is choosen from drop down menu
            %get new id
            value = app.TrackIDDropDown.Value;
            ID = str2double(value);
            %set new data
            app.Zdata = [];
            app.PixelZdata = [];
            app.TrackData = app.VisualisationWin.getTrackbyID(ID);
            adjustDataAndScale(app);
            app.SaveStructure = app.setSaveStruct();
                        grabFlattendStrucData(app);
            fillDropDownWithProperties(app);
            %calcualte new data
            populateGraphs(app);
            %Plot Track
            adjustSliderValues(app)
            plotSingleTrack(app);
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            %gets the previous id in the list
            allids = app.VisualisationWin.getAllIds();
            idx = find(allids == app.TrackIDDropDown.Value);
            idx = idx-1;
            id = str2double(allids(idx));
            %gets new data associated to new id
            app.Zdata = [];
            app.PixelZdata = [];
            app.TrackData = app.VisualisationWin.getTrackbyID(id);
            app.TrackID = app.TrackData(1,1);
            app.TrackIDDropDown.Value = num2str(app.TrackID);
            adjustDataAndScale(app);
            app.SaveStructure = app.setSaveStruct();
                        grabFlattendStrucData(app);
            fillDropDownWithProperties(app);
            %calculates and plots the data
            populateGraphs(app);
            adjustSliderValues(app)
            plotSingleTrack(app);
            plotSelectedTrackProperty(app);
            %checks if there are more ids
            checkNextIdPresent(app);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            %gets the previous id in the list
            allids = app.VisualisationWin.getAllIds();
            idx = find(allids == app.TrackIDDropDown.Value);
            idx = idx+1;
            id = str2double(allids(idx));
            %gets new data associated to new id
            app.Zdata = [];
            app.TrackData = app.VisualisationWin.getTrackbyID(id);
            app.TrackID = app.TrackData(1,1);
            app.TrackIDDropDown.Value = num2str(app.TrackID);
            adjustDataAndScale(app);
            app.SaveStructure = app.setSaveStruct();
                        grabFlattendStrucData(app);
            fillDropDownWithProperties(app);
            %calculates and plots the data
            populateGraphs(app);
            adjustSliderValues(app)
            plotSingleTrack(app);
            plotSelectedTrackProperty(app);
            %checks if there are more ids
            checkNextIdPresent(app);
        end

        % Selection changed function: IntensityoptionsButtonGroup
        function IntensityoptionsButtonGroupSelectionChanged(app, event)
            selectedButton = app.IntensityoptionsButtonGroup.SelectedObject;
            populateGraphs(app);
        end

        % Close request function: SingleTrackAnalysisUIFigure
        function SingleTrackAnalysisUIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            plotSelectedTrackProperty(app);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            plotSingleTrack(app);
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            value = app.Switch_3.Value;
            plotSingleTrack(app);
            plotSelectedTrackProperty(app);
        end

        % Value changed function: Switch_4
        function Switch_4ValueChanged(app, event)
            value = app.Switch_4.Value;
            plotSingleTrack(app);
            plotSelectedTrackProperty(app);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            plotSingleTrack(app);
        end

        % Key press function: SingleTrackAnalysisUIFigure
        function SingleTrackAnalysisUIFigureKeyPress(app, event)
            value = app.Slider.Value; % get the slider value
            rotate3d(app.UIAxes,'off');
	        key = event.Key; % get the pressed key value
            step = 1;
            if strcmp(key,'leftarrow')
        	    value = round(value-step,0); % left value
            elseif strcmp(key,'rightarrow')
                value = round(value+step,0); % right value
            end
            app.Slider.Value = value; % set the slider value
            plotSingleTrack(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SingleTrackAnalysisUIFigure = uifigure('Visible', 'off');
            app.SingleTrackAnalysisUIFigure.Name = 'Single Track Analysis';
            app.SingleTrackAnalysisUIFigure.Icon = '3rdPeak1.png';
            app.SingleTrackAnalysisUIFigure.CloseRequestFcn = createCallbackFcn(app, @SingleTrackAnalysisUIFigureCloseRequest, true);
            app.SingleTrackAnalysisUIFigure.KeyPressFcn = createCallbackFcn(app, @SingleTrackAnalysisUIFigureKeyPress, true);
            app.GridLayout7 = uigridlayout(app.SingleTrackAnalysisUIFigure);
            app.TrackOverviewPanel = uipanel(app.GridLayout7);
            app.TrackOverviewPanel.Title = 'Track Overview';
            app.GridLayout8 = uigridlayout(app.TrackOverviewPanel);
            app.UIAxes = uiaxes(app.GridLayout8);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Slider = uislider(app.GridLayout8);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.SpecificTrackPropertiesPanel = uipanel(app.GridLayout7);
            app.SpecificTrackPropertiesPanel.Title = 'Specific Track Properties';
            app.GridLayout9 = uigridlayout(app.SpecificTrackPropertiesPanel);
            app.UIAxes2 = uiaxes(app.GridLayout9);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.PropertySelectionPanel = uipanel(app.GridLayout9);
            app.PropertySelectionPanel.Title = 'Property Selection';
            app.DropDownLabel = uilabel(app.PropertySelectionPanel);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.PropertySelectionPanel);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.Button = uibutton(app.PropertySelectionPanel, 'push');
            app.Button.Enable = 'off';
            app.Button.Visible = 'off';
            app.FurtherDataAnalysisPanel = uipanel(app.GridLayout9);
            app.FurtherDataAnalysisPanel.Title = 'Further Data Analysis';
            app.GridLayout = uigridlayout(app.FurtherDataAnalysisPanel);
            app.TotaldistancetravelledLabel = uilabel(app.GridLayout);
            app.TotaldistancetravelledLabel.Text = 'Total distance travelled';
            app.NettodistancetraveledLabel = uilabel(app.GridLayout);
            app.NettodistancetraveledLabel.Text = 'Netto distance traveled';
            app.TotalTrajectoryTimeLabel = uilabel(app.GridLayout);
            app.TotalTrajectoryTimeLabel.Text = 'Total Trajectory Time';
            app.ConfinementRatioLabel = uilabel(app.GridLayout);
            app.ConfinementRatioLabel.Text = 'Confinement Ratio';
            app.TotaldistValueLabel = uilabel(app.GridLayout);
            app.TotaldistValueLabel.Text = 'TotaldistValue';
            app.NettodistValueLabel = uilabel(app.GridLayout);
            app.NettodistValueLabel.Text = 'NettodistValue';
            app.TotaltrajectoryTimeValueLabel = uilabel(app.GridLayout);
            app.TotaltrajectoryTimeValueLabel.Text = 'TotaltrajectoryTimeValue';
            app.ConfinementRatioValueLabel = uilabel(app.GridLayout);
            app.ConfinementRatioValueLabel.Text = 'ConfinementRatioValue';
            app.IntensityAnglesandTrackControlPanel = uipanel(app.GridLayout7);
            app.IntensityAnglesandTrackControlPanel.Title = 'Intensity, Angles and Track Control';
            app.GridLayout10 = uigridlayout(app.IntensityAnglesandTrackControlPanel);
            app.TrackSelectionPanel = uipanel(app.GridLayout10);
            app.TrackSelectionPanel.Title = 'Track Selection';
            app.GridLayout4 = uigridlayout(app.TrackSelectionPanel);
            app.PreviousButton = uibutton(app.GridLayout4, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Tooltip = {'Jump to the previous track in the ID list.'};
            app.PreviousButton.Text = 'Previous';
            app.TrackIDDropDownLabel = uilabel(app.GridLayout4);
            app.TrackIDDropDownLabel.Text = 'Track ID';
            app.TrackIDDropDown = uidropdown(app.GridLayout4);
            app.TrackIDDropDown.ValueChangedFcn = createCallbackFcn(app, @TrackIDDropDownValueChanged, true);
            app.NextButton = uibutton(app.GridLayout4, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Tooltip = {'Jump to the next track in the ID list.'};
            app.NextButton.Text = 'Next';
            app.AnglesPanel = uipanel(app.GridLayout10);
            app.AnglesPanel.Title = 'Angles';
            app.GridLayout11 = uigridlayout(app.AnglesPanel);
            app.IntensityPanel = uipanel(app.GridLayout10);
            app.IntensityPanel.Title = 'Intensity';
            app.GridLayout3 = uigridlayout(app.IntensityPanel);
            app.IntensityAx = uiaxes(app.GridLayout3);
            title(app.IntensityAx, 'Intensity')
            xlabel(app.IntensityAx, 'X')
            ylabel(app.IntensityAx, 'Y')
            zlabel(app.IntensityAx, 'Z')
            app.IntensityoptionsButtonGroup = uibuttongroup(app.GridLayout3);
            app.IntensityoptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @IntensityoptionsButtonGroupSelectionChanged, true);
            app.IntensityoptionsButtonGroup.Title = 'Intensity options';
            app.MeanButton = uiradiobutton(app.IntensityoptionsButtonGroup);
            app.MeanButton.Tooltip = {'Plots the mean intensity over time.'};
            app.MeanButton.Text = 'Mean';
            app.MeanButton.Value = true;
            app.CummulativeButton = uiradiobutton(app.IntensityoptionsButtonGroup);
            app.CummulativeButton.Tooltip = {'Plots the cummulaive intensity over time'};
            app.CummulativeButton.Text = 'Cummulative';
            app.DimensionsPanel = uipanel(app.GridLayout10);
            app.DimensionsPanel.Title = 'Dimensions';
            app.Switch_3 = uiswitch(app.DimensionsPanel, 'slider');
            app.Switch_3.Items = {'2D', '3D'};
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_3.Value = '2D';
            app.Switch_4 = uiswitch(app.DimensionsPanel, 'slider');
            app.Switch_4.Items = {'Pixel/Frame', 'Unit/Unit'};
            app.Switch_4.ValueChangedFcn = createCallbackFcn(app, @Switch_4ValueChanged, true);
            app.Switch_4.Value = 'Pixel/Frame';
            % Show the figure after all components are created
            app.SingleTrackAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SingleTrackAnalysisWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SingleTrackAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SingleTrackAnalysisUIFigure)
        end
    end
end