classdef ValidationWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ValidationUIFigure            matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        GridLayout6                   matlab.ui.container.GridLayout
        KaryogramPanel                matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        LocPrecZEditField             matlab.ui.control.NumericEditField
        LocPrecZEditFieldLabel        matlab.ui.control.Label
        LocPrecXYEditField            matlab.ui.control.NumericEditField
        LocPrecXYEditFieldLabel       matlab.ui.control.Label
        TrackTypeDropDown             matlab.ui.control.DropDown
        TrackTypeDropDownLabel        matlab.ui.control.Label
        NrTrackstoshowEditField       matlab.ui.control.NumericEditField
        NrTrackstoshowEditFieldLabel  matlab.ui.control.Label
        PlotKaryogramButton           matlab.ui.control.Button
        ImageTrackOverlayPanel        matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        ShowLocPrecCheckBox           matlab.ui.control.CheckBox
        ZSlicesizeEditFieldLabel      matlab.ui.control.Label
        ZSlicesizeEditField           matlab.ui.control.NumericEditField
        ZMaxIntensityProjectionCheckBox  matlab.ui.control.CheckBox
        SpotsizeEditField             matlab.ui.control.NumericEditField
        SpotsizeEditFieldLabel        matlab.ui.control.Label
        PixelsizeinNmEditField        matlab.ui.control.NumericEditField
        PixelsizeinNmEditFieldLabel   matlab.ui.control.Label
        TrackOffsetZEditField         matlab.ui.control.NumericEditField
        TrackOffsetZEditFieldLabel    matlab.ui.control.Label
        TrackOffsetYEditField         matlab.ui.control.NumericEditField
        TrackOffsetYEditFieldLabel    matlab.ui.control.Label
        TrackOffsetXEditField         matlab.ui.control.NumericEditField
        TrackOffsetXEditFieldLabel    matlab.ui.control.Label
        TrackIDDropDown               matlab.ui.control.DropDown
        TrackIDDropDownLabel          matlab.ui.control.Label
        Switch_3                      matlab.ui.control.Switch
        RotateImageButton             matlab.ui.control.Button
        FlipImageButton               matlab.ui.control.Button
        Switch                        matlab.ui.control.Switch
        DataImportPanel               matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        MinTrackLengthEditField       matlab.ui.control.NumericEditField
        MinTrackLengthEditFieldLabel  matlab.ui.control.Label
        TrackLocalisationDataLabel    matlab.ui.control.Label
        ImageDataLabel                matlab.ui.control.Label
        Switch_2                      matlab.ui.control.Switch
        BrowseButton_2                matlab.ui.control.Button
        BrowseButton                  matlab.ui.control.Button
        ImportSettingsButton          matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        ZPosEditField                 matlab.ui.control.NumericEditField
        ZPosEditFieldLabel            matlab.ui.control.Label
        StepSizeEditField             matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel        matlab.ui.control.Label
        Slider_2                      matlab.ui.control.Slider
        FrameEditField                matlab.ui.control.NumericEditField
        FrameEditFieldLabel           matlab.ui.control.Label
        Slider                        matlab.ui.control.Slider
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties
        ImportSettingsStruct %Settings from the Import Settings Window
    end
    
    properties (Access = private)
        ImportSettingsValidationWindow % Link to the Import Settings
        SelectionWindow %Link to Selection Window
        
        SettingsChangedFlag %Info if a new settings has been selected
        ImageData %Raw image tiff data from the localisation video
        MaxIntImageData % For z stacks, make a max int projection
        
        OriginalTrackData %Includes all tracks after import
        TrackData %Imported tracking data which has been filtered
        PixelFrameTrackData %track data converted into pixelFrame for plotting
        Spothandles %Handles for spots to plot
        Trackhandles %Handles for tracks to plot
        TrackIDList %ID list for the track handles

        LineC%colors for lines
        SpotC%colors for spots
        LocPrecColorbarHandle % Description
    end

    methods
        
        function updateSettings(app)
            %grabs the settings from the settings dialogue window
            if isa(app.ImportSettingsValidationWindow, "ImportSettingsValidationWindow")
                app.ImportSettingsStruct = app.ImportSettingsValidationWindow.getSettings();
                app.PixelsizeinNmEditField.Value = app.ImportSettingsStruct.customUnits.Pixelsize;
                app.PixelsizeinNmEditFieldLabel.Text = sprintf("Pixelsize in [%s]", app.ImportSettingsStruct.customUnits.LengthUnit);
            else
                %Nothing
            end
        end
        
        function setSettingsChanged(app, value)
            app.SettingsChangedFlag = value;
        end
    end
    
    methods (Access = private)
        
        function activateKaryogramPanel(app)
            app.KaryogramPanel.Enable = "on";
            child = app.GridLayout5.Children;
            set(child,"Enable","on");
        end
        
        function deactivateKaryogramPanel(app)
            app.KaryogramPanel.Enable = "off";
            child = app.GridLayout5.Children;
            set(child,"Enable","off");
        end
        
        function deactivateImageSettings(app)
            app.ImageTrackOverlayPanel.Enable = "off";
            child = app.GridLayout4.Children;
            set(child, "Enable", "off");
        end
        
        function activateImageSettings(app)
            app.ImageTrackOverlayPanel.Enable = "on";
            child = app.GridLayout4.Children;
            set(child, "Enable", "on");
        end
        
        function adjustDataAndScale(app)
            %when offering more data import option, I have to adjust the
            %data so that is uniform and that it can be used as an overlay
            %for the microscopy image. As I cant scale the image in a sens
            %of making pixel smaller, i will have to work with the
            %track/spot data.
            %4 options: pixel/frame, pixel/unit, unit/frame and unit/unit
            %mic data is pixel/frame, so we have to make the tracks fit
            type = split(convertCharsToStrings(app.ImportSettingsStruct.customUnits.DataType),"/");
            comparison = strcmp(type, ["Pixel"; "Frame"]);
            if comparison %pixel/frame
                %if pixel/frame, nothing much has to be done
                app.PixelsizeinNmEditField.Value = 1;
                app.PixelsizeinNmEditFieldLabel.Text = "Pixelsize";
                app.TrackOffsetZEditField.Value = 0;
            elseif comparison(1) & (~comparison(2))%Pixel/Unit
                %if pixel/unit, must convert unit frame
                time = app.TrackData(:,2);
                time = round(time/app.ImportSettingsStruct.customUnits.Timestep,0);
                app.TrackData(:,2) = time;
            elseif (~comparison(1)) & comparison(2)%Unit/Frame
                %if unit/frame, must convert unit to pixel with the scale
                app.PixelsizeinNmEditFieldLabel.Text = join(["Pixelsize in", app.ImportSettingsStruct.customUnits.LengthUnit]);
                app.PixelsizeinNmEditField.Value = app.ImportSettingsStruct.customUnits.Pixelsize;
                app.TrackData(:,3:4) = app.TrackData(:,3:4)/1;%(app.PixelsizeinNmEditField.Value);
            else
                %if unit/unit, must convert both
                time = app.TrackData(:,2);
                time = round(time/app.ImportSettingsStruct.customUnits.Timestep,0);
                app.TrackData(:,2) = time;
                app.TrackData(:,3:4) = app.TrackData(:,3:4)/(1/app.PixelsizeinNmEditField.Value);
                app.PixelsizeinNmEditFieldLabel.Text = join(["Pixelsize in", app.ImportSettingsStruct.customUnits.LengthUnit]);
                app.PixelsizeinNmEditField.Value = app.ImportSettingsStruct.customUnits.Pixelsize;
            end
        end

        function updateFigureImage(app)
            %function to update the background microscopy image
            if ~mean(cellfun(@isempty, app.ImageData))
                try
                    %dont throw error if only track data is loaded
                    ax = app.UIAxes;
                    frame = ceil(app.Slider.Value);
                    zValue = ceil(app.Slider_2.Value);
                    if app.ZMaxIntensityProjectionCheckBox.Value == 1
                        image(ax,app.MaxIntImageData{frame,1}(:,:),'CDataMapping','scaled');
                    else
                        if size(app.ImageData,1) == 1
                            image(ax,app.ImageData{1,1}(:,:,frame),'CDataMapping','scaled');
                        else
                            image(ax,app.ImageData{frame,1}(:,:,zValue),'CDataMapping','scaled');
                        end
                    end
                catch
                end
            else
                %nothing
            end
        end
        
        function updateTimeSlider(app)
            %adjusts the slider limits depending on the microscopy data
            if size(app.ImageData,1) == 1
                app.Slider.Limits = [1 size(app.ImageData{1,1},3)];
            else
                app.Slider.Limits = [1 size(app.ImageData,1)];
            end
            
            %set major ticks as 10 big steps depending on the frame numbers
            if size(app.ImageData,1) > 10 | size(app.ImageData{1,1},3) > 10
                ticks = [0, 0.1, 0.2, 0.3, 0.4, 0.5, 0.6, 0.7, 0.8, 0.9, 1];
                if size(app.ImageData,1) == 1
                    ticks = ticks*size(app.ImageData{1,1},3);
                else
                    ticks = ticks*size(app.ImageData,1);
                end
            else
                if size(app.ImageData,1) == 1
                    ticks = 1:1:size(app.ImageData{1,1},1);
                else
                    ticks = 1:1:size(app.ImageData,1);
                end
            end
            app.Slider.MajorTicks = ticks;
        end

        function updateZSliceSlider(app)
            %if we have Zstack data, make slider available and set its
            %range
            if size(app.ImageData,1) > 1
                if size(app.ImageData{1,1},3) > 1
                    app.Slider_2.Limits = [1, size(app.ImageData{1,1},3)];
                    ticks = 1:1:size(app.ImageData{1,1},3);
                    app.Slider_2.MajorTicks = ticks;
                    app.Slider_2.Enable = "on";
                    app.Slider_2.Visible = "on";
                    app.ZPosEditField.Enable = "on";
                    app.ZPosEditField.Visible = "on";
                end
            end
        end

        function KaryogramDiffusionTypeSelection(app)
            %checks if diffusion types are available and therefore makes
            %the filter available. if not, only "all" can be selected. Type
            %is stored in column 14 of the data.
            dim = size(app.TrackData);
            if dim(2) < 14 %no track type
                app.TrackTypeDropDown.Value = "All";
                app.TrackTypeDropDown.Enable = "off";
            else %all data present
                difftypes = app.ImportSettingsStruct.diffstates;
                difftypes = split(difftypes, ", ");
                difftypes = difftypes.';
                difftypes = ["All", difftypes];
                app.TrackTypeDropDown.Items = difftypes;
                app.TrackTypeDropDown.Value = "All";
                app.TrackTypeDropDown.Enable = "on";
            end
        end
        
        function updateTrackSelection(app)
            %Fill the selection box with the unique ids of the individual
            %tracks
            IDs = unique(app.TrackData(:,1));
            IDs = IDs.';
            IDs = string(IDs);
            app.TrackIDDropDown.Items = IDs;
        end
        
        function manageSpotsTrackOverlay(app)
            %function which manages the center figure. Will set certain
            %flags depending on the options chosen by the user. then adjust
            %the scaling depending on the values and then plots the figure
            %components
            
            %get information from the GUI
            %3d or 2d plotting
            switch app.Switch_2.Value
                case "3D"
                    flag3d = 1;
                case "2D"
                    flag3d = 0;
            end
            %show tracks/spots or not
            switch app.Switch_3.Value
                case "On"
                    flagOn = 1;
                case "Off"
                    flagOn = 0;
            end
            %show spots or tracks
            switch app.Switch.Value
                case "Spots"
                    flagtype = 0;
                case "Tracks"
                    flagtype = 1;
            end
            %grab the pixelsize
            pixelsize = app.PixelsizeinNmEditField.Value;
            unitUsed = app.ImportSettingsStruct.customUnits.LengthUnit;
            scale = 1/pixelsize;
            %the current frame
            frame = ceil(app.Slider.Value);
            %the plotted spot size used
            spotsize = app.SpotsizeEditField.Value;
            %extra zstack values
            zslicePos = app.Slider_2.Value;
            zScalingValue = app.ZSlicesizeEditField.Value;
            if size(app.ImageData,1) == 1
                isImage3D = 0;
            else
                isImage3D = 1;
            end
            %clear the figure
            cla(app.UIAxes);
            
            %set the background microscopy image
            updateFigureImage(app);
            hold(app.UIAxes,"on");
            %plots the current marker for the currently localized spot in
            %the background image
            if ~isempty(app.TrackData)
                [lineC, spotC, locPrecCb] = plotupdateTracesOverlay(app.UIAxes, app.TrackData, flag3d, flagOn, flagtype, frame, scale, app.TrackOffsetXEditField.Value, app.TrackOffsetYEditField.Value, app.TrackOffsetZEditField.Value, spotsize, app.LineC, app.SpotC, zslicePos, zScalingValue, isImage3D, app.ShowLocPrecCheckBox.Value, app.LocPrecColorbarHandle, unitUsed);
                app.LineC = lineC;
                app.SpotC = spotC;
                app.LocPrecColorbarHandle = locPrecCb;
                if app.ShowLocPrecCheckBox.Value
                else
                    colorbar(app.UIAxes, "off");
                end
            end
            hold(app.UIAxes,"off");
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            %link to the calling app
            app.SelectionWindow = mainapp;
            movegui(app.ValidationUIFigure, "center");
            app.SettingsChangedFlag = 0;
            %make track selection unavailable as long as no setting have
            %been chosen
            app.BrowseButton_2.Enable = "off";
            %set the colormap for the microscopy data
            colormap(app.UIAxes,gray);
            %this will usually open a new figure, so we hide it
            h = gcf;
            set(h, "visible", "off");     
            %no track data in the beginning, so no options
            deactivateKaryogramPanel(app);
            deactivateImageSettings(app);

            app.LineC = {};
            app.SpotC = {};
            app.LocPrecColorbarHandle = [];
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ValidationUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {693, 693};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {246, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: ImportSettingsButton
        function ImportSettingsButtonPushed(app, event)
            %prevent another settings window
            app.ImportSettingsButton.Enable = "off";
            %prevent loading of tracks
            app.BrowseButton_2.Enable = "off";
            %if a settings window is already existing, make it visibile, if
            %not make a new one
            if isa(app.ImportSettingsValidationWindow, "ImportSettingsValidationWindow")
                app.ImportSettingsValidationWindow.ImportSettingsUIFigure.Visible = "on";
            else
                app.ImportSettingsValidationWindow = ImportSettingsValidationWindow(app); %#ok<ADPROPLC> 
            end
            app.BrowseButton_2.Enable = "off";
        end

        % Close request function: ValidationUIFigure
        function ValidationUIFigureCloseRequest(app, event)
            app.SelectionWindow.ThirdPeakUIFigure.Visible = "on";
            delete(app)
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            %Button to select the microscopy image data. Search in file
            %system. Will load tiff-stacks
            % Display uigetfile dialog
            filterspec = {'*.tif'};
            [f, p] = uigetfile(filterspec, "MultiSelect","on");
            % Make sure user didn't cancel uigetfile dialog
            if ~ischar(p)
                error("No file selected")
            end
            if isa(f,"char")
                f = {f};
            end
            %show a loading widow so the user does not get impatient
            d = uiprogressdlg(app.ValidationUIFigure,'Title','Loading Tif', 'Indeterminate','on');
            drawnow
            %now load the tiff images
            tiffImages = cell(size(f,2),1);
            for i = 1:size(f,2)
                tiffImages{i,1} = loadtiff(fullfile(p,f{1,i}));
            end
            %make the max int projections
            maxIntImages = cellfun(@(x) max(x,[],3), tiffImages, "UniformOutput",false);
            
            if ~mean(cellfun(@isempty, tiffImages))
                %set the image data and adjust the GUI properties depending on
                %that
                app.ImageData = tiffImages;
                app.MaxIntImageData = maxIntImages;
                app.updateTimeSlider();
                app.updateZSliceSlider();
                app.updateFigureImage();
                app.BrowseButton_2.Enable = "on";
                %close the loading window
                close(d);
            else
                msgbox("No Microscopy data loaded!");
            end
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            %Button to select the trace data matching the microscopy data.
            %Get the settings
            updateSettings(app);
            %check if settings changed. if yes clear the data
            if app.SettingsChangedFlag
                app.TrackData = [];
                app.ImageData = [];
                cla(app.UIAxes);
            end
            %Load the data
            LocTrackData = loadDataFileSelectionVisualisation(app.ImportSettingsStruct);
            app.OriginalTrackData = LocTrackData{1,1};
            app.TrackData = filterTracksinArrayByLength(app.OriginalTrackData, app.MinTrackLengthEditField.Value);
            %make sure that the data is not empty, then set the GUI
            if ~isempty(app.TrackData)
                app.updateTrackSelection();
                adjustDataAndScale(app);
                manageSpotsTrackOverlay(app);
                updateFigureImage(app);
                KaryogramDiffusionTypeSelection(app);
                activateKaryogramPanel(app);   
                activateImageSettings(app);

                app.LineC = {};
                app.SpotC = {};
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            %adjusts track and image data to the newly selected frame
            %get the value
            value = app.Slider.Value;
            %set the new frame number
            app.FrameEditField.Value = ceil(value);
            rotate3d(app.UIAxes,'off');
            %update image and tracks
            app.updateFigureImage();
            manageSpotsTrackOverlay(app);
        end

        % Key press function: ValidationUIFigure
        function ValidationUIFigureKeyPress(app, event)
            %allows the user the navigate the frames using the left and
            %right arrow keys
            value = app.Slider.Value; % get the slider value
            zvalue = app.Slider_2.Value;
            rotate3d(app.UIAxes,'off');
	        key = event.Key; % get the pressed key value
            step = app.StepSizeEditField.Value;
            if strcmp(key,'leftarrow')
        	    value = value-step; % left value
            elseif strcmp(key,'rightarrow')
                value = value+step; % right value
            elseif strcmp(key,'uparrow')
                zvalue = zvalue+1;
            elseif strcmp(key, "downarrow")
                zvalue = zvalue-1;
            end
            app.Slider.Value = value; % set the slider value
            app.Slider_2.Value = zvalue;
            app.FrameEditField.Value = value;
            app.ZPosEditField.Value = zvalue;
            app.updateFigureImage();
            manageSpotsTrackOverlay(app);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            %changingValue = event.Value;
            
        end

        % Button pushed function: FlipImageButton
        function FlipImageButtonPushed(app, event)
            %Flips/Mirrors the image in the background
            app.ImageData = cellfun(@(x) flip(x,2), app.ImageData, 'UniformOutput', false);
            app.updateFigureImage();
            manageSpotsTrackOverlay(app);
        end

        % Button pushed function: RotateImageButton
        function RotateImageButtonPushed(app, event)
            %Rotates the image 90° counterclockwise
            app.ImageData = cellfun(@(x) rot90(x), app.ImageData, 'UniformOutput', false);
            app.updateFigureImage();
            manageSpotsTrackOverlay(app);
        end

        % Button pushed function: PlotKaryogramButton
        function PlotKaryogramButtonPushed(app, event)
            %Plots an overview of all the tracks that match the properties
            %set on the GUI.
            switch app.Switch_2.Value
                case "3D"
                    flag3d = 1;
                case "2D"
                    flag3d = 0;
            end
            Unit = app.ImportSettingsStruct.customUnits.LengthUnit;
            Karyogramm3DwithType(app.TrackData, app.NrTrackstoshowEditField.Value, flag3d, app.TrackTypeDropDown.Value, app.LocPrecXYEditField.Value, app.LocPrecZEditField.Value, Unit, app.ImportSettingsStruct.diffstates);
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            %decides if tracks and spots will be shown or not
            value = app.Switch_3.Value;
            app.updateFigureImage();
            manageSpotsTrackOverlay(app);
        end

        % Value changed function: TrackIDDropDown
        function TrackIDDropDownValueChanged(app, event)
            %will allow to jump to a given frame of a selected track id and
            %will adjust the GUI according to the new frame number etc.
            
            value = str2double(app.TrackIDDropDown.Value);
            %want to jump to the timepoint where the selected track starts
            frame = min(app.TrackData(app.TrackData(:,1)==value,2));
            app.FrameEditField.Value = frame;
            app.Slider.Value = frame;
            app.updateFigureImage();
            manageSpotsTrackOverlay(app);
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            %decides if 2D or 3D data will be shown. will change the view
            %accordingly
            
            value = app.Switch_2.Value;
            switch value
                case "2D"
                    view(app.UIAxes,2);
                    app.TrackOffsetZEditField.Value = 1;
                rotate3d(app.UIAxes,'off');
                case "3D"
                    app.TrackOffsetZEditField.Value = 500;
                    view(app.UIAxes,45,45);
                    rotate3d(app.UIAxes,'on');
            end
            app.updateFigureImage();
            manageSpotsTrackOverlay(app);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            %decides if spots or tracks will be shown
            value = app.Switch.Value;
            app.updateFigureImage();
            manageSpotsTrackOverlay(app);
        end

        % Value changed function: TrackOffsetXEditField
        function TrackOffsetXEditFieldValueChanged(app, event)
            %changes the track offset in the x direction
            value = app.TrackOffsetXEditField.Value;
            manageSpotsTrackOverlay(app);
        end

        % Value changed function: TrackOffsetYEditField
        function TrackOffsetYEditFieldValueChanged(app, event)
            %changes the track offset in the y direction
            value = app.TrackOffsetYEditField.Value;
            manageSpotsTrackOverlay(app);
        end

        % Value changed function: TrackOffsetZEditField
        function TrackOffsetZEditFieldValueChanged(app, event)
            %changes the track offset in the z direction
            value = app.TrackOffsetZEditField.Value;
            manageSpotsTrackOverlay(app);
        end

        % Value changed function: FrameEditField
        function FrameEditFieldValueChanged(app, event)
            %lets you jump to a frame of your choice
            value = app.FrameEditField.Value;
            app.Slider.Value = value;
            app.updateFigureImage();
            manageSpotsTrackOverlay(app);
        end

        % Value changed function: SpotsizeEditField
        function SpotsizeEditFieldValueChanged(app, event)
            value = app.SpotsizeEditField.Value;
            manageSpotsTrackOverlay(app);
        end

        % Value changed function: MinTrackLengthEditField
        function MinTrackLengthEditFieldValueChanged(app, event)
            value = app.MinTrackLengthEditField.Value;
            app.TrackData = filterTracksinArrayByLength(app.OriginalTrackData, value);
            updateTrackSelection(app);
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            %adjusts track and image data to the newly selected frame
            %set the new frame number
            app.ZPosEditField.Value = ceil(value);
            rotate3d(app.UIAxes,'off');
            %update image and tracks
            app.updateFigureImage();
            manageSpotsTrackOverlay(app);
            
        end

        % Value changed function: ZPosEditField
        function ZPosEditFieldValueChanged(app, event)
            value = app.ZPosEditField.Value;
            app.Slider_2.Value = value;
            app.updateFigureImage();
            manageSpotsTrackOverlay(app);
        end

        % Value changed function: ZMaxIntensityProjectionCheckBox
        function ZMaxIntensityProjectionCheckBoxValueChanged(app, event)
            value = app.ZMaxIntensityProjectionCheckBox.Value;
            app.updateFigureImage();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ValidationUIFigure = uifigure('Visible', 'off');
            app.ValidationUIFigure.Name = 'Validation';
            app.ValidationUIFigure.Icon = '3rdPeak1.png';
            app.ValidationUIFigure.CloseRequestFcn = createCallbackFcn(app, @ValidationUIFigureCloseRequest, true);
            app.ValidationUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.ValidationUIFigure.KeyPressFcn = createCallbackFcn(app, @ValidationUIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.ValidationUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout6 = uigridlayout(app.LeftPanel);
            app.DataImportPanel = uipanel(app.GridLayout6);
            app.DataImportPanel.Title = 'Data Import';
            app.GridLayout3 = uigridlayout(app.DataImportPanel);
            app.ImportSettingsButton = uibutton(app.GridLayout3, 'push');
            app.ImportSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportSettingsButtonPushed, true);
            app.ImportSettingsButton.Tooltip = {'Set the import settings first to allow for the loading of track data.'; ''};
            app.ImportSettingsButton.Text = 'Import Settings';
            app.BrowseButton = uibutton(app.GridLayout3, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Tooltip = {'Enables you to load a tiff stack for validation purposes.'};
            app.BrowseButton.Text = 'Browse';
            app.BrowseButton_2 = uibutton(app.GridLayout3, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Enable = 'off';
            app.BrowseButton_2.Tooltip = {'Opens a file select dialogue based on the import settings set to load the track data.'};
            app.BrowseButton_2.Text = 'Browse';
            app.Switch_2 = uiswitch(app.GridLayout3, 'slider');
            app.Switch_2.Items = {'3D', '2D'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Tooltip = {'Switches between 3D and 2D visualisation of your data.'};
            app.Switch_2.Value = '3D';
            app.ImageDataLabel = uilabel(app.GridLayout3);
            app.ImageDataLabel.Text = 'Image Data';
            app.TrackLocalisationDataLabel = uilabel(app.GridLayout3);
            app.TrackLocalisationDataLabel.Text = 'Track/Localisation Data';
            app.MinTrackLengthEditFieldLabel = uilabel(app.GridLayout3);
            app.MinTrackLengthEditFieldLabel.Text = 'Min. Track Length';
            app.MinTrackLengthEditField = uieditfield(app.GridLayout3, 'numeric');
            app.MinTrackLengthEditField.Limits = [1 Inf];
            app.MinTrackLengthEditField.ValueChangedFcn = createCallbackFcn(app, @MinTrackLengthEditFieldValueChanged, true);
            app.MinTrackLengthEditField.Tooltip = {'Filter the tracks by a given minimal track lenght'};
            app.MinTrackLengthEditField.Value = 5;
            app.ImageTrackOverlayPanel = uipanel(app.GridLayout6);
            app.ImageTrackOverlayPanel.Title = 'Image - Track Overlay';
            app.GridLayout4 = uigridlayout(app.ImageTrackOverlayPanel);
            app.Switch = uiswitch(app.GridLayout4, 'slider');
            app.Switch.Items = {'Spots', 'Tracks'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Tooltip = {'Switches between the single localisations (Spots) and connected Tracks.'};
            app.Switch.Value = 'Spots';
            app.FlipImageButton = uibutton(app.GridLayout4, 'push');
            app.FlipImageButton.ButtonPushedFcn = createCallbackFcn(app, @FlipImageButtonPushed, true);
            app.FlipImageButton.Tooltip = {'Flips the background image if necessary.'};
            app.FlipImageButton.Text = 'Flip Image';
            app.RotateImageButton = uibutton(app.GridLayout4, 'push');
            app.RotateImageButton.ButtonPushedFcn = createCallbackFcn(app, @RotateImageButtonPushed, true);
            app.RotateImageButton.Tooltip = {'Rotates the image counter-clockwise if ncessary.'};
            app.RotateImageButton.Text = 'Rotate Image';
            app.Switch_3 = uiswitch(app.GridLayout4, 'slider');
            app.Switch_3.Items = {'On', 'Off'};
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_3.Tooltip = {'Enables disables the track overlay.'};
            app.Switch_3.Value = 'On';
            app.TrackIDDropDownLabel = uilabel(app.GridLayout4);
            app.TrackIDDropDownLabel.Text = 'Track ID';
            app.TrackIDDropDown = uidropdown(app.GridLayout4);
            app.TrackIDDropDown.ValueChangedFcn = createCallbackFcn(app, @TrackIDDropDownValueChanged, true);
            app.TrackIDDropDown.Tooltip = {'Allows to switch to a track with the selected ID'};
            app.TrackOffsetXEditFieldLabel = uilabel(app.GridLayout4);
            app.TrackOffsetXEditFieldLabel.Text = 'Track Offset X';
            app.TrackOffsetXEditField = uieditfield(app.GridLayout4, 'numeric');
            app.TrackOffsetXEditField.ValueChangedFcn = createCallbackFcn(app, @TrackOffsetXEditFieldValueChanged, true);
            app.TrackOffsetXEditField.Tooltip = {'Moves the tracks in x direction to correct for an possible offset.'};
            app.TrackOffsetYEditFieldLabel = uilabel(app.GridLayout4);
            app.TrackOffsetYEditFieldLabel.Text = 'Track Offset Y';
            app.TrackOffsetYEditField = uieditfield(app.GridLayout4, 'numeric');
            app.TrackOffsetYEditField.ValueChangedFcn = createCallbackFcn(app, @TrackOffsetYEditFieldValueChanged, true);
            app.TrackOffsetYEditField.Tooltip = {'Moves the tracks in Y direction to correct for possible offset.'};
            app.TrackOffsetZEditFieldLabel = uilabel(app.GridLayout4);
            app.TrackOffsetZEditFieldLabel.Text = 'Track Offset Z';
            app.TrackOffsetZEditField = uieditfield(app.GridLayout4, 'numeric');
            app.TrackOffsetZEditField.ValueChangedFcn = createCallbackFcn(app, @TrackOffsetZEditFieldValueChanged, true);
            app.TrackOffsetZEditField.Tooltip = {'Moves the tracks in Z direction to correct for possible offset.'};
            app.TrackOffsetZEditField.Value = 500;
            app.PixelsizeinNmEditFieldLabel = uilabel(app.GridLayout4);
            app.PixelsizeinNmEditFieldLabel.Text = 'Pixelsize in Nm';
            app.PixelsizeinNmEditField = uieditfield(app.GridLayout4, 'numeric');
            app.PixelsizeinNmEditField.Limits = [1e-05 Inf];
            app.PixelsizeinNmEditField.Tooltip = {'Pixelsize of the image. Rescales the tracks to fit to the image.'};
            app.PixelsizeinNmEditField.Value = 160;
            app.SpotsizeEditFieldLabel = uilabel(app.GridLayout4);
            app.SpotsizeEditFieldLabel.Text = 'Spot size';
            app.SpotsizeEditField = uieditfield(app.GridLayout4, 'numeric');
            app.SpotsizeEditField.ValueChangedFcn = createCallbackFcn(app, @SpotsizeEditFieldValueChanged, true);
            app.SpotsizeEditField.Tooltip = {'Defines the size of the plotted Spots. '};
            app.SpotsizeEditField.Value = 25;
            app.ZMaxIntensityProjectionCheckBox = uicheckbox(app.GridLayout4);
            app.ZMaxIntensityProjectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZMaxIntensityProjectionCheckBoxValueChanged, true);
            app.ZMaxIntensityProjectionCheckBox.Text = 'Z-Max Intensity Projection';
            app.ZSlicesizeEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ZSlicesizeEditField.Value = 1;
            app.ZSlicesizeEditFieldLabel = uilabel(app.GridLayout4);
            app.ZSlicesizeEditFieldLabel.Text = 'Z Size';
            app.ShowLocPrecCheckBox = uicheckbox(app.GridLayout4);
            app.ShowLocPrecCheckBox.Text = 'Show LocPrec';
            app.KaryogramPanel = uipanel(app.GridLayout6);
            app.KaryogramPanel.Title = 'Karyogram';
            app.GridLayout5 = uigridlayout(app.KaryogramPanel);
            app.PlotKaryogramButton = uibutton(app.GridLayout5, 'push');
            app.PlotKaryogramButton.ButtonPushedFcn = createCallbackFcn(app, @PlotKaryogramButtonPushed, true);
            app.PlotKaryogramButton.Tooltip = {'Will polt an overview of all tracks available in an extra window, arranged in an square.'};
            app.PlotKaryogramButton.Text = 'Plot Karyogram';
            app.NrTrackstoshowEditFieldLabel = uilabel(app.GridLayout5);
            app.NrTrackstoshowEditFieldLabel.Text = 'Nr. Tracks to show';
            app.NrTrackstoshowEditField = uieditfield(app.GridLayout5, 'numeric');
            app.NrTrackstoshowEditField.Limits = [0 Inf];
            app.NrTrackstoshowEditField.Tooltip = {'Defines how many tracks will be plotted.'};
            app.TrackTypeDropDownLabel = uilabel(app.GridLayout5);
            app.TrackTypeDropDownLabel.Text = 'Track Type';
            app.TrackTypeDropDown = uidropdown(app.GridLayout5);
            app.TrackTypeDropDown.Items = {'All', 'Diffusion', 'Immobile', 'Directed', 'Directed_Diffusion', 'None'};
            app.TrackTypeDropDown.Tooltip = {'Filter the tracks by the tracktype.'};
            app.TrackTypeDropDown.Value = 'All';
            app.LocPrecXYEditFieldLabel = uilabel(app.GridLayout5);
            app.LocPrecXYEditFieldLabel.Text = 'LocPrecXY';
            app.LocPrecXYEditField = uieditfield(app.GridLayout5, 'numeric');
            app.LocPrecXYEditField.Tooltip = {'Define the localisation precision for XY here. A sphere will be plotted as the first element in the graph of the given size. Can help to identify wrong classifications as usually immobile particles are defined by the localisation precision given.'};
            app.LocPrecZEditFieldLabel = uilabel(app.GridLayout5);
            app.LocPrecZEditFieldLabel.Text = 'LocPrecZ';
            app.LocPrecZEditField = uieditfield(app.GridLayout5, 'numeric');
            app.LocPrecZEditField.Tooltip = {'Define the localisation precision for Z here. A sphere will be plotted as the first element in the graph of the given size. Can help to identify wrong classifications as usually immobile particles are defined by the localisation precision given.'};
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, ' ')
            xlabel(app.UIAxes, 'X [px]')
            ylabel(app.UIAxes, 'Y [px]')
            zlabel(app.UIAxes, 'Z [px]')
            app.Slider = uislider(app.GridLayout2);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Tooltip = {'Allows to move trhough the video frame by frame using the mouse or the left and right arrow keys.'};
            app.FrameEditFieldLabel = uilabel(app.GridLayout2);
            app.FrameEditFieldLabel.Text = 'Frame';
            app.FrameEditField = uieditfield(app.GridLayout2, 'numeric');
            app.FrameEditField.Limits = [0 Inf];
            app.FrameEditField.ValueChangedFcn = createCallbackFcn(app, @FrameEditFieldValueChanged, true);
            app.FrameEditField.Tooltip = {'Shows the given frame, but also allows to switch to a specific frame.'};
            app.Slider_2 = uislider(app.GridLayout2);
            app.Slider_2.Limits = [1 2];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.Enable = 'off';
            app.Slider_2.Visible = 'off';
            app.Slider_2.Value = 1;
            app.StepSizeEditFieldLabel = uilabel(app.GridLayout2);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.GridLayout2, 'numeric');
            app.StepSizeEditField.Limits = [1 Inf];
            app.StepSizeEditField.Tooltip = {'Defines the timestep size when using the left and right arrow keys to move through the video.'};
            app.StepSizeEditField.Value = 1;
            app.ZPosEditFieldLabel = uilabel(app.GridLayout2);
            app.ZPosEditFieldLabel.Enable = 'off';
            app.ZPosEditFieldLabel.Visible = 'off';
            app.ZPosEditFieldLabel.Text = 'Z Pos';
            app.ZPosEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ZPosEditField.Limits = [1 Inf];
            app.ZPosEditField.ValueChangedFcn = createCallbackFcn(app, @ZPosEditFieldValueChanged, true);
            app.ZPosEditField.Enable = 'off';
            app.ZPosEditField.Visible = 'off';
            app.ZPosEditField.Value = 1;
            % Show the figure after all components are created
            app.ValidationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ValidationWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ValidationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ValidationUIFigure)
        end
    end
end