classdef VisualisationWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VisualisationUIFigure           matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        ImageSettingsPanel              matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        FrameEditField                  matlab.ui.control.NumericEditField
        OffsetXEditFieldLabel_2         matlab.ui.control.Label
        OffsetYEditField                matlab.ui.control.NumericEditField
        OffsetZEditField                matlab.ui.control.NumericEditField
        OffsetZEditFieldLabel           matlab.ui.control.Label
        OffsetXEditField                matlab.ui.control.NumericEditField
        OffsetXEditFieldLabel           matlab.ui.control.Label
        ZSliceEditField                 matlab.ui.control.NumericEditField
        ZSliceEditFieldLabel            matlab.ui.control.Label
        FrameLabel                      matlab.ui.control.Label
        MaxIntensityCheckBox            matlab.ui.control.CheckBox
        AverageIntensityCheckBox        matlab.ui.control.CheckBox
        FlipButton                      matlab.ui.control.Button
        RotateButton                    matlab.ui.control.Button
        LoadReferenceImageButton        matlab.ui.control.Button
        Switch_5                        matlab.ui.control.Switch
        KaryogramPanel                  matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        TrackTypeDropDown               matlab.ui.control.DropDown
        TrackTypeDropDownLabel          matlab.ui.control.Label
        ZEditField                      matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        LocPrecXYEditField              matlab.ui.control.NumericEditField
        LocPrecXYEditFieldLabel         matlab.ui.control.Label
        NrTrackstoshowEditField         matlab.ui.control.NumericEditField
        NrTrackstoshowEditFieldLabel    matlab.ui.control.Label
        PlotKaryogramButton             matlab.ui.control.Button
        DimensionsandPlotPanel          matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        SpotsizeEditField               matlab.ui.control.NumericEditField
        SpotsizeEditFieldLabel          matlab.ui.control.Label
        TrackIDDropDown                 matlab.ui.control.DropDown
        TrackIDDropDownLabel            matlab.ui.control.Label
        AxisScaleSwitch                 matlab.ui.control.Switch
        AxisScaleSwitchLabel            matlab.ui.control.Label
        ScaleZEditField                 matlab.ui.control.NumericEditField
        ScaleZEditFieldLabel            matlab.ui.control.Label
        ScaleXYEditField                matlab.ui.control.NumericEditField
        ScaleXYEditFieldLabel           matlab.ui.control.Label
        ShowTracksSwitch                matlab.ui.control.Switch
        ShowTracksSwitchLabel           matlab.ui.control.Label
        FilterSetStatusNoFilterLabel    matlab.ui.control.Label
        UseFilterfromAllTrackAnalysisCheckBox  matlab.ui.control.CheckBox
        NextTracksButton                matlab.ui.control.Button
        PrevTracksButton                matlab.ui.control.Button
        Switch_6                        matlab.ui.control.Switch
        Switch                          matlab.ui.control.Switch
        ImportTracksPanel               matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        TmpSegWindowEditField           matlab.ui.control.NumericEditField
        TmpSegWindowEditFieldLabel      matlab.ui.control.Label
        PerformDeepSPTAnalysisCheckBox  matlab.ui.control.CheckBox
        PyLinkLamp                      matlab.ui.control.Lamp
        PyLinkLampLabel                 matlab.ui.control.Label
        MinTrackLengthEditField         matlab.ui.control.NumericEditField
        MinTrackLengthEditFieldLabel    matlab.ui.control.Label
        TrackFileDropDown               matlab.ui.control.DropDown
        TrackFileDropDownLabel          matlab.ui.control.Label
        PerformDataDriftCorrectionButton  matlab.ui.control.Button
        LoadTracksButton                matlab.ui.control.Button
        ImportSettingsButton            matlab.ui.control.Button
        CenterPanel                     matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        FilenameEditField               matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        PlotlySavelocationButton        matlab.ui.control.Button
        SaveViewasPlotlyButton          matlab.ui.control.Button
        TimeSlider                      matlab.ui.control.Slider
        TimepointEditField              matlab.ui.control.NumericEditField
        TimepointEditFieldLabel         matlab.ui.control.Label
        StepsizeEditField               matlab.ui.control.NumericEditField
        StepsizeEditFieldLabel          matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        CompleteAnalysisPanel           matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        UseSingleTracksforAnalysisCheckBox  matlab.ui.control.CheckBox
        UseROIforAnalysisCheckBox       matlab.ui.control.CheckBox
        TracksButton                    matlab.ui.control.Button
        SpotsButton                     matlab.ui.control.Button
        Switch_4                        matlab.ui.control.Switch
        SupermapGenerationPanel         matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        ExpTimeEditField                matlab.ui.control.NumericEditField
        ExpTimeEditFieldLabel           matlab.ui.control.Label
        MinClusterSizeEditField         matlab.ui.control.NumericEditField
        MinClusterSizeEditFieldLabel    matlab.ui.control.Label
        DBSCANButton                    matlab.ui.control.Button
        PropertiesListBox               matlab.ui.control.ListBox
        PropertiesListBoxLabel          matlab.ui.control.Label
        PropertyDropDown                matlab.ui.control.DropDown
        SupermapButton                  matlab.ui.control.Button
        FurtherClassificationsLabel     matlab.ui.control.Label
        SuperpxXYSizeLabel              matlab.ui.control.Label
        SuperpixelSizeZEditField        matlab.ui.control.NumericEditField
        SuperpixelSizeZEditFieldLabel   matlab.ui.control.Label
        TimeWindowsEditField            matlab.ui.control.NumericEditField
        TimeWindowsEditFieldLabel       matlab.ui.control.Label
        SuperpixelSizeXYEditField       matlab.ui.control.NumericEditField
        SuperpixelSizeXYEditFieldLabel  matlab.ui.control.Label
        DiffusionButton                 matlab.ui.control.Button
        Switch_7                        matlab.ui.control.Switch
        SuperpxZSizeLabel               matlab.ui.control.Label
        DataDensityButton               matlab.ui.control.Button
        DriftButton                     matlab.ui.control.Button
        Switch_2                        matlab.ui.control.Switch
        VelocityButton                  matlab.ui.control.Button
        ROIselectionPanel               matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        Switch_8                        matlab.ui.control.Switch
        DeleteROIButton                 matlab.ui.control.Button
        ROINameEditField                matlab.ui.control.EditField
        ROINameEditFieldLabel           matlab.ui.control.Label
        ROIListDropDown                 matlab.ui.control.DropDown
        ROIListDropDownLabel            matlab.ui.control.Label
        SaveAntiROICheckBox             matlab.ui.control.CheckBox
        LoadROIsButton                  matlab.ui.control.Button
        SaveallROIsButton               matlab.ui.control.Button
        SaveROIButton                   matlab.ui.control.Button
        SelectROIButton                 matlab.ui.control.Button
        SingleTrackAnalysisPanel        matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        TrackListDropDown               matlab.ui.control.DropDown
        TrackListDropDownLabel          matlab.ui.control.Label
        TrackNameEditField              matlab.ui.control.EditField
        TrackNameEditFieldLabel         matlab.ui.control.Label
        LoadTracksButton_2              matlab.ui.control.Button
        DeleteTrackButton               matlab.ui.control.Button
        SaveallTracksButton             matlab.ui.control.Button
        SaveSingleTrackButton           matlab.ui.control.Button
        Label_3                         matlab.ui.control.Label
        TrackIDLabel                    matlab.ui.control.Label
        AnalyzeButton                   matlab.ui.control.Button
        SelectTrackButton               matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    properties (Access = public)
        ImportSettingsStruct %Settings from the Import Settings Window
        Is2DData %bool to tell if 2d or 3d data
        FlattedDataStruc %data from all track analysis
    end
    
    properties (Access = private)
        SelectionWindow % Link to selection window
        ImportSettingsVisualisationWindow %Link to import settings window
        
        AllTrackAnalysisWindow %Link to AllTrack Analysis
        SingleTrackAnalysisWindow %Link to Single Track Analysis
        SpotStatisticsWindow %Link to spot Statistics Window
        
        ImageData %Raw image tiff data from the localisation video
        AveragedImageData %Averaged Intensity Projection of the tiff stack
        MaxIntenImageData
        
        OriginalTrackData %Data directly after import
        LengthFilteredTrackData %Imported tracking data after min step length filtering
        CurrentTrackFileData %Holds the tracks of the currently selected file
        FilteredIDs %IDs from the Data Filter of tracks that match the filter settings
        FilteredTrackData %Only contains the filtered tracks
        FilteredTrackFileName %Name of the filtered file. either All Tracks or something else
        OriginalDataPath    %Where the data comes from
        
        DeepSPTClassifiedTracks %Results from DeepSPT analysis
        
        Spothandles %Handles for spots to plot
        Trackhandles %Handles for tracks to plot
        TrackIDList %ID list for the track handles
        SavedSingleTracks %Data of tracks to be analyzed seperately
        
        AllPropertyNames
        
        ROIList %List of all the saved ROIs
        StoreAxes%Keeps plottet things save
        
        SelectedTrackData %Data of the selected track form track selection
        SelectedTrackID %ID of the selected track
        
        Scalexy %holds the scale value from the settings window
        CurrentROI % holds the current selected roi as a propertey
        SettingsChangedFlag %boolean to tell the mainapp if something has changed in the settings window
        CurrentDataTypes %keepts the current data type
        DeepSPTAvailable %Flag if Connection to python works
        PropertyNames %Values from DeepSPT
        PropertyIDs %Idx of values from DeepSPT
        
        PlotlySaveLocation % Description
    end
    
    methods (Access = public)
        function getFlattedStructArrayfromAnalysis(app, flattenData)
            app.FlattedDataStruc = flattenData;
            app.fillDropDownWithProperties();
        end
    end


    methods
        function updateSettings(app)
            %if there is a settings window, get the settings
            if isa(app.ImportSettingsVisualisationWindow, "ImportSettingsVisualisationWindow")
                app.ImportSettingsStruct = app.ImportSettingsVisualisationWindow.getSettings();
                %set properties of GUI depending on the settings
                app.CurrentDataTypes = app.ImportSettingsStruct.customUnits;
                app.LoadTracksButton.Enable = "on";
            else
                %Nothing
            end
        end
                 
        function setSettingsChanged(app, value)
            %changes the settingschangedflag to either 1 or 0
            app.SettingsChangedFlag = value;
        end
        
        function trackdata = getIntensityData(app)
            trackdata{1,2} = app.TrackFileDropDown.Value;
            trackdata{1,1} = app.CurrentTrackFileData(:,9);
        end
        

        function tracks = getTracks(app)
            %retrieves all track that are left after filtering
            tracks = app.FilteredTrackData;
        end
        
        function IDs = getAllIds(app)
            %get the trackids of the remaining tracks for the selection
            %window
            IDs = app.TrackIDList;
        end
        
        function sz = getPixelsize(app)
            %retrieves the scaling factor for the single track and all
            %track analysis
            sz = app.ScaleXYEditField.Value;
        end
        
        function ts = getTimestep(app)
            %get the timestep for the single track and all track analysis
            ts = app.ImportSettingsStruct.customUnits.Timestep;
        end
        
        function trackdata = getTrackbyID(app, ID)
            %selects a track by its id and returns the corresponding spots
            trackdata = app.CurrentTrackFileData(app.CurrentTrackFileData(:,1) == ID,:);
        end

        function trackdata = getCurrentData(app)
            %gets the data of the currently selected file
            trackdata{1,2} = app.TrackFileDropDown.Value;
            trackdata{1,1} = app.CurrentTrackFileData(:,:);
        end   
    
        function setFilteredIds(app, filterIDs, filename)
            app.FilteredIDs = filterIDs;
            app.FilteredTrackFileName = filename;
            if ~isempty(app.FilteredIDs)
                app.FilterSetStatusNoFilterLabel.Text = "Filter Set Status: Filter present!";
                app.UseFilterfromAllTrackAnalysisCheckBox.Enable = "on";
            else
                app.FilterSetStatusNoFilterLabel.Text = "Filter Set Status: No Filter";
            end
            %updateTracksAfterFilter(app);
        end
        
        function axes = getAxes(app)
            axes = app.UIAxes;
        end
        
        function setAxes(app, axes)
            app.UIAxes = axes;
        end
        
        function Trackdata = getTrackDataForAnalysis(app)
            %Decides which data goes to the analysis window given the
            %settings on the GUI
            %Gather the settings
            switch app.Switch_4.Value
                case "Current File"
                    useAllData = 0;
                case "All Files"
                    useAllData = 1;
            end
            useROIData = app.UseROIforAnalysisCheckBox.Value;
            useSingleTrackData = app.UseSingleTracksforAnalysisCheckBox.Value;
            %The checkbox will overwrite the loaded files
            Trackdata = {};
            %switch all-current
            if useAllData
                %All Files
                Trackdata = app.LengthFilteredTrackData;
            else
                %currentfile
                Trackdata{1,2} = app.TrackFileDropDown.Value;
                Trackdata{1,1} = app.CurrentTrackFileData(:,:);
            end
            %ROI check box
            if useROIData
                %only roi data
                roinames = app.ROIListDropDown.Items;
                Trackdata = cell(size(roinames,2),2);
                %data1, names2
                for i = 1:size(roinames,2)
                    Trackdata{i,1} = app.ROIList{i,3};
                    Trackdata{i,2} = roinames{1,i};
                end
            end
            if useSingleTrackData
                %name , data
                tracknames = app.TrackListDropDown.Items;
                Trackdata = cell(size(tracknames,2),2);
                for i = 1:size(tracknames,2)
                    Trackdata{i,1} = app.SavedSingleTracks{i,2};
                    Trackdata{i,2} = tracknames{1,i};
                end
            end
        end 
    end
    
    methods (Access = private)
        function updateTracksAfterFilter(app)
             if app.FilteredTrackFileName ~= "All Tracks"
                 %determine which file is filtered
                    app.TrackFileDropDown.Value = app.FilteredTrackFileName;
                    name = string(app.FilteredTrackFileName);
                    fileNames = string((app.LengthFilteredTrackData(:,2)));
                    %get the data associated to the file
                    idx = fileNames == name;
                    trackData = app.LengthFilteredTrackData{idx,1};
                    mask = ismember(trackData(:,1), app.FilteredIDs);
                    filteredTracks = trackData(mask,:);
                    app.FilteredTrackData = filteredTracks;
             else
                    allTrackFilterUsed(app);
             end
             app.TrackFileDropDownValueChanged();
        end
        
        function allTrackFilterUsed(app)
            if app.UseFilterfromAllTrackAnalysisCheckBox.Value
                if app.FilteredTrackFileName == "All Tracks"
                    trackdata = getCurrentData(app);
                    CurrentName = string(trackdata{1,2});
                    filteredNames = string((app.FilteredIDs(:,1)));
                    if any(filteredNames == CurrentName)
                        currFileIdx = filteredNames == CurrentName;
                        tracks = trackdata{1,1};
                        filterids = app.FilteredIDs{currFileIdx,2};
                        mask = ismember(tracks(:,1), filterids);
                        tracks = tracks(mask,:);
                        app.FilteredTrackData = tracks;
                    else
                        app.FilteredTrackData = trackdata{1,1};
                    end
                end
            end
        end
        
        
        function updateTrackFileSelection(app)
            %Fills the track file selection drop down menue with the
            %availabe files
            filenames = app.LengthFilteredTrackData(:,2);
            app.TrackFileDropDown.Items = filenames;
            try
                app.TrackFileDropDown.Value = filenames(1);
            catch
                f = msgbox("No tracks left after filtering! Decrease the min. track length.");
            end
        end
        
        function setCurrentTrackData(app)
            %Depending on the selected track file, the current available
            %data is stored in the currentTrackData property
            value = app.TrackFileDropDown.Value;
            index = contains(app.LengthFilteredTrackData(:,2), value);
            app.CurrentTrackFileData = app.LengthFilteredTrackData{index,1};
            %Update Track related information
            updateTrackSelection(app);
        end
        
        function updateTrackSelection(app)
            %Fill the selection box with the unique ids of the individual
            %tracks
            IDs = unique(app.CurrentTrackFileData(:,1));
            IDs = IDs.';
            IDs = string(IDs);
            app.TrackIDList = IDs;
            app.TrackIDDropDown.Items = IDs;
        end
        
        function ID = getIDfromDataTip(app)
            %gets the track id from the currently active datatip for single
            %track analysis
            lines = app.UIAxes.Children;
            for i = 1:size(lines,1)
                if ~isempty(lines(i).Children)
                datatip = lines(i).Children;
                end
            end
            ID = datatip.Content(end);
            ID = erase(ID,"Track ");
            ID = str2double(ID);
        end
        
        function updateFrameSliderLimits(app)
            %depending on the track data the frame slider will be set to
            %fit its range
            if ~isempty(app.CurrentTrackFileData)
                %got some trackdata to work with, set slider limts
                %accordingly
                mint = 1;
                maxt = max(app.CurrentTrackFileData(:,2));
                limits = [mint, maxt];
                app.TimeSlider.Limits = limits;
            elseif ~isempty(app.ImageData)
                %ony an image has been loaded
                mint = 1;
                maxt = size(app.ImageData,3);
                limits = [mint, maxt];
                app.TimeSlider.Limits = limits;
            else
                app.TimeSlider.Limits = [1,100];
            end
        end
        
        function updateROIList(app)
            %when a new roi is saved it is added to the selection menue
            names = cell(size(app.ROIList,1),1);
            for i = 1:size(app.ROIList,1)
                names{i} = strcat(app.ROIList{i,4}, "-", app.ROIList{i,1});
            end
            names = vertcat(names{:});
            if size(names,1) == 0
                names = "Nothing";
            end
            app.ROIListDropDown.Items = names;
        end
        
        function updateSingleTrackList(app)
            %when a new track is saved, it is added to the drop down menue
            names = cell(size(app.SavedSingleTracks,1),1);
            for i = 1:size(app.SavedSingleTracks,1)
                names{i} = app.SavedSingleTracks{i,1};
            end
            names = vertcat(names{:});
            if size(names,1) == 0
                names = "Nothing";
            end
            app.TrackListDropDown.Items = names;
        end
        
        function switchROI(app)
            %when one switches to an already saved roi, the current one
            %needs to be disabled and the new one enabled
            value = app.ROIListDropDown.Value;
            idx = find(strcmp(app.ROIListDropDown.Items,value));
            dim = size(app.ROIListDropDown.Items,2);
            %datacell = app.ROIList(idx,:);
            try
                delete(app.CurrentROI);
            catch
            end
            roiidx = flip(linspace(1, dim, dim));
            idx = find(roiidx == idx);
            oldROI = app.StoreAxes.Children(idx);
            app.CurrentROI = copyobj(oldROI,app.UIAxes);       
        end
    
        function adjustDataAndScale(app)
            %when offering more data import option, I have to adjust the
            %data so that is uniform and that it can be used as an overlay
            %for the microscopy image. As I cant scale the image in a sense
            %of making pixel smaller, i will have to work with the
            %track/spot data.
            %4 options: pixel/frame, pixel/unit, unit/frame and unit/unit
            %mic data is pixel/frame, so we have to make the tracks fit
            type = split(convertCharsToStrings(app.ImportSettingsStruct.customUnits.DataType),"/");
            comparison = strcmp(type, ["Pixel"; "Frame"]);
            if comparison %pixel/frame
                %if pixel/frame, nothing much has to be done
                app.ScaleXYEditField.Value = 1;
                app.ScaleXYEditFieldLabel.Text = "Scale XY [px]";
                app.ScaleZEditField.Value = 1;
            elseif comparison(1) & (~comparison(2))%Pixel/Unit
                %if pixel/unit, must convert unit frame
                time = app.TrackData(:,2);
                time = round(time/app.ImportSettingsStruct.customUnits.Timestep,0);
                app.TrackData(:,2) = time;
                app.ScaleXYEditField.Value = 1;
                app.ScaleXYEditFieldLabel.Text = "Scale XY [px]";
                app.ScaleZEditField.Value = 1;
            elseif (~comparison(1)) & comparison(2)%Unit/Frame
                %if unit/frame, must convert unit to pixel with the scale
                app.ScaleXYEditFieldLabel.Text = join(["Scale XY", app.ImportSettingsStruct.customUnits.LengthUnit]);
                app.ScaleXYEditField.Value = app.ImportSettingsStruct.customUnits.Pixelsize;
            else
                %if unit/unit, must convert both
                time = app.TrackData(:,2);
                time = round(time/app.ImportSettingsStruct.customUnits.Timestep,0);
                app.TrackData(:,2) = time;
                app.ScaleXYEditFieldLabel.Text = join(["Scale XY", app.ImportSettingsStruct.customUnits.LengthUnit]);
                app.ScaleXYEditField.Value = app.ImportSettingsStruct.customUnits.Pixelsize;
            end
            app.SuperpxXYSizeLabel.Text = app.ImportSettingsStruct.customUnits.LengthUnit;
            app.SuperpxZSizeLabel.Text = app.ImportSettingsStruct.customUnits.LengthUnit;
        end
        
        function disableNearlyEverything(app)
            app.ImageSettingsPanel.Enable = "off";
            child = app.GridLayout4.Children;
            set(child, "Enable", "off");
            app.DimensionsandPlotPanel.Enable = "off";
            child = app.GridLayout5.Children;
            set(child, "Enable", "off");
            app.KaryogramPanel.Enable = "off";
            child = app.GridLayout6.Children;
            set(child, "Enable", "off");
            app.SingleTrackAnalysisPanel.Enable = "off";
            child = app.GridLayout8.Children;
            set(child, "Enable", "off");
            app.ROIselectionPanel.Enable = "off";
            child = app.GridLayout10.Children;
            set(child, "Enable", "off");
            app.SupermapGenerationPanel.Enable = "off";
            child = app.GridLayout11.Children;
            set(child, "Enable", "off");
            app.CompleteAnalysisPanel.Enable = "off";
            child = app.GridLayout9.Children;
            set(child, "Enable", "off");
        end
        
        function enableNearlyEverything(app)
            app.ImageSettingsPanel.Enable = "on";
            child = app.GridLayout4.Children;
            set(child, "Enable", "on");
            app.DimensionsandPlotPanel.Enable = "on";
            child = app.GridLayout5.Children;
            set(child, "Enable", "on");
            app.KaryogramPanel.Enable = "on";
            child = app.GridLayout6.Children;
            set(child, "Enable", "on");
            app.SingleTrackAnalysisPanel.Enable = "on";
            child = app.GridLayout8.Children;
            set(child, "Enable", "on");
            app.ROIselectionPanel.Enable = "on";
            child = app.GridLayout10.Children;
            set(child, "Enable", "on");
            app.SupermapGenerationPanel.Enable = "on";
            child = app.GridLayout11.Children;
            set(child, "Enable", "on");
            DisableDeepSPT(app);
            app.CompleteAnalysisPanel.Enable = "on";
            child = app.GridLayout9.Children;
            set(child, "Enable", "on");
            app.SpotsButton.Visible = "off";
            if app.PerformDeepSPTAnalysisCheckBox.Value == 1 
                app.PropertyDropDown.Enable = "on";
                app.PropertiesListBox.Enable = "on";
                app.SupermapButton.Enable = "on";
                app.DBSCANButton.Enable = "on";
                app.MinClusterSizeEditField.Enable = "on";
            elseif size(app.FilteredTrackData,2) > 5
                app.PropertyDropDown.Enable = "on";
                app.PropertiesListBox.Enable = "on";
                app.SupermapButton.Enable = "on";
                app.DBSCANButton.Enable = "on";
                app.MinClusterSizeEditField.Enable = "on";
            end
        end
        function DisableDeepSPT(app)
            app.PropertyDropDown.Enable = "off";
            app.PropertiesListBox.Enable = "off";
            app.SupermapButton.Enable = "off";
            app.DBSCANButton.Enable = "off";
            app.MinClusterSizeEditField.Enable = "off";
        end

        function EnableDeepSPT(app)
            app.PropertyDropDown.Enable = "on";
            app.PropertiesListBox.Enable = "on";
            app.SupermapButton.Enable = "on";
            app.DBSCANButton.Enable = "on";
            app.MinClusterSizeEditField.Enable = "on";
        end

        function locallyFilterTracks(app, minTrackLength)
            app.LengthFilteredTrackData = app.OriginalTrackData;
            
            for i = 1:size(app.OriginalTrackData,1)
                app.LengthFilteredTrackData{i,1} = filterTracksinCellbyLength(app.OriginalTrackData{i,1}, minTrackLength);
            end
            %j = 1;
            arrSizes = cellfun(@size, app.LengthFilteredTrackData(:,1), 'UniformOutput', false);
            arrSizes = cell2mat(arrSizes);
            cellmask = arrSizes(:,1) == 0;
            app.LengthFilteredTrackData(cellmask,:) = [];
        end
        
        function updateDataAfterMinTrackFilterChange(app)
            %need to readjust some GUI fields if the min track length
            %filter was changed and now more/less IDs are available
            %load the first data into the figure
            locallyFilterTracks(app, app.MinTrackLengthEditField.Value);
            app.LengthFilteredTrackData = addSegmentToTracks(app.LengthFilteredTrackData);
             %set the file names in GUI
            updateTrackFileSelection(app);
            setCurrentTrackData(app);
            updateFrameSliderLimits(app);
            %get the current track ID
            IDs = app.TrackIDList;
            currID = app.TrackIDDropDown.Value;
            if length(IDs) == 1
                idx = 1;
                newID = IDs(idx);
            else
            idx = find(IDs == currID)+1;
            newID = IDs(idx);
            end
            app.TrackIDDropDown.Value = newID;
            value = str2double(newID);
            frame = min(app.CurrentTrackFileData(app.CurrentTrackFileData(:,1)==value,2));
            app.TimepointEditField.Value = frame;
            try
            app.TimeSlider.Value = frame;
            catch
            end
            adjustDataAndScale(app);
        end
   
        function plotImageAndTraces(app)
            %main function to control the appearance of the figure in the
            %center.
            %First the background image. Depending on the GUI, image will
            %be plotted or not. And if plotted, decide between avg
            %intensity or a single frame
            %Get the current settings for the background image
            cla(app.UIAxes);
            scalexy = 1/app.ScaleXYEditField.Value;
            scalez = app.ScaleZEditField.Value;
            isImageActive = app.Switch_5.Value;
            isImageAverage = app.AverageIntensityCheckBox.Value;
            isMaxIntensity = app.MaxIntensityCheckBox.Value;
            %now the tracks
            %grab the options to determine the track appearance
            if app.Switch.Value == "3D" is3D = 1; else is3D = 0; end
            if app.ShowTracksSwitch.Value == "On" isTracks = 1; else isTracks = 0; end
            if app.Switch_6.Value == "All Tracks" isAlltracks = 1; else isAlltracks = 0; end
            %determine the track data based if filter is present or not
            if app.UseFilterfromAllTrackAnalysisCheckBox.Value
                currentTracks = app.FilteredTrackData;
            else
                currentTracks = app.CurrentTrackFileData;
            end
            %process the information
            try
                app.Trackhandles = manageTrackOverlayVisualisation(app.UIAxes, currentTracks, is3D, isTracks, isAlltracks, scalexy, scalez, app.TimepointEditField.Value, app.ImportSettingsStruct.customUnits.LengthUnit, app.SpotsizeEditField.Value);
            catch
            end
            if isImageActive == "On"
                %Decide on the image
                if isImageAverage
                    imageData = app.AveragedImageData;
                elseif isMaxIntensity
                    imageData = app.MaxIntenImageData;
                else
                    if size(app.ImageData,1) > 1
                        imageData = app.ImageData{app.FrameEditField.Value}(:,:,app.ZSliceEditField.Value);
                    else
                        imageData = app.ImageData{1}(:,:,app.FrameEditField.Value);
                    end
                end
                %if we want an image, plot it
                plotBackgroundImageVisualisation(app.getAxes(), imageData, app.OffsetXEditField.Value, app.OffsetYEditField.Value, app.OffsetZEditField.Value, app.Scalexy);
                hold(app.UIAxes, "on");
            end
            
            hold(app.UIAxes, "off");
        end
        
        function KaryogramDiffusionTypeSelection(app)
            %checks if diffusion types are available and therefore makes
            %the filter available. if not, only "all" can be selected. Type
            %is stored in column 14 of the data.
            dim = size(app.OriginalTrackData{1,1});
            if dim(2) < 14 %no track type
                app.TrackTypeDropDown.Value = "All";
                app.TrackTypeDropDown.Enable = "off";
            else %all data present
                difftypes = app.ImportSettingsStruct.diffstates;
                difftypes = split(difftypes, ", ");
                difftypes = difftypes.';
                difftypes = ["All", difftypes];
                app.TrackTypeDropDown.Items = difftypes;
                app.TrackTypeDropDown.Value = "All";
                app.TrackTypeDropDown.Enable = "on";
            end
        end
        
        function enableImageSettings(app)
            %after image has been loaded, enable settings
            app.Switch_5.Enable = "on";
            app.RotateButton.Enable = "on";
            app.FlipButton.Enable = "on";
            app.AverageIntensityCheckBox.Enable = "on";
            app.MaxIntensityCheckBox.Enable = "on";
            app.MaxIntensityCheckBox.Enable = "on";
            app.FrameEditField.Enable = "on";
            app.FrameLabel.Enable = "on";
            %app.FrameEditFieldLabel.Enable = "on";
            app.OffsetXEditField.Enable = "on";
            app.OffsetXEditFieldLabel.Enable = "on";
            app.OffsetXEditFieldLabel_2.Enable = "on";
            app.OffsetYEditField.Enable = "on";
            %app.OffsetYEditFieldLabel.Enable = "on";
            app.OffsetZEditField.Enable = "on";
            app.OffsetZEditFieldLabel.Enable = "on";
        end
        
        function disableImageSettings(app)
            %disable if no image has been loaded
            app.Switch_5.Enable = "off";
            app.RotateButton.Enable = "off";
            app.FlipButton.Enable = "off";
            app.AverageIntensityCheckBox.Enable = "off";
            app.MaxIntensityCheckBox.Enable = "off";
            app.FrameEditField.Enable = "off";
            app.FrameLabel.Enable = "off";
            app.OffsetXEditField.Enable = "off";
            app.OffsetXEditFieldLabel.Enable = "off";
            app.OffsetYEditField.Enable = "off";
            app.OffsetXEditFieldLabel_2.Enable = "off";
            app.OffsetZEditField.Enable = "off";
            app.OffsetZEditFieldLabel.Enable = "off";
        end
    
        function controlDataAnalysisCheckboxes(app)
            %Makes checkboxes available if data is present. If not, make
            %them unavailable
            if app.TrackListDropDown.Value == "Nothing"
                app.UseSingleTracksforAnalysisCheckBox.Enable = "off";
                app.UseSingleTracksforAnalysisCheckBox.Value = 0;
            else
                app.UseSingleTracksforAnalysisCheckBox.Enable = "on";
            end
            if app.ROIListDropDown.Value == "Nothing"
                app.UseROIforAnalysisCheckBox.Enable = "off";
                app.UseROIforAnalysisCheckBox.Value = 0;
            else
                app.UseROIforAnalysisCheckBox.Enable = "on";
            end
        end
        
        function controlAnalysisDataSelection(app)
            %Need to make sure that only one type of data can be send to
            %the analysis.
            controlDataAnalysisCheckboxes(app);
            if app.UseSingleTracksforAnalysisCheckBox.Value == 1
                app.UseROIforAnalysisCheckBox.Value = 0;
                app.UseROIforAnalysisCheckBox.Enable = "off";
            end
            if app.UseSingleTracksforAnalysisCheckBox.Value == 0
                app.UseROIforAnalysisCheckBox.Enable = "on";
            end
            if app.UseROIforAnalysisCheckBox.Value == 1
                app.UseSingleTracksforAnalysisCheckBox.Value = 0;
                app.UseSingleTracksforAnalysisCheckBox.Enable = "off";
            end
            if app.UseROIforAnalysisCheckBox.Value == 0
                app.UseSingleTracksforAnalysisCheckBox.Enable = "on";
            end
            controlDataAnalysisCheckboxes(app);
        end
        
        function disable3Dfor2DData(app)
            data = app.CurrentTrackFileData;
            %data is only 2d
            if mean(data(:,5)) == 0
                app.Switch.Enable = "off";
                app.Switch.Value = "2D";
                app.Switch_2.Enable = "off";
                app.Switch_2.Value = "2D";
                app.Is2DData = 1;
            else
                app.Switch.Enable = "on";
                app.Switch.Value = "2D";
                app.Switch_2.Enable = "on";
                app.Switch_2.Value = "2D";
                app.Is2DData = 0;
            end
        end
        
        function checkPyLink(app)
            %establish link to DeepSPT and make it available
            pathString = SettingPythonEnvironment();
            if length(pathString)>1
                app.PyLinkLamp.Color = [0.47,0.67,0.19];
                app.DeepSPTAvailable = 1;
                app.PerformDeepSPTAnalysisCheckBox.Enable = "on";
            else
                app.DeepSPTAvailable = 0;
                app.PerformDeepSPTAnalysisCheckBox.Enable = "off";
            end
        end
        
        function runDeepSPTAnalysis(app)
            filePath = app.OriginalDataPath;
            tracks = app.OriginalTrackData;
            unit = app.ImportSettingsStruct.customUnits.TimeUnit;
            timestep = getTimestep(app);
            data = tracks{1,1};
            %data is only 2d
            if mean(data(:,5)) == 0
                dimension = 2;
            else
                dimension = 3;
            end
            windowSize = app.TmpSegWindowEditField.Value;
            app.OriginalTrackData = calculateValuesUsingDeepSPT(tracks, filePath, timestep, dimension, windowSize);
        end

        function fillDropDownWithProperties(app)
            %Fills the central dropdown menue with the properties contained
            %in the track file. Can select one of you choosing.
            allpropertyNames = ["TrackID", "Timestep", "X-Pos", "Y-Pos", "Z-Pos", "Ext-Jumpdistance", "Ext-Segment-D", "Ext-Segment-DErr", "Ext-Segment-DR", "Ext-Segment-MeanJumpDistance", "Ext-Segment-MeanJumpDistanceErr", "Ext-Segment-MSD", "Ext-Segment-MSDErr", "Motiontype", "SegmentStart", "SegmentLifetime", "NumberOfSegmentsInTrack", "Intensity", "SegmentMeanJumpDist-N", "Timelag", "TrackId", "Tracksegments", "DSPT-Alpha", "DSPT-D", "extra", "pval" "DSPT-Efficiency", "DSPT-logEfficiency", "DSPT-FractalDim", "DSPT-Gaussiantiy", "DSPT-Kurtosis", "DSPT-MSDratio", "DSPT-Trappedness", "DSPT-T0", "DSPT-T1", "DSPT-T2", "DSPT-T3", "DSPT-Lifetime", "DSPT-Length", "DSPT-AvgStepLength", "DSPT-AvgMSD", "DSPT-AvgDP", "DSPT-corrDP", "DSPT-signDP", "DSPT-SumSteplength", "DSPT-MinSteplength", "DSPT-MaxSteplength", "DSPT-BroadnessSteplength", "DSPT-Speed", "DSPT-Covariance", "DSPT-FractionSlow", "DSPT-FractionFast", "DSPT-Volume", "DSPT-Percent-NormalDiff", "DSPT-Percent-DirectedMot", "DSPT-Percent-ConfinedDiff", "DSPT-Percent-SuperDiff", "DSPT-NumberChangepoints", "DSPT-Instant-MSD-D", "DSPT-meanSequence", "DSPT-medianSequence", "maxSequence", "DSPT-minSequence", "DSPT-stdSequence", "DSPT-SimSeq", "IntJumpDistX", "IntJumpDistY", "IntJumpDistZ", "IntJumpDistXY", "IntJumpDistXYZ", "IntCumJumpDistX","IntCumJumpDistY","IntCumJumpDIstZ","IntCumJumpDistXY","IntCumJumpDistXYZ", "IntMeanJumpDistX", "IntMeanJumpDistY","IntMeanJumpDistZ","IntMeanJumpDistXY","IntMeanJumpDistXYZ","IntCumMeanJumpDistX","IntCumMeanJumpDistY","IntCumMeanJumpDistZ","IntCumMeanJumpDistXY","IntCumMeanJumpDistXYZ","IntJumpAnglesXY","IntJumpAnglesXYZ","IntNumbrSteps","IntNetLenXY","IntNetLenXYZ","IntConfRatioXY","IntConfRatioXYZ","IntAbsLenXY","IntAbsLenXYZ","IntMSDXYAlpha","IntMSDXYA","IntMSDXYd","IntMSDXYLogR","IntMSDXYLinR","IntMSDXYZAlpha","IntMSDXYZA","IntMSDXYZd","IntMSDXYZLogR","IntMSDXYZLinR"];
            app.AllPropertyNames = allpropertyNames;
            allpropertyIDS = [1:1:size(allpropertyNames,2)];
            allpropertyIDS = allpropertyIDS(allpropertyIDS>0);
            %check if deepspt and or other data is available
            if ( (mean(app.CurrentTrackFileData(:,6:22),"all") == 0)  && (size(app.CurrentTrackFileData,2) < 24) )%no extra data yet
                if isempty(app.FlattedDataStruc)
                    %no extra data
                    allpropertyIDS = allpropertyIDS(allpropertyIDS<6);
                elseif size(app.FlattedDataStruc,2) < 46 & (mean(app.FlattedDataStruc(:,35:45),"all") == 0) %maybe now
                    allpropertyIDS = allpropertyIDS(allpropertyIDS<6 | (allpropertyIDS>65 & allpropertyIDS<95));
                elseif size(app.FlattedDataStruc,2) < 46
                    allpropertyIDS = allpropertyIDS(allpropertyIDS<6 | allpropertyIDS>65);
                end
                %allpropertyNames = allpropertyNames(allpropertyIDS>0);
            elseif size(app.CurrentTrackFileData, 2) < 24 %swift/external data
                if isempty(app.FlattedDataStruc)
                    %no extra data
                    allpropertyIDS = allpropertyIDS(allpropertyIDS<23);
                elseif size(app.FlattedDataStruc,2) < 46 & (mean(app.FlattedDataStruc(:,35:45),"all") == 0)%maybe now
                    allpropertyIDS = allpropertyIDS(allpropertyIDS<23 | (allpropertyIDS>65 & allpropertyIDS<95));
                elseif size(app.FlattedDataStruc,2) < 46
                    allpropertyIDS = allpropertyIDS(allpropertyIDS<23 | allpropertyIDS>65);
                end
                %allpropertyNames = allpropertyNames(allpropertyIDS>0);
            elseif (size(app.CurrentTrackFileData, 2) < 67) &  (mean(app.CurrentTrackFileData(:,6:22),"all") == 0)%no external but Deepspt
                if isempty(app.FlattedDataStruc)
                    %no extra data
                    allpropertyIDS = allpropertyIDS(allpropertyIDS<6 |(allpropertyIDS>22 & allpropertyIDS<66));
                elseif size(app.FlattedDataStruc,2) < 46 & (mean(app.FlattedDataStruc(:,35:45),"all") == 0)%maybe now
                    allpropertyIDS = allpropertyIDS(allpropertyIDS<6 |  (allpropertyIDS>22 & allpropertyIDS<95));
                elseif size(app.FlattedDataStruc,2) < 46
                    allpropertyIDS = allpropertyIDS(allpropertyIDS<6 | allpropertyIDS>22);
                end
                %allpropertyNames = allpropertyNames(allpropertyIDS>0);
            elseif size(app.CurrentTrackFileData, 2) == 66 %external and DeepSPT
                if isempty(app.FlattedDataStruc)
                    %no extra data
                    allpropertyIDS = allpropertyIDS(allpropertyIDS<66);
                elseif size(app.FlattedDataStruc,2) < 46 & (mean(app.FlattedDataStruc(:,35:45),"all") == 0)%maybe now
                    allpropertyIDS = allpropertyIDS(allpropertyIDS<95);
                elseif size(app.FlattedDataStruc,2) < 46
                    allpropertyIDS = allpropertyIDS;
                end
                %allpropertyNames = allpropertyNames(allpropertyIDS>0);
            end
            allpropertyNames = allpropertyNames(allpropertyIDS);
            %Send to app as constant variable
            app.PropertyNames = allpropertyNames;
            app.PropertyIDs = allpropertyIDS;

            %Set the dropdown menu
            propertyNames = cellstr(allpropertyNames);
            app.PropertyDropDown.Items = propertyNames;
            app.PropertiesListBox.Items = propertyNames;
            if ~isempty(allpropertyIDS)
                EnableDeepSPT(app);
            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            %connection to the calling window
            app.SelectionWindow = mainapp;
            movegui(app.VisualisationUIFigure, "center");
            %generate save structures
            app.ROIList = {};
            app.StoreAxes = axes();
            app.SavedSingleTracks = {};
            set(app.StoreAxes.Parent, "Visible", "off");
            set(gcf, "visible", "off");
            set(app.StoreAxes, "visible", "off");
            %enable/disable panels depending on the files selected
            checkPyLink(app);
            app.Scalexy = app.ScaleXYEditField.Value;
            app.LoadTracksButton.Enable = "off";
            app.PerformDataDriftCorrectionButton.Enable = "off";
            app.TrackFileDropDown.Enable = "off";
            app.FlattedDataStruc = [];
            disableNearlyEverything(app);
        end

        % Button pushed function: ImportSettingsButton
        function ImportSettingsButtonPushed(app, event)
            app.ImportSettingsButton.Enable = "off";
            if isa(app.ImportSettingsVisualisationWindow, "ImportSettingsVisualisationWindow")
                app.ImportSettingsVisualisationWindow.ImportSettingsUIFigure.Visible = "on";
            else
                app.ImportSettingsVisualisationWindow = ImportSettingsVisualisationWindow(app);
            end
        end

        % Button pushed function: TracksButton
        function TracksButtonPushed(app, event)
            app.AllTrackAnalysisWindow = AllTrackAnalysisWindow(app);
        end

        % Button pushed function: SpotsButton
        function SpotsButtonPushed(app, event)
            app.SpotStatisticsWindow = SpotStatisticsWindow(app);
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            try
                ID = getIDfromDataTip(app);
                app.SelectedTrackID = ID;
                app.Label_3.Text = num2str(ID);
            catch
                warning("No track selected!");
            end
            app.SelectedTrackData = app.CurrentTrackFileData(app.CurrentTrackFileData(:,1) == ID,:);
            app.SingleTrackAnalysisWindow = SingleTrackAnalysisWindow(app, app.SelectedTrackData, ID);
        end

        % Close request function: VisualisationUIFigure
        function VisualisationUIFigureCloseRequest(app, event)
            app.SelectionWindow.ThirdPeakUIFigure.Visible = "on";
            delete(app)
        end

        % Button pushed function: LoadTracksButton
        function LoadTracksButtonPushed(app, event)
            %Loads the tracks depending on the settings struct and filters
            %them to a step length of two steps
            [app.OriginalTrackData, app.OriginalDataPath] = loadTrackDataFileSelectionVisualisation(app.ImportSettingsStruct);
            %Do the DeepSPT analysis
            if app.PerformDeepSPTAnalysisCheckBox.Value == 1
                d = uiprogressdlg(app.VisualisationUIFigure,'Title','Performing DeepSPT Analysis', 'Indeterminate','on');
                drawnow
                runDeepSPTAnalysis(app)
                delete(d);
            end
            d = uiprogressdlg(app.VisualisationUIFigure,'Title','Preparing Data', 'Indeterminate','on');
            %filter the tracks to the minimal step size given by the user
            locallyFilterTracks(app, app.MinTrackLengthEditField.Value);
            %add segment ids
            app.LengthFilteredTrackData = addSegmentToTracks(app.LengthFilteredTrackData);
            %set the file names in GUI
            updateTrackFileSelection(app);
            %set the available TrackIDs
            updateDataAfterMinTrackFilterChange(app);
            %activate the GUI
            if ~isempty(app.LengthFilteredTrackData)
                app.TrackFileDropDown.Enable = "on";
                app.PerformDataDriftCorrectionButton.Enable = "on";
                KaryogramDiffusionTypeSelection(app);
                enableNearlyEverything(app);
                fillDropDownWithProperties(app);
                controlDataAnalysisCheckboxes(app);
                disableImageSettings(app);
                disable3Dfor2DData(app);
            end
            
            %show the first tracks and if necessary also the microscopy
            %image
            plotImageAndTraces(app);
            value = app.AxisScaleSwitch.Value;
            switch value
                case "Auto"
                    app.ScaleXYEditField.Value = app.Scalexy;
                    axis(app.UIAxes,'normal')
                    cla(app.UIAxes);
                    plotImageAndTraces(app);
                    axis(app.UIAxes,'tight')
                case "Equal"
                    app.ScaleXYEditField.Value = 1;
                    axis(app.UIAxes,'equal')
                    cla(app.UIAxes);
                    plotImageAndTraces(app);
                    axis(app.UIAxes,'tight')
            end
            delete(d);
        end

        % Button pushed function: LoadReferenceImageButton
        function LoadReferenceImageButtonPushed(app, event)
            %Button to select the microscopy image data. Search in file
            %system. Will load tif-stacks
            % Display uigetfile dialog
            filterspec = {'*.tif'};
            [f, p] = uigetfile(filterspec,"MultiSelect","on");
            % Make sure user didn't cancel uigetfile dialog
            if ~ischar(p)
                error("No file selected")
            end
            if isa(f,"char")
                f = {f};
            end
            %Show a loading bar so user does not get impatient
            d = uiprogressdlg(app.VisualisationUIFigure,'Title','Loading Tiff', 'Indeterminate','on');
            drawnow
            %load the tif file
            tiffImages = cell(size(f,2),1);
            for i = 1:size(f,2)
                tiffImages{i,1} = loadtiff(fullfile(p,f{1,i}));
            end
            
            imagestack = tiffImages;
            %if we have a tif file
            if ~mean(cellfun(@isempty, imagestack))
                %generate the averageIntensityProjection
                avgIntenImage = cellfun(@(x) max(x,[],3), imagestack, "UniformOutput",false);
                maxIntImages = cellfun(@(x) max(x,[],3), imagestack, "UniformOutput",false);
                %save the image data
                app.ImageData = imagestack;
                app.AveragedImageData = avgIntenImage;
                app.MaxIntenImageData = maxIntImages;
                delete(d);
                enableImageSettings(app);
                if size(app.ImageData,1) > 1
                    app.ZSliceEditField.Enable = "on";
                    app.ZSliceEditField.Visible = "on";
                    app.ZSliceEditFieldLabel.Enable = "on";
                    app.ZSliceEditFieldLabel.Visible = "on";
                end
            else
                %no file
                delete(d);
                msgbox("No Microscopy data loaded!");
            end
            plotImageAndTraces(app);
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            %Rotates the image 90° counterclockwise
            app.ImageData = cellfun(@rot90, app.ImageData, "UniformOutput",false);
            app.AveragedImageData = cellfun(@rot90, app.AveragedImageData, "UniformOutput",false);
            plotImageAndTraces(app);
        end

        % Button pushed function: FlipButton
        function FlipButtonPushed(app, event)
            %Flips/Mirrors the image in the background
            app.ImageData = cellfun(@flip, app.ImageData, "UniformOutput",false);
            app.AveragedImageData = cellfun(@flip, app.AveragedImageData, "UniformOutput",false);
            plotImageAndTraces(app);
        end

        % Value changed function: AverageIntensityCheckBox
        function AverageIntensityCheckBoxValueChanged(app, event)
            %Decides if the average intensity projection will be used or
            %not
            value = app.AverageIntensityCheckBox.Value;
            if value
                app.MaxIntensityCheckBox.Value = 0;
            end
            plotImageAndTraces(app);
        end

        % Value changed function: Switch_5
        function Switch_5ValueChanged(app, event)
            %Decides wheter or not to show the microscopy image
            value = app.Switch_5.Value;
            app.AxisScaleSwitch.Value = "Equal";
            plotImageAndTraces(app);
        end

        % Value changed function: FrameEditField
        function FrameEditFieldValueChanged(app, event)
            %Determines the frame of the image stack to be used as a
            %background if no average intesity projection is used
            value = app.FrameEditField.Value;
            plotImageAndTraces(app);
        end

        % Value changed function: OffsetXEditField
        function OffsetXEditFieldValueChanged(app, event)
            %Determines the offset the image will be shifted in the X
            %dimension
            value = app.OffsetXEditField.Value;
            plotImageAndTraces(app);
        end

        % Value changed function: OffsetYEditField
        function OffsetYEditFieldValueChanged(app, event)
            %determines the offset the image will be shifted in the y
            %dimension
            value = app.OffsetYEditField.Value;
            plotImageAndTraces(app);
        end

        % Value changed function: OffsetZEditField
        function OffsetZEditFieldValueChanged(app, event)
            %determines the offset of the image in the Z dimension
            value = app.OffsetZEditField.Value;
            plotImageAndTraces(app);
        end

        % Value changed function: MinTrackLengthEditField
        function MinTrackLengthEditFieldValueChanged(app, event)
            %Changeing the filter value will re-filter the tracks in the
            %app and change the available IDS. Then replot the figure
            value = app.MinTrackLengthEditField.Value;
            updateDataAfterMinTrackFilterChange(app);
            plotImageAndTraces(app);
        end

        % Value changed function: TrackFileDropDown
        function TrackFileDropDownValueChanged(app, event)
            %Select a new file from the file selection drop down menue.
            %Will clear the figure for new data to be shown
            %get the new data form the file
            value = app.TrackFileDropDown.Value;
            setCurrentTrackData(app);
            allTrackFilterUsed(app);
            cla(app.UIAxes);
            %adjust the GUI in accordence to the new data
            %set to the first ID in the file
            IDs = app.TrackIDList;
            newID = IDs(2);
            app.TrackIDDropDown.Value = newID;
            %set the frame slider
            value = str2double(newID);
            frame = min(app.CurrentTrackFileData(app.CurrentTrackFileData(:,1)==value,2));
            app.TimepointEditField.Value = frame;
            app.TimeSlider.Value = frame;
            xticks(app.UIAxes,"auto");
            yticks(app.UIAxes, "auto");
            disable3Dfor2DData(app);
            app.FlattedDataStruc = [];
            %update the figure
            plotImageAndTraces(app);
        end

        % Callback function
        function TrackIDDropDownValueChanged(app, event)
            value = str2double(app.TrackIDDropDown.Value);
            %want to jump to the timepoint where the selected track starts
            frame = min(app.FilteredTrackData(app.FilteredTrackData(:,1)==value,2));
            app.TimepointEditField.Value = frame;
            app.TimeSlider.Value = frame;
            app.FrameEditField.Value = frame;
            cla(app.UIAxes);
            plotImageAndTraces(app);
        end

        % Button pushed function: PrevTracksButton
        function PrevTracksButtonPushed(app, event)
            %allows to jump to the previous track in the track list
            IDs = app.TrackIDList;
            %currID = str2double(app.TrackIDDropDown.Value);
            currID = app.TrackIDDropDown.Value;
            idx = find(IDs == currID)-1;
            newID = IDs(idx);
            app.TrackIDDropDown.Value = newID;
            value = str2double(newID);
            frame = min(app.CurrentTrackFileData(app.CurrentTrackFileData(:,1)==value,2));
            app.TimepointEditField.Value = frame;
            app.TimeSlider.Value = frame;
            %app.FrameEditField.Value = frame;
            plotImageAndTraces(app);
        end

        % Button pushed function: NextTracksButton
        function NextTracksButtonPushed(app, event)
            %allows to jump to the next track in the track list
            IDs = app.TrackIDList;
            currID = app.TrackIDDropDown.Value;
            idx = find(IDs == currID)+1;
            newID = IDs(idx);
            app.TrackIDDropDown.Value = newID;
            value = str2double(newID);
            frame = min(app.CurrentTrackFileData(app.CurrentTrackFileData(:,1)==value,2));
            app.TimepointEditField.Value = frame;
            app.TimeSlider.Value = frame;
            %app.FrameEditField.Value = frame;
            plotImageAndTraces(app);
        end

        % Key press function: VisualisationUIFigure
        function VisualisationUIFigureKeyPress(app, event)
            value = app.TimeSlider.Value; % get the slider value
            rotate3d(app.UIAxes,'off');
	        key = event.Key; % get the pressed key value
            step = app.StepsizeEditField.Value;
            if strcmp(key,'leftarrow')
        	    value = round(value-step,0); % left value
            elseif strcmp(key,'rightarrow')
                value = round(value+step,0); % right value
            end
            app.TimeSlider.Value = value; % set the slider value
            app.TimepointEditField.Value = value;
            plotImageAndTraces(app);
        end

        % Button pushed function: SelectTrackButton
        function SelectTrackButtonPushed(app, event)
            %Shut off the background image before selecting a track,
            %otherwise the image can also be selected.
            app.Switch_5.Value = "Off";
            plotImageAndTraces(app);
            datacursormode(app.VisualisationUIFigure,"toggle");
        end

        % Value changed function: ShowTracksSwitch
        function ShowTracksSwitchValueChanged(app, event)
            %determines if tracks/spots will be shown or not
            value = app.ShowTracksSwitch.Value;
            plotImageAndTraces(app)
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            %determines if visualization is done in 2d or 3d
            value = app.Switch.Value;
            cla(app.UIAxes);
            plotImageAndTraces(app);
            switch value
                case "2D"
                    view(app.UIAxes,2);
                    rotate3d(app.UIAxes,'off');
                case "3D"
                    view(app.UIAxes,45,45);
            end
        end

        % Value changed function: TimepointEditField
        function TimepointEditFieldValueChanged(app, event)
            value = app.TimepointEditField.Value;
            app.TimeSlider.Value = value;
            plotImageAndTraces(app);
        end

        % Button down function: CenterPanel
        function CenterPanelButtonDown(app, event)
            if datacursormode.Enable == "on"
                try
                    app.Label_3 = getIDfromDataTip(app);
                catch
                end
            end
        end

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)
            value = ceil(app.TimeSlider.Value);
            app.TimepointEditField.Value = value;
            plotImageAndTraces(app);
        end

        % Value changing function: TimeSlider
        function TimeSliderValueChanging(app, event)
            value = ceil(app.TimeSlider.Value);
            app.TimepointEditField.Value = value;
            plotImageAndTraces(app);
        end

        % Value changed function: Switch_6
        function Switch_6ValueChanged(app, event)
            %Determines if all tracks or only current tracks will be shown
            value = app.Switch_6.Value;
            cla(app.UIAxes);
            plotImageAndTraces(app);
        end

        % Value changed function: ScaleXYEditField
        function ScaleXYEditFieldValueChanged(app, event)
            %determines the scale of the traces. filled initaly with the
            %pixel size of the import settings dialogue.
            value = app.ScaleXYEditField.Value;
            app.Scalexy = app.ScaleXYEditField.Value;
            cla(app.UIAxes);
            plotImageAndTraces(app);
        end

        % Value changed function: ScaleZEditField
        function ScaleZEditFieldValueChanged(app, event)
            %determines the scaling of tracks in the z dimension
            value = app.ScaleZEditField.Value;
            cla(app.UIAxes);
            plotImageAndTraces(app);
        end

        % Value changed function: AxisScaleSwitch
        function AxisScaleSwitchValueChanged(app, event)
            %allows to switch between auto scaling(usually stretches z) and
            %equal scaling, so each dimension is in the same scale
            value = app.AxisScaleSwitch.Value;
            switch value
                case "Auto"
                    app.ScaleXYEditField.Value = app.Scalexy;
                    axis(app.UIAxes,'normal')
                    cla(app.UIAxes);
                    plotImageAndTraces(app);
                    axis(app.UIAxes,'tight')
                case "Equal"
                    app.ScaleXYEditField.Value = 1;
                    axis(app.UIAxes,'equal')
                    cla(app.UIAxes);
                    plotImageAndTraces(app);
                    axis(app.UIAxes,'tight')
            end
        end

        % Button pushed function: SelectROIButton
        function SelectROIButtonPushed(app, event)
            %Shut off the background image before selecting a track,
            %otherwise the image can also be selected.
            app.Switch_5.Value = "Off";
            plotImageAndTraces(app);
            %first make sure that no other ROI is in the axes
            try
                delete(app.CurrentROI);
            catch         
            end
            switch app.Switch.Value
                case "2D"
                    roi = drawrectangle(app.UIAxes);
                case "3D"
                    roi = drawcuboid(app.UIAxes);
            end
            app.CurrentROI = roi;
        end

        % Button pushed function: SaveROIButton
        function SaveROIButtonPushed(app, event)
            scale = app.ScaleXYEditField.Value;
            if app.Switch_8.Value == "Inclusive"
                inclusionMode = 1;
            else
                inclusionMode = 0;
            end
            
            if strcmp(app.CurrentROI.Type,"images.roi.cuboid") || strcmp(app.CurrentROI.Type,"images.roi.rectangle")
                name = string(app.ROINameEditField.Value);
                roiobj = app.CurrentROI;
                if ~isvalid(app.StoreAxes)
                    app.StoreAxes = axes();
                    set(app.StoreAxes.Parent, "Visible", "off");
                    set(gcf, "visible", "off");
                    set(app.StoreAxes, "visible", "off");
                end
                roiobj = copyobj(roiobj,app.StoreAxes);
                if app.Switch.Value == "3D"
                    dataidx = inROI(roiobj, app.CurrentTrackFileData(:,3)/scale,app.CurrentTrackFileData(:,4)/scale,app.CurrentTrackFileData(:,5)/app.ScaleZEditField.Value);
                else
                    dataidx = inROI(roiobj, app.CurrentTrackFileData(:,3)/scale,app.CurrentTrackFileData(:,4)/scale);
                end
                if size(app.ROIList,1) == 0
                else
                    allROInames = string([]);
                    for i = 1:size(app.ROIList,1)
                        allROInames(i) = string(app.ROIList{i,1});
                    end
                    if any(string(allROInames) == name)
                        counter = 1;
                        name = iterateFileName(allROInames, name, counter);
                    end
                end
                
                app.ROIList{end+1,1} = name; %name
                app.ROIList{end,2} = roiobj; %object

                %check for inlcude exclude
                if inclusionMode %inclusive
                    involvedIds = unique(app.CurrentTrackFileData(dataidx,1));
                    involvedIdsIdx = ismember(app.CurrentTrackFileData(:,1),involvedIds);
                    app.ROIList{end,3} = app.CurrentTrackFileData(involvedIdsIdx,:);%data
                else %exclusive
                    app.ROIList{end,3} = app.CurrentTrackFileData(dataidx,:);%data
                end
                
                app.ROIList{end,4} = app.TrackFileDropDown.Value; %filename
                
                if app.SaveAntiROICheckBox.Value
                    app.ROIList{end+1,1} = strcat("Anti-", name); %name
                    app.ROIList{end,2} = roiobj; %object
                    if inclusionMode
                        involvedIds = unique(app.CurrentTrackFileData(~dataidx,1));
                        involvedIdsIdx = ismember(app.CurrentTrackFileData(:,1),involvedIds);
                        app.ROIList{end,3} = app.CurrentTrackFileData(involvedIdsIdx,:);%data
                    else
                        app.ROIList{end,3} = app.CurrentTrackFileData(~dataidx,:);%data
                    end
                    app.ROIList{end,4} = app.TrackFileDropDown.Value; %filename
                end
            end
            updateROIList(app);
            app.ROIListDropDown.Value = app.ROIListDropDown.Items(end);
            controlDataAnalysisCheckboxes(app);
        end

        % Value changed function: ROIListDropDown
        function ROIListDropDownValueChanged(app, event)
            switchROI(app)           
        end

        % Button pushed function: DeleteROIButton
        function DeleteROIButtonPushed(app, event)
            value = app.ROIListDropDown.Value;
            idx = find(strcmp(app.ROIListDropDown.Items, value));
            try
                delete(app.StoreAxes.Children(idx));
            catch
            end
            app.ROIList(idx,:) = [];
            updateROIList(app);
            app.ROIListDropDown.Value = app.ROIListDropDown.Items(1);
        end

        % Button pushed function: SaveallROIsButton
        function SaveallROIsButtonPushed(app, event)
            if size(app.ROIList,1) > 0 
                % Display uigetfile dialog
                filterspec = {'*.mat'};
                [f, p] = uiputfile(filterspec);
                % Make sure user didn't cancel uigetfile dialog
                if ~ischar(p)
                    error("No file selected")
                end
    
                fulfile = fullfile(p,f);
                ROIs = app.ROIList;
                storeax = app.StoreAxes;
                save(fulfile, "ROIs", "storeax");
            end
            controlDataAnalysisCheckboxes(app);
        end

        % Button pushed function: LoadROIsButton
        function LoadROIsButtonPushed(app, event)
            filterspec = {'*.mat'};
            [f, p] = uigetfile(filterspec);
            % Make sure user didn't cancel uigetfile dialog
            if ~ischar(p)
            error("No file selected")
            end
            loadfile = load(fullfile(p,f));
            if size(loadfile.ROIs,2) == 4 && size(loadfile.storeax,1) > 0
                app.ROIList = loadfile.ROIs;
                app.StoreAxes = loadfile.storeax;
                updateROIList(app);
            end
            controlDataAnalysisCheckboxes(app);
        end

        % Button pushed function: VelocityButton
        function VelocityButtonPushed(app, event)
            switch app.Switch_2.Value
                case "2D"
                    flag3d = 0;
                case "3D"
                    flag3d = 1;
            end
            superpxsize = app.SuperpixelSizeXYEditField.Value;
            superZsize = app.SuperpixelSizeZEditField.Value;
            nmbrTimeWins = app.TimeWindowsEditField.Value;
            minAcqDT = app.ImportSettingsStruct.customUnits.Timestep; 
            nump = 1;
            filter = 0;
            filtersize = 3;
            unit = [app.ImportSettingsStruct.customUnits.LengthUnit "/" app.ImportSettingsStruct.customUnits.TimeUnit];
            unit = join(unit,"");
            
            if app.UseFilterfromAllTrackAnalysisCheckBox.Value
                data = app.FilteredTrackData;
            else
                data = app.CurrentTrackFileData;
            end
            makeVelocityMap(app, data, flag3d, superpxsize, nmbrTimeWins, minAcqDT, nump, filter, filtersize, superZsize, unit);

        end

        % Button pushed function: DriftButton
        function DriftButtonPushed(app, event)
            switch app.Switch_2.Value
                case "2D"
                    flag3d = 0;
                case "3D"
                    flag3d = 1;
            end
            superpxsize = app.SuperpixelSizeXYEditField.Value;
            superZsize = app.SuperpixelSizeZEditField.Value;
            nmbrTimeWins = app.TimeWindowsEditField.Value;
            minAcqDT = app.ImportSettingsStruct.customUnits.Timestep; 
            nump = 1;
            filter = 0;
            filtersize = 3;
            
            if app.UseFilterfromAllTrackAnalysisCheckBox.Value
                data = app.FilteredTrackData;
            else
                data = app.CurrentTrackFileData;
            end
            makeDriftMap(app, data, flag3d, superpxsize, nmbrTimeWins, minAcqDT, nump, filter, filtersize, superZsize);
        end

        % Button pushed function: DataDensityButton
        function DataDensityButtonPushed(app, event)
            switch app.Switch_2.Value
                case "2D"
                    flag3d = 0;
                case "3D"
                    flag3d = 1;
            end
            superpxsize = app.SuperpixelSizeXYEditField.Value;
            superZsize = app.SuperpixelSizeZEditField.Value;
            nmbrTimeWins = app.TimeWindowsEditField.Value;
            minAcqDT = app.ImportSettingsStruct.customUnits.Timestep; 
            switch app.Switch_7.Value
                case "DENS"
                    dopt = "DENS";
                case "NPTS"
                    dopt = "NPTS";
            end
            %dopt = "DENS"; % "LOGDENS" "NPTS"
            filter = 0;
            filtersize = 1;
            
             if app.UseFilterfromAllTrackAnalysisCheckBox.Value
                data = app.FilteredTrackData;
            else
                data = app.CurrentTrackFileData;
             end
            makeDensityMap(app, data, flag3d, superpxsize, nmbrTimeWins, minAcqDT, dopt, filter, filtersize, superZsize);
        end

        % Button pushed function: PlotKaryogramButton
        function PlotKaryogramButtonPushed(app, event)
            switch app.Switch.Value
                case "3D"
                    flag3d = 1;
                case "2D"
                    flag3d = 0;
            end
            Unit = app.ImportSettingsStruct.customUnits.LengthUnit;
            %track id filter
            if app.UseFilterfromAllTrackAnalysisCheckBox.Value
                if app.FilteredTrackFileName ~= "All Tracks"
                    app.TrackFileDropDown.Value = app.FilteredTrackFileName;
                    TrackFileDropDownValueChanged(app, event);
                    app.FilteredTrackData = app.CurrentTrackFileData(app.CurrentTrackFileData(:,1) == app.FilteredIDs,:);
                    Karyogramm3DwithType(app.FilteredTrackData, app.NrTrackstoshowEditField.Value, flag3d, app.TrackTypeDropDown.Value, app.LocPrecXYEditField.Value, app.ZEditField.Value, Unit, app.ImportSettingsStruct.diffstates);
                end
            else
                 Karyogramm3DwithType(app.CurrentTrackFileData, app.NrTrackstoshowEditField.Value, flag3d, app.TrackTypeDropDown.Value, app.LocPrecXYEditField.Value, app.ZEditField.Value, Unit, app.ImportSettingsStruct.diffstates);
            end
        end

        % Button pushed function: PerformDataDriftCorrectionButton
        function PerformDataDriftCorrectionButtonPushed(app, event)
            d = uiprogressdlg(app.VisualisationUIFigure,"Title", "Performing Drift Correction for all files.", "Indeterminate","on");
            drawnow
            app.LengthFilteredTrackData = performPreprocessingDriftCorrectionwoBead(app.LengthFilteredTrackData);
            close(d);
        end

        % Value changed function: TrackIDDropDown
        function TrackIDDropDownValueChanged2(app, event)
            %allows to directly jump to a selected ID
            value = app.TrackIDDropDown.Value;
            value = str2double(value);
            %want to jump to the timepoint where the selected track starts
            frame = min(app.CurrentTrackFileData(app.CurrentTrackFileData(:,1)==value,2));
            app.TimepointEditField.Value = frame;
            app.TimeSlider.Value = frame;
            app.FrameEditField.Value = frame;
            cla(app.UIAxes);
            plotImageAndTraces(app);
        end

        % Window button down function: VisualisationUIFigure
        function VisualisationUIFigureWindowButtonDown(app, event)
            datacursormode(app.VisualisationUIFigure,"off");
        end

        % Value changed function: SpotsizeEditField
        function SpotsizeEditFieldValueChanged(app, event)
            %Determines the size of the spots drawn
            value = app.SpotsizeEditField.Value;
            plotImageAndTraces(app);
        end

        % Value changed function: UseFilterfromAllTrackAnalysisCheckBox
        function UseFilterfromAllTrackAnalysisCheckBoxValueChanged(app, event)
            %Determines if only filtered tracks will be displayed or not
            value = app.UseFilterfromAllTrackAnalysisCheckBox.Value;
            if value
                if app.FilteredTrackFileName ~= "All Tracks"
                    app.TrackFileDropDown.Value = app.FilteredTrackFileName;
                    TrackFileDropDownValueChanged(app, event);
                    app.FilteredTrackData = [];
                    app.FilteredTrackData = app.CurrentTrackFileData(ismember(app.CurrentTrackFileData(:,1), app.FilteredIDs),:);
                else
                end
            else
                
            end
            plotImageAndTraces(app);
        end

        % Value changed function: NrTrackstoshowEditField
        function NrTrackstoshowEditFieldValueChanged(app, event)
            value = app.NrTrackstoshowEditField.Value;
            
        end

        % Button pushed function: SaveSingleTrackButton
        function SaveSingleTrackButtonPushed(app, event)
            %Allow to save the selected track in a separate variable to one
            %can select tracks from seperate files and analyze them later.
            %Select the track and grab its data
            try
                ID = getIDfromDataTip(app);
                app.SelectedTrackID = ID;
                app.Label_3.Text = num2str(ID);
                app.SelectedTrackData = app.CurrentTrackFileData(app.CurrentTrackFileData(:,1) == ID,:);
            catch
                warning("No track selected!");
            end
            %get the name
            trackname = app.TrackNameEditField.Value;
            %append the file name
            trackname = strcat(app.TrackFileDropDown.Value, "-", trackname);
            %get all names present
            savedTrackNames = string([]);
            try
                for i = 1:size(app.SavedSingleTracks,1)
                    tmp = app.SavedSingleTracks{i,1};
                    savedTrackNames(i) = tmp;
                end
            catch
            end
            %check that no names are double
            if any(string(savedTrackNames) == trackname)
                counter = 1;
                trackname = iterateFileName(savedTrackNames, trackname, counter);
            end
            %save to the structure
            app.SavedSingleTracks(end+1,:) = {trackname, app.SelectedTrackData, app.TrackFileDropDown.Value};
            updateSingleTrackList(app);
            controlDataAnalysisCheckboxes(app);
        end

        % Button pushed function: DeleteTrackButton
        function DeleteTrackButtonPushed(app, event)
            %deletes a given single track from the list
            value = app.TrackListDropDown.Value;
            %might have tracks with the same name, will delete both
            idx = find(strcmp(app.TrackListDropDown.Items, value));
            for i = 1:size(idx,2)
                app.SavedSingleTracks(idx(i),:) = [];
            end
            updateSingleTrackList(app);
            app.TrackListDropDown.Value = app.TrackListDropDown.Items(1);
            controlDataAnalysisCheckboxes(app);
        end

        % Button pushed function: SaveallTracksButton
        function SaveallTracksButtonPushed(app, event)
            if size(app.SavedSingleTracks,1) > 0 
                % Display uigetfile dialog
                filterspec = {'*.mat'};
                [f, p] = uiputfile(filterspec);
                % Make sure user didn't cancel uigetfile dialog
                if ~ischar(p)
                    error("No file selected")
                end
                fulfile = fullfile(p,f);
                tracks = app.SavedSingleTracks;
                save(fulfile, "tracks");
            end
            controlDataAnalysisCheckboxes(app);
        end

        % Button pushed function: LoadTracksButton_2
        function LoadTracksButton_2Pushed(app, event)
            filterspec = {'*.mat'};
            [f, p] = uigetfile(filterspec);
            % Make sure user didn't cancel uigetfile dialog
            if ~ischar(p)
            error("No file selected")
            end
            loadfile = load(fullfile(p,f));
            if size(loadfile,1)>0
                app.SavedSingleTracks = loadfile.tracks;
            end
            updateSingleTrackList(app);
            app.TrackListDropDown.Value = app.TrackListDropDown.Items(1);
            controlDataAnalysisCheckboxes(app);
        end

        % Value changed function: UseSingleTracksforAnalysisCheckBox
        function UseSingleTracksforAnalysisCheckBoxValueChanged(app, event)
            value = app.UseSingleTracksforAnalysisCheckBox.Value;
            controlAnalysisDataSelection(app);
        end

        % Value changed function: UseROIforAnalysisCheckBox
        function UseROIforAnalysisCheckBoxValueChanged(app, event)
            value = app.UseROIforAnalysisCheckBox.Value;
            controlAnalysisDataSelection(app);
        end

        % Value changed function: TrackListDropDown
        function TrackListDropDownValueChanged(app, event)
            value = app.TrackListDropDown.Value;
            %allows to go to the selected track if possible
            %get the properties of the track
            idx = find(strcmp(app.TrackListDropDown.Items, value));
            SingleTrackData = app.SavedSingleTracks(idx,:);
            %deconstruct the properties
            singletrackfilename = SingleTrackData{1,3};
            singletrackTrackData = SingleTrackData{1,2};
            singleTrackFirstFrame = singletrackTrackData(1,2);
            %change to single track view, go to file and frame
            try
                %get the file names
                files = app.TrackFileDropDown.Items;
                filenames = string(files);
                %check that we have a matching file name
                if any(filenames == singletrackfilename)
                    app.Switch_6.Value = "Current Tracks";
                    app.TrackFileDropDown.Value = singletrackfilename;
                    TrackFileDropDownValueChanged(app, event);
                    app.TimepointEditField.Value = singleTrackFirstFrame;
                    plotImageAndTraces(app);
                end
            catch ME
            end
        end

        % Button pushed function: DiffusionButton
        function DiffusionButtonPushed(app, event)
             switch app.Switch_2.Value
                case "2D"
                    flag3d = 0;
                case "3D"
                    flag3d = 1;
            end
            superpxsize = app.SuperpixelSizeXYEditField.Value;
            superZsize = app.SuperpixelSizeZEditField.Value;
            nmbrTimeWins = app.TimeWindowsEditField.Value;
            minAcqDT = app.ImportSettingsStruct.customUnits.Timestep; 
            expTime = app.ExpTimeEditField.Value;
            locPrecXY = app.LocPrecXYEditField.Value;
            locPrecZ = app.ZEditField.Value;
            nump = 1;
            filter = 0;
            filtersize = 3;
            unit = [app.ImportSettingsStruct.customUnits.LengthUnit "/" app.ImportSettingsStruct.customUnits.TimeUnit];
            unit = join(unit,"");
            
            if app.UseFilterfromAllTrackAnalysisCheckBox.Value
                data = app.FilteredTrackData;
            else
                data = app.CurrentTrackFileData;
            end
            makeDiffusionMap(app, data, flag3d, superpxsize, nmbrTimeWins, minAcqDT, nump, filter, filtersize, superZsize, unit, expTime, locPrecXY, locPrecZ);
            
        end

        % Button pushed function: SupermapButton
        function SupermapButtonPushed(app, event)
            %make the supermap from the DeepSPT generated data
            switch app.Switch_2.Value
                case "2D"
                    flag3d = 0;
                case "3D"
                    flag3d = 1;
            end
            superpxsize = app.SuperpixelSizeXYEditField.Value;
            superZsize = app.SuperpixelSizeZEditField.Value;
            nmbrTimeWins = app.TimeWindowsEditField.Value;
            unit = [app.ImportSettingsStruct.customUnits.LengthUnit "/" app.ImportSettingsStruct.customUnits.TimeUnit];
            unit = join(unit,"");
            allPropId = app.PropertyIDs;
            propertyID = allPropId(app.PropertyNames == app.PropertyDropDown.Value);

            if app.UseFilterfromAllTrackAnalysisCheckBox.Value
                data = app.FilteredTrackData;
                if propertyID > 65 %need the save struc data
                    filterID = unique(data(:,1));
                    idxs = ismember(app.FlattedDataStruc, filterID);
                    data(:,66:(66-6+size(app.FlattedDataStruc,2))) = app.FlattedDataStruc(idxs,6:end);
                end
            else
                data = app.CurrentTrackFileData;
                if propertyID > 65 %need the save struc data
                    data(:,66:(66-6+size(app.FlattedDataStruc,2))) = app.FlattedDataStruc(:,6:end);
                end
            end
            propertyNames = app.AllPropertyNames;
            
            makeScalarMapFromProperty(app, data, flag3d, superpxsize, nmbrTimeWins, app.ImportSettingsStruct.customUnits.TimeUnit, app.ImportSettingsStruct.customUnits.LengthUnit, superZsize, unit, propertyID, propertyNames, allPropId);
        end

        % Button pushed function: DBSCANButton
        function DBSCANButtonPushed(app, event)
            %perform the HDBSCAN on the selected properties
            switch app.Switch_2.Value
                case "2D"
                    flag3d = 0;
                case "3D"
                    flag3d = 1;
            end
            superpxsize = app.SuperpixelSizeXYEditField.Value;
            superZsize = app.SuperpixelSizeZEditField.Value;
            nmbrTimeWins = app.TimeWindowsEditField.Value;
            unit = [app.ImportSettingsStruct.customUnits.LengthUnit "/" app.ImportSettingsStruct.customUnits.TimeUnit];
            unit = join(unit,"");
            minCLusterSize = app.MinClusterSizeEditField.Value;
            %get the relevant properties
            selectedProperty = app.PropertiesListBox.Value;
            selectedProperty = string(selectedProperty);
            allPropId = app.PropertyIDs;
            propertyNames = app.PropertyNames;
            propertyIDs = zeros(length(selectedProperty),1);
            for i = 1:length(selectedProperty)
                propertyIDs(i) = allPropId(1, propertyNames == selectedProperty(i));
            end
           
            if app.UseFilterfromAllTrackAnalysisCheckBox.Value
                data = app.FilteredTrackData;
                if any(propertyIDs > 65) %need the save struc data
                    filterID = unique(data(:,1));
                    idxs = ismember(app.FlattedDataStruc, filterID);
                    data(:,66:(66-6+size(app.FlattedDataStruc,2))) = app.FlattedDataStruc(idxs,6:end);
                end
            else
                data = app.CurrentTrackFileData;
                if any(propertyIDs > 65)
                    data(:,66:(66-6+size(app.FlattedDataStruc,2))) = app.FlattedDataStruc(:,6:end);
                end
            end
            
            performDBScanAndPlot(app, data, flag3d, superpxsize, nmbrTimeWins, app.ImportSettingsStruct.customUnits.TimeUnit, app.ImportSettingsStruct.customUnits.LengthUnit, superZsize, unit, propertyIDs, propertyNames, minCLusterSize, allPropId);
        end

        % Value changed function: MaxIntensityCheckBox
        function MaxIntensityCheckBoxValueChanged(app, event)
            value = app.MaxIntensityCheckBox.Value;
            if value
                app.AverageIntensityCheckBox.Value = 0;
            end
            plotImageAndTraces(app);
        end

        % Value changed function: ZSliceEditField
        function ZSliceEditFieldValueChanged(app, event)
            value = app.ZSliceEditField.Value;
            plotImageAndTraces(app);
        end

        % Button pushed function: PlotlySavelocationButton
        function PlotlySavelocationButtonPushed(app, event)
            app.PlotlySaveLocation = uigetdir;
            if ~isempty(app.PlotlySaveLocation)
                app.FilenameEditField.Enable = "on";
                app.SaveViewasPlotlyButton.Enable = "on";
            end
        end

        % Button pushed function: SaveViewasPlotlyButton
        function SaveViewasPlotlyButtonPushed(app, event)
            if ~isempty(app.PlotlySaveLocation)
                d = uiprogressdlg(app.VisualisationUIFigure,'Title','Saving figure', 'Indeterminate','on');
                filename = app.FilenameEditField.Value;
                savepath = app.PlotlySaveLocation;
                ending = "html";
                fullPath = fullfile(savepath,filename,ending);
                a = figure();
                copyobj(app.UIAxes, a);
                cd(savepath);
                fig2plotly(a, "offline", true, "filename", filename);
                delete(d);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.VisualisationUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {972, 972, 972};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {972, 972};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {234, '1x', 232};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VisualisationUIFigure = uifigure('Visible', 'off');
            app.VisualisationUIFigure.Name = 'Visualisation';
            app.VisualisationUIFigure.Icon = '3rdPeak1.png';
            app.VisualisationUIFigure.CloseRequestFcn = createCallbackFcn(app, @VisualisationUIFigureCloseRequest, true);
            app.VisualisationUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.VisualisationUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @VisualisationUIFigureWindowButtonDown, true);
            app.VisualisationUIFigure.KeyPressFcn = createCallbackFcn(app, @VisualisationUIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.VisualisationUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout7 = uigridlayout(app.LeftPanel);
            app.ImportTracksPanel = uipanel(app.GridLayout7);
            app.ImportTracksPanel.Title = 'Import Tracks';
            app.GridLayout3 = uigridlayout(app.ImportTracksPanel);
            app.ImportSettingsButton = uibutton(app.GridLayout3, 'push');
            app.ImportSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportSettingsButtonPushed, true);
            app.ImportSettingsButton.Tooltip = {'Opens a window to determine the data format of the track data that will be imported.'};
            app.ImportSettingsButton.Text = 'Import Settings';
            app.LoadTracksButton = uibutton(app.GridLayout3, 'push');
            app.LoadTracksButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTracksButtonPushed, true);
            app.LoadTracksButton.Tooltip = {'Allows the selection of mutliple track files defined by the import settings.'};
            app.LoadTracksButton.Text = 'Load Tracks';
            app.PerformDataDriftCorrectionButton = uibutton(app.GridLayout3, 'push');
            app.PerformDataDriftCorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @PerformDataDriftCorrectionButtonPushed, true);
            app.PerformDataDriftCorrectionButton.Tooltip = {'Will automatically try to correct for drift in the loaded data.'};
            app.PerformDataDriftCorrectionButton.Text = 'Perform Data Drift Correction';
            app.TrackFileDropDownLabel = uilabel(app.GridLayout3);
            app.TrackFileDropDownLabel.Text = 'Track File';
            app.TrackFileDropDown = uidropdown(app.GridLayout3);
            app.TrackFileDropDown.Items = {'Nothing'};
            app.TrackFileDropDown.ValueChangedFcn = createCallbackFcn(app, @TrackFileDropDownValueChanged, true);
            app.TrackFileDropDown.Tooltip = {'Allows to switch to a different loaded file.'};
            app.TrackFileDropDown.Value = 'Nothing';
            app.MinTrackLengthEditFieldLabel = uilabel(app.GridLayout3);
            app.MinTrackLengthEditFieldLabel.Text = 'Min. Track Length';
            app.MinTrackLengthEditField = uieditfield(app.GridLayout3, 'numeric');
            app.MinTrackLengthEditField.Limits = [5 Inf];
            app.MinTrackLengthEditField.ValueChangedFcn = createCallbackFcn(app, @MinTrackLengthEditFieldValueChanged, true);
            app.MinTrackLengthEditField.Tooltip = {'Filters the tracks by a minimal track length given.'};
            app.MinTrackLengthEditField.Value = 5;
            app.PyLinkLampLabel = uilabel(app.GridLayout3);
            app.PyLinkLampLabel.Text = 'PyLink';
            app.PyLinkLamp = uilamp(app.GridLayout3);
            app.PerformDeepSPTAnalysisCheckBox = uicheckbox(app.GridLayout3);
            app.PerformDeepSPTAnalysisCheckBox.Text = 'Perform DeepSPT Analysis';
            app.TmpSegWindowEditFieldLabel = uilabel(app.GridLayout3);
            app.TmpSegWindowEditFieldLabel.Text = 'Tmp. Seg. Window';
            app.TmpSegWindowEditField = uieditfield(app.GridLayout3, 'numeric');
            app.TmpSegWindowEditField.Value = 5;
            app.DimensionsandPlotPanel = uipanel(app.GridLayout7);
            app.DimensionsandPlotPanel.Title = 'Dimensions and Plot';
            app.GridLayout5 = uigridlayout(app.DimensionsandPlotPanel);
            app.Switch = uiswitch(app.GridLayout5, 'slider');
            app.Switch.Items = {'2D', '3D'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Tooltip = {'Plots the tracks in 2D or 3D.'};
            app.Switch.Value = '2D';
            app.Switch_6 = uiswitch(app.GridLayout5, 'slider');
            app.Switch_6.Items = {'Current Tracks', 'All Tracks'};
            app.Switch_6.ValueChangedFcn = createCallbackFcn(app, @Switch_6ValueChanged, true);
            app.Switch_6.Tooltip = {'Allows to switch between plotting single tracks in dependence of the timepoint or all tracks at once, independet of the timepoint.'};
            app.Switch_6.Value = 'All Tracks';
            app.PrevTracksButton = uibutton(app.GridLayout5, 'push');
            app.PrevTracksButton.ButtonPushedFcn = createCallbackFcn(app, @PrevTracksButtonPushed, true);
            app.PrevTracksButton.Tooltip = {'Jump to the previous track id.'};
            app.PrevTracksButton.Text = 'Prev. Tracks';
            app.NextTracksButton = uibutton(app.GridLayout5, 'push');
            app.NextTracksButton.ButtonPushedFcn = createCallbackFcn(app, @NextTracksButtonPushed, true);
            app.NextTracksButton.Tooltip = {'Jump to the next track id.'};
            app.NextTracksButton.Text = 'Next Tracks';
            app.UseFilterfromAllTrackAnalysisCheckBox = uicheckbox(app.GridLayout5);
            app.UseFilterfromAllTrackAnalysisCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseFilterfromAllTrackAnalysisCheckBoxValueChanged, true);
            app.UseFilterfromAllTrackAnalysisCheckBox.Enable = 'off';
            app.UseFilterfromAllTrackAnalysisCheckBox.Tooltip = {'Allows to filter the visualized tracks by the filter settings set in the Track Analysis Window - Data Filter. '};
            app.UseFilterfromAllTrackAnalysisCheckBox.Text = 'Use Filter from All Track Analysis';
            app.FilterSetStatusNoFilterLabel = uilabel(app.GridLayout5);
            app.FilterSetStatusNoFilterLabel.Text = 'Filter Set Status: No Filter';
            app.ShowTracksSwitchLabel = uilabel(app.GridLayout5);
            app.ShowTracksSwitchLabel.Text = 'Show Tracks';
            app.ShowTracksSwitch = uiswitch(app.GridLayout5, 'slider');
            app.ShowTracksSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowTracksSwitchValueChanged, true);
            app.ShowTracksSwitch.Tooltip = {'Enalbes/Disables the tracks.'};
            app.ShowTracksSwitch.Value = 'On';
            app.ScaleXYEditFieldLabel = uilabel(app.GridLayout5);
            app.ScaleXYEditFieldLabel.Text = 'Scale XY';
            app.ScaleXYEditField = uieditfield(app.GridLayout5, 'numeric');
            app.ScaleXYEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleXYEditFieldValueChanged, true);
            app.ScaleXYEditField.Tooltip = {'Scales the tracks according to the Value. Should match the pixel size of the background image.'};
            app.ScaleXYEditField.Value = 1;
            app.ScaleZEditFieldLabel = uilabel(app.GridLayout5);
            app.ScaleZEditFieldLabel.Text = 'Scale Z';
            app.ScaleZEditField = uieditfield(app.GridLayout5, 'numeric');
            app.ScaleZEditField.Limits = [0.0001 Inf];
            app.ScaleZEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleZEditFieldValueChanged, true);
            app.ScaleZEditField.Tooltip = {'Scales the Tracks in Z. '};
            app.ScaleZEditField.Value = 1;
            app.AxisScaleSwitchLabel = uilabel(app.GridLayout5);
            app.AxisScaleSwitchLabel.Text = 'Axis Scale';
            app.AxisScaleSwitch = uiswitch(app.GridLayout5, 'slider');
            app.AxisScaleSwitch.Items = {'Auto', 'Equal'};
            app.AxisScaleSwitch.ValueChangedFcn = createCallbackFcn(app, @AxisScaleSwitchValueChanged, true);
            app.AxisScaleSwitch.Tooltip = {'Allows to switch between equal sacaling of all axes or automatic (stretched) scaling.'};
            app.AxisScaleSwitch.Value = 'Equal';
            app.TrackIDDropDownLabel = uilabel(app.GridLayout5);
            app.TrackIDDropDownLabel.Text = 'Track ID';
            app.TrackIDDropDown = uidropdown(app.GridLayout5);
            app.TrackIDDropDown.Items = {'Nothing'};
            app.TrackIDDropDown.ValueChangedFcn = createCallbackFcn(app, @TrackIDDropDownValueChanged2, true);
            app.TrackIDDropDown.Tooltip = {'Allows to switch to a track with the selected ID.Switches to the timepoint the selected treack starts.'};
            app.TrackIDDropDown.Value = 'Nothing';
            app.SpotsizeEditFieldLabel = uilabel(app.GridLayout5);
            app.SpotsizeEditFieldLabel.Text = 'Spot size';
            app.SpotsizeEditField = uieditfield(app.GridLayout5, 'numeric');
            app.SpotsizeEditField.ValueChangedFcn = createCallbackFcn(app, @SpotsizeEditFieldValueChanged, true);
            app.SpotsizeEditField.Tooltip = {'Defines the size of the plotted spot for visualization.'};
            app.SpotsizeEditField.Value = 25;
            app.KaryogramPanel = uipanel(app.GridLayout7);
            app.KaryogramPanel.Title = 'Karyogram';
            app.GridLayout6 = uigridlayout(app.KaryogramPanel);
            app.PlotKaryogramButton = uibutton(app.GridLayout6, 'push');
            app.PlotKaryogramButton.ButtonPushedFcn = createCallbackFcn(app, @PlotKaryogramButtonPushed, true);
            app.PlotKaryogramButton.Tooltip = {'Will plot an overview of all tracks available in an extra window, arranged in an square.'};
            app.PlotKaryogramButton.Text = 'Plot Karyogram';
            app.NrTrackstoshowEditFieldLabel = uilabel(app.GridLayout6);
            app.NrTrackstoshowEditFieldLabel.Text = 'Nr. Tracks to show';
            app.NrTrackstoshowEditField = uieditfield(app.GridLayout6, 'numeric');
            app.NrTrackstoshowEditField.Limits = [0 Inf];
            app.NrTrackstoshowEditField.ValueChangedFcn = createCallbackFcn(app, @NrTrackstoshowEditFieldValueChanged, true);
            app.NrTrackstoshowEditField.Tooltip = {'Defines how many tracks will be plotted. Zero means all tracks.'};
            app.LocPrecXYEditFieldLabel = uilabel(app.GridLayout6);
            app.LocPrecXYEditFieldLabel.Text = 'LocPrec XY';
            app.LocPrecXYEditField = uieditfield(app.GridLayout6, 'numeric');
            app.LocPrecXYEditField.Limits = [0 Inf];
            app.LocPrecXYEditField.Tooltip = {'You can defines the localisation precision here to give a reference for the tracks in the karyogram.'};
            app.LocPrecXYEditField.Value = 40;
            app.ZEditFieldLabel = uilabel(app.GridLayout6);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.GridLayout6, 'numeric');
            app.ZEditField.Limits = [0 Inf];
            app.ZEditField.Tooltip = {'You can defines the localisation precision here to give a reference for the tracks in the karyogram.'};
            app.ZEditField.Value = 80;
            app.TrackTypeDropDownLabel = uilabel(app.GridLayout6);
            app.TrackTypeDropDownLabel.Text = 'Track Type';
            app.TrackTypeDropDown = uidropdown(app.GridLayout6);
            app.TrackTypeDropDown.Items = {'All', 'Diffusion', 'Immobile', 'Directed', 'Directed_Diffusion', 'None'};
            app.TrackTypeDropDown.Tooltip = {'Filter the tracks by the tracktype.'};
            app.TrackTypeDropDown.Value = 'All';
            app.ImageSettingsPanel = uipanel(app.GridLayout7);
            app.ImageSettingsPanel.Title = 'Image Settings';
            app.GridLayout4 = uigridlayout(app.ImageSettingsPanel);
            app.Switch_5 = uiswitch(app.GridLayout4, 'slider');
            app.Switch_5.ValueChangedFcn = createCallbackFcn(app, @Switch_5ValueChanged, true);
            app.Switch_5.Tooltip = {'Enables or disables the background image.'};
            app.LoadReferenceImageButton = uibutton(app.GridLayout4, 'push');
            app.LoadReferenceImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadReferenceImageButtonPushed, true);
            app.LoadReferenceImageButton.Tooltip = {'Can load a single reference image to be used as a background.'};
            app.LoadReferenceImageButton.Text = 'Load Reference Image';
            app.RotateButton = uibutton(app.GridLayout4, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Tooltip = {'Rotates the background image.'};
            app.RotateButton.Text = 'Rotate';
            app.FlipButton = uibutton(app.GridLayout4, 'push');
            app.FlipButton.ButtonPushedFcn = createCallbackFcn(app, @FlipButtonPushed, true);
            app.FlipButton.Tooltip = {'Flips the background image.'};
            app.FlipButton.Text = 'Flip';
            app.AverageIntensityCheckBox = uicheckbox(app.GridLayout4);
            app.AverageIntensityCheckBox.ValueChangedFcn = createCallbackFcn(app, @AverageIntensityCheckBoxValueChanged, true);
            app.AverageIntensityCheckBox.Tooltip = {'Geneartes an average intensity projection of the background image stack.'};
            app.AverageIntensityCheckBox.Text = 'Average Intensity';
            app.MaxIntensityCheckBox = uicheckbox(app.GridLayout4);
            app.MaxIntensityCheckBox.ValueChangedFcn = createCallbackFcn(app, @MaxIntensityCheckBoxValueChanged, true);
            app.MaxIntensityCheckBox.Text = 'Max Intensity';
            app.FrameLabel = uilabel(app.GridLayout4);
            app.FrameLabel.Text = 'Frame';
            app.ZSliceEditFieldLabel = uilabel(app.GridLayout4);
            app.ZSliceEditFieldLabel.Enable = 'off';
            app.ZSliceEditFieldLabel.Visible = 'off';
            app.ZSliceEditFieldLabel.Text = 'Z Slice';
            app.ZSliceEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ZSliceEditField.Limits = [1 Inf];
            app.ZSliceEditField.ValueChangedFcn = createCallbackFcn(app, @ZSliceEditFieldValueChanged, true);
            app.ZSliceEditField.Enable = 'off';
            app.ZSliceEditField.Visible = 'off';
            app.ZSliceEditField.Value = 1;
            app.OffsetXEditFieldLabel = uilabel(app.GridLayout4);
            app.OffsetXEditFieldLabel.Text = 'Offset X';
            app.OffsetXEditField = uieditfield(app.GridLayout4, 'numeric');
            app.OffsetXEditField.ValueChangedFcn = createCallbackFcn(app, @OffsetXEditFieldValueChanged, true);
            app.OffsetXEditField.Tooltip = {'Offset for the image in X.'};
            app.OffsetZEditFieldLabel = uilabel(app.GridLayout4);
            app.OffsetZEditFieldLabel.Text = 'Offset Z';
            app.OffsetZEditField = uieditfield(app.GridLayout4, 'numeric');
            app.OffsetZEditField.ValueChangedFcn = createCallbackFcn(app, @OffsetZEditFieldValueChanged, true);
            app.OffsetZEditField.Tooltip = {'Offset for the image in Z.'};
            app.OffsetZEditField.Value = -1;
            app.OffsetYEditField = uieditfield(app.GridLayout4, 'numeric');
            app.OffsetYEditField.ValueChangedFcn = createCallbackFcn(app, @OffsetYEditFieldValueChanged, true);
            app.OffsetYEditField.Tooltip = {'Offset for the image in Y.'};
            app.OffsetXEditFieldLabel_2 = uilabel(app.GridLayout4);
            app.OffsetXEditFieldLabel_2.Text = 'Offset Y';
            app.FrameEditField = uieditfield(app.GridLayout4, 'numeric');
            app.FrameEditField.Limits = [1 Inf];
            app.FrameEditField.ValueChangedFcn = createCallbackFcn(app, @FrameEditFieldValueChanged, true);
            app.FrameEditField.Tooltip = {'Select a frame of the backgorund image stack.'};
            app.FrameEditField.Value = 1;
            app.CenterPanel = uipanel(app.GridLayout);
            app.CenterPanel.ButtonDownFcn = createCallbackFcn(app, @CenterPanelButtonDown, true);
            app.GridLayout2 = uigridlayout(app.CenterPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StepsizeEditFieldLabel = uilabel(app.GridLayout2);
            app.StepsizeEditFieldLabel.Text = 'Step size';
            app.StepsizeEditField = uieditfield(app.GridLayout2, 'numeric');
            app.StepsizeEditField.Tooltip = {'Defines the step size taken when using the left and right arrow keys to navigate.'};
            app.StepsizeEditField.Value = 1;
            app.TimepointEditFieldLabel = uilabel(app.GridLayout2);
            app.TimepointEditFieldLabel.Text = 'Timepoint';
            app.TimepointEditField = uieditfield(app.GridLayout2, 'numeric');
            app.TimepointEditField.Limits = [0 Inf];
            app.TimepointEditField.ValueChangedFcn = createCallbackFcn(app, @TimepointEditFieldValueChanged, true);
            app.TimepointEditField.Tooltip = {'Shows the current frame number. Can also be used to go to a certain frame by entering the number'};
            app.TimeSlider = uislider(app.GridLayout2);
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderValueChanging, true);
            app.TimeSlider.Tooltip = {'Allows to interactively switch to a certain time point.'};
            app.SaveViewasPlotlyButton = uibutton(app.GridLayout2, 'push');
            app.SaveViewasPlotlyButton.ButtonPushedFcn = createCallbackFcn(app, @SaveViewasPlotlyButtonPushed, true);
            app.SaveViewasPlotlyButton.Enable = 'off';
            app.SaveViewasPlotlyButton.Text = 'Save View as Plotly';
            app.PlotlySavelocationButton = uibutton(app.GridLayout2, 'push');
            app.PlotlySavelocationButton.ButtonPushedFcn = createCallbackFcn(app, @PlotlySavelocationButtonPushed, true);
            app.PlotlySavelocationButton.Text = 'Plotly Savelocation';
            app.FilenameEditFieldLabel = uilabel(app.GridLayout2);
            app.FilenameEditFieldLabel.Enable = 'off';
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.GridLayout2, 'text');
            app.FilenameEditField.Enable = 'off';
            app.FilenameEditField.Value = '1';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout12 = uigridlayout(app.RightPanel);
            app.SingleTrackAnalysisPanel = uipanel(app.GridLayout12);
            app.SingleTrackAnalysisPanel.Title = 'Single Track Analysis';
            app.GridLayout8 = uigridlayout(app.SingleTrackAnalysisPanel);
            app.SelectTrackButton = uibutton(app.GridLayout8, 'push');
            app.SelectTrackButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTrackButtonPushed, true);
            app.SelectTrackButton.Tooltip = {'Enables a point to select a track in the figure. Usefull  when all tracks are plotted. '};
            app.SelectTrackButton.Text = 'Select Track';
            app.AnalyzeButton = uibutton(app.GridLayout8, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Tooltip = {'Analyze the single track in a new window.'};
            app.AnalyzeButton.Text = 'Analyze';
            app.TrackIDLabel = uilabel(app.GridLayout8);
            app.TrackIDLabel.Text = 'TrackID:';
            app.Label_3 = uilabel(app.GridLayout8);
            app.Label_3.Text = '1';
            app.SaveSingleTrackButton = uibutton(app.GridLayout8, 'push');
            app.SaveSingleTrackButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSingleTrackButtonPushed, true);
            app.SaveSingleTrackButton.Tooltip = {'Saves the selected track in an extra list to be analyzed later using the Track Analysis Window.'};
            app.SaveSingleTrackButton.Text = 'Save Single Track';
            app.SaveallTracksButton = uibutton(app.GridLayout8, 'push');
            app.SaveallTracksButton.ButtonPushedFcn = createCallbackFcn(app, @SaveallTracksButtonPushed, true);
            app.SaveallTracksButton.Tooltip = {'Saves the current Track list to a file to be loaded later.'};
            app.SaveallTracksButton.Text = 'Save all Tracks';
            app.DeleteTrackButton = uibutton(app.GridLayout8, 'push');
            app.DeleteTrackButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteTrackButtonPushed, true);
            app.DeleteTrackButton.Tooltip = {'Delete the selected track from the Track List.'};
            app.DeleteTrackButton.Text = 'Delete Track';
            app.LoadTracksButton_2 = uibutton(app.GridLayout8, 'push');
            app.LoadTracksButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadTracksButton_2Pushed, true);
            app.LoadTracksButton_2.Tooltip = {'Loads a previously saved track list.'};
            app.LoadTracksButton_2.Text = 'Load Tracks';
            app.TrackNameEditFieldLabel = uilabel(app.GridLayout8);
            app.TrackNameEditFieldLabel.Text = 'TrackName';
            app.TrackNameEditField = uieditfield(app.GridLayout8, 'text');
            app.TrackNameEditField.Tooltip = {'Will name the saved track with the name given here.'};
            app.TrackNameEditField.Value = 'Track';
            app.TrackListDropDownLabel = uilabel(app.GridLayout8);
            app.TrackListDropDownLabel.Text = 'Track List';
            app.TrackListDropDown = uidropdown(app.GridLayout8);
            app.TrackListDropDown.Items = {'Nothing'};
            app.TrackListDropDown.ValueChangedFcn = createCallbackFcn(app, @TrackListDropDownValueChanged, true);
            app.TrackListDropDown.Tooltip = {'Contains the saved tracks. Switch to a certain track to delete it from the list.'};
            app.TrackListDropDown.Value = 'Nothing';
            app.ROIselectionPanel = uipanel(app.GridLayout12);
            app.ROIselectionPanel.Title = 'ROI selection';
            app.GridLayout10 = uigridlayout(app.ROIselectionPanel);
            app.SelectROIButton = uibutton(app.GridLayout10, 'push');
            app.SelectROIButton.ButtonPushedFcn = createCallbackFcn(app, @SelectROIButtonPushed, true);
            app.SelectROIButton.Tooltip = {'Allows the selection of a ROI. It will be a 2D ROI if the current visualisation is 2D or a 3D ROI if the current visualisation is 3D.'};
            app.SelectROIButton.Text = 'Select ROI';
            app.SaveROIButton = uibutton(app.GridLayout10, 'push');
            app.SaveROIButton.ButtonPushedFcn = createCallbackFcn(app, @SaveROIButtonPushed, true);
            app.SaveROIButton.Tooltip = {'Saves the ROI in the list below.'};
            app.SaveROIButton.Text = 'Save ROI';
            app.SaveallROIsButton = uibutton(app.GridLayout10, 'push');
            app.SaveallROIsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveallROIsButtonPushed, true);
            app.SaveallROIsButton.Tooltip = {'Saves all the ROIS and their data in a file to be loaded later.'};
            app.SaveallROIsButton.Text = 'Save all ROIs';
            app.LoadROIsButton = uibutton(app.GridLayout10, 'push');
            app.LoadROIsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadROIsButtonPushed, true);
            app.LoadROIsButton.Tooltip = {'Loads saved ROIs.'};
            app.LoadROIsButton.Text = 'Load ROIs';
            app.SaveAntiROICheckBox = uicheckbox(app.GridLayout10);
            app.SaveAntiROICheckBox.Tooltip = {'Additionally save all the data in an Anti-ROI that was not included in the original ROI. '};
            app.SaveAntiROICheckBox.Text = 'Save Anti-ROI';
            app.ROIListDropDownLabel = uilabel(app.GridLayout10);
            app.ROIListDropDownLabel.Text = 'ROI List';
            app.ROIListDropDown = uidropdown(app.GridLayout10);
            app.ROIListDropDown.Items = {'Nothing', ''};
            app.ROIListDropDown.ValueChangedFcn = createCallbackFcn(app, @ROIListDropDownValueChanged, true);
            app.ROIListDropDown.Tooltip = {'Allows to switch between the already saved ROIs.'};
            app.ROIListDropDown.Value = 'Nothing';
            app.ROINameEditFieldLabel = uilabel(app.GridLayout10);
            app.ROINameEditFieldLabel.Text = 'ROI Name';
            app.ROINameEditField = uieditfield(app.GridLayout10, 'text');
            app.ROINameEditField.Tooltip = {'Name for the ROI to be saved.'};
            app.ROINameEditField.Value = '1';
            app.DeleteROIButton = uibutton(app.GridLayout10, 'push');
            app.DeleteROIButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteROIButtonPushed, true);
            app.DeleteROIButton.Tooltip = {'Deletes the selcted ROI from the list.'};
            app.DeleteROIButton.Text = 'Delete ROI';
            app.Switch_8 = uiswitch(app.GridLayout10, 'slider');
            app.Switch_8.Items = {'Inclusive', 'Exclusive'};
            app.Switch_8.Value = 'Inclusive';
            app.SupermapGenerationPanel = uipanel(app.GridLayout12);
            app.SupermapGenerationPanel.Title = 'Supermap Generation';
            app.GridLayout11 = uigridlayout(app.SupermapGenerationPanel);
            app.VelocityButton = uibutton(app.GridLayout11, 'push');
            app.VelocityButton.ButtonPushedFcn = createCallbackFcn(app, @VelocityButtonPushed, true);
            app.VelocityButton.Tooltip = {'Generates a diffusion map of the data.'};
            app.VelocityButton.Text = 'Velocity';
            app.Switch_2 = uiswitch(app.GridLayout11, 'slider');
            app.Switch_2.Items = {'2D', '3D'};
            app.Switch_2.Tooltip = {'Determines if the supermaps will be generated in 2D or 3D.'};
            app.Switch_2.Value = '2D';
            app.DriftButton = uibutton(app.GridLayout11, 'push');
            app.DriftButton.ButtonPushedFcn = createCallbackFcn(app, @DriftButtonPushed, true);
            app.DriftButton.Tooltip = {'Will generate a drift map of the data. Will depict it using arrows.'};
            app.DriftButton.Text = 'Drift';
            app.DataDensityButton = uibutton(app.GridLayout11, 'push');
            app.DataDensityButton.ButtonPushedFcn = createCallbackFcn(app, @DataDensityButtonPushed, true);
            app.DataDensityButton.Tooltip = {'Generates a data density map of the data to pinpoint the number of detections in a given area.'};
            app.DataDensityButton.Text = 'Data Density';
            app.SuperpxZSizeLabel = uilabel(app.GridLayout11);
            app.SuperpxZSizeLabel.Text = 'SuperpxZSize';
            app.Switch_7 = uiswitch(app.GridLayout11, 'slider');
            app.Switch_7.Items = {'DENS', 'NPTS'};
            app.Switch_7.Tooltip = {'Allows to Switch between a density ratio (localisation/area) or a absolute number of localisations per superpixel.'};
            app.Switch_7.Value = 'NPTS';
            app.DiffusionButton = uibutton(app.GridLayout11, 'push');
            app.DiffusionButton.ButtonPushedFcn = createCallbackFcn(app, @DiffusionButtonPushed, true);
            app.DiffusionButton.Tooltip = {'Will generate a drift map of the data. Will depict it using arrows.'};
            app.DiffusionButton.Text = 'Diffusion';
            app.SuperpixelSizeXYEditFieldLabel = uilabel(app.GridLayout11);
            app.SuperpixelSizeXYEditFieldLabel.Text = 'Superpixel Size XY';
            app.SuperpixelSizeXYEditField = uieditfield(app.GridLayout11, 'numeric');
            app.SuperpixelSizeXYEditField.Tooltip = {'Defines the supermap pixel size in X and Y.'};
            app.SuperpixelSizeXYEditField.Value = 160;
            app.TimeWindowsEditFieldLabel = uilabel(app.GridLayout11);
            app.TimeWindowsEditFieldLabel.Text = 'Time Windows';
            app.TimeWindowsEditField = uieditfield(app.GridLayout11, 'numeric');
            app.TimeWindowsEditField.Tooltip = {'Will split the data in the determined number of time windows. Useful if something changes during the movie during a given time to compare before/after.'};
            app.TimeWindowsEditField.Value = 1;
            app.SuperpixelSizeZEditFieldLabel = uilabel(app.GridLayout11);
            app.SuperpixelSizeZEditFieldLabel.Text = 'Superpixel Size Z';
            app.SuperpixelSizeZEditField = uieditfield(app.GridLayout11, 'numeric');
            app.SuperpixelSizeZEditField.Tooltip = {'Defines the supermap pixel size.in Z'};
            app.SuperpixelSizeZEditField.Value = 160;
            app.SuperpxXYSizeLabel = uilabel(app.GridLayout11);
            app.SuperpxXYSizeLabel.Text = 'SuperpxXYSize';
            app.FurtherClassificationsLabel = uilabel(app.GridLayout11);
            app.FurtherClassificationsLabel.Text = 'Further Classifications';
            app.SupermapButton = uibutton(app.GridLayout11, 'push');
            app.SupermapButton.ButtonPushedFcn = createCallbackFcn(app, @SupermapButtonPushed, true);
            app.SupermapButton.Enable = 'off';
            app.SupermapButton.Text = 'Supermap';
            app.PropertyDropDown = uidropdown(app.GridLayout11);
            app.PropertyDropDown.Enable = 'off';
            app.PropertiesListBoxLabel = uilabel(app.GridLayout11);
            app.PropertiesListBoxLabel.Enable = 'off';
            app.PropertiesListBoxLabel.Text = 'Properties';
            app.PropertiesListBox = uilistbox(app.GridLayout11);
            app.PropertiesListBox.Multiselect = 'on';
            app.PropertiesListBox.Enable = 'off';
            app.PropertiesListBox.Value = {'Item 1'};
            app.DBSCANButton = uibutton(app.GridLayout11, 'push');
            app.DBSCANButton.ButtonPushedFcn = createCallbackFcn(app, @DBSCANButtonPushed, true);
            app.DBSCANButton.Enable = 'off';
            app.DBSCANButton.Text = 'DBSCAN';
            app.MinClusterSizeEditFieldLabel = uilabel(app.GridLayout11);
            app.MinClusterSizeEditFieldLabel.Enable = 'off';
            app.MinClusterSizeEditFieldLabel.Text = 'Min. Cluster Size';
            app.MinClusterSizeEditField = uieditfield(app.GridLayout11, 'numeric');
            app.MinClusterSizeEditField.Limits = [1 Inf];
            app.MinClusterSizeEditField.Enable = 'off';
            app.MinClusterSizeEditField.Value = 5;
            app.ExpTimeEditFieldLabel = uilabel(app.GridLayout11);
            app.ExpTimeEditFieldLabel.Text = 'Exp. Time';
            app.ExpTimeEditField = uieditfield(app.GridLayout11, 'numeric');
            app.ExpTimeEditField.Value = 36;
            app.CompleteAnalysisPanel = uipanel(app.GridLayout12);
            app.CompleteAnalysisPanel.Title = 'Complete Analysis';
            app.GridLayout9 = uigridlayout(app.CompleteAnalysisPanel);
            app.Switch_4 = uiswitch(app.GridLayout9, 'slider');
            app.Switch_4.Items = {'Current File', 'All Files'};
            app.Switch_4.Tooltip = {'Defines if only the selected tracks or all loaded tracks should be analyzed.'};
            app.Switch_4.Value = 'Current File';
            app.SpotsButton = uibutton(app.GridLayout9, 'push');
            app.SpotsButton.ButtonPushedFcn = createCallbackFcn(app, @SpotsButtonPushed, true);
            app.SpotsButton.Tooltip = {'Perform a spot analysis. Only shows the intensities at the moment. '};
            app.SpotsButton.Text = 'Spots';
            app.TracksButton = uibutton(app.GridLayout9, 'push');
            app.TracksButton.ButtonPushedFcn = createCallbackFcn(app, @TracksButtonPushed, true);
            app.TracksButton.Tooltip = {'Perform an analysis on the tracks.'};
            app.TracksButton.Text = 'Tracks';
            app.UseROIforAnalysisCheckBox = uicheckbox(app.GridLayout9);
            app.UseROIforAnalysisCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseROIforAnalysisCheckBoxValueChanged, true);
            app.UseROIforAnalysisCheckBox.Enable = 'off';
            app.UseROIforAnalysisCheckBox.Tooltip = {'If this is checked, the data from the ROIs will be used for analysis and not the total track file data.'};
            app.UseROIforAnalysisCheckBox.Text = 'Use ROI for Analysis';
            app.UseSingleTracksforAnalysisCheckBox = uicheckbox(app.GridLayout9);
            app.UseSingleTracksforAnalysisCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseSingleTracksforAnalysisCheckBoxValueChanged, true);
            app.UseSingleTracksforAnalysisCheckBox.Enable = 'off';
            app.UseSingleTracksforAnalysisCheckBox.Tooltip = {'If this is checked, the Tracks from the Track List will be used and not the total track file data.'};
            app.UseSingleTracksforAnalysisCheckBox.Text = 'Use Single Tracks for Analysis';
            % Show the figure after all components are created
            app.VisualisationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VisualisationWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VisualisationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VisualisationUIFigure)
        end
    end
end